classdef agataCompareMultipleProfile < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AGATAMultipleProfileGUIUIFigure  matlab.ui.Figure
        LoadDataPanelGroup1             matlab.ui.container.Panel
        SelectAndLoadDataButtonGroup1   matlab.ui.control.Button
        SelectAndLoadDataButtonLabelGroup1  matlab.ui.control.Label
        SelectCGMdatasourceDropDownLabel  matlab.ui.control.Label
        SelectCGMdatasourceDropDownGroup1  matlab.ui.control.DropDown
        AddDataButtonGroup1             matlab.ui.control.Button
        VisualizationDataPanel          matlab.ui.container.Panel
        CompareGlucoseDataAsOneDayButton  matlab.ui.control.Button
        CompareGlucoseCVGAButton        matlab.ui.control.Button
        PlotGroup1DataAsOneDayButton    matlab.ui.control.Button
        PlotGroup2DataAsOneDayButton    matlab.ui.control.Button
        CompareGlucoseGRIButton         matlab.ui.control.Button
        ProcessingDataPanel             matlab.ui.container.Panel
        RetimeDataButton                matlab.ui.control.Button
        ImputeDataButton                matlab.ui.control.Button
        DetrendDataButton               matlab.ui.control.Button
        SaveDataButton                  matlab.ui.control.Button
        AnalysisDataPanel               matlab.ui.container.Panel
        AnalyzeGlucoseDataButton        matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        VariabilityTab                  matlab.ui.container.Tab
        AUCLabel                        matlab.ui.control.Label
        CVGALabel                       matlab.ui.control.Label
        MEANLabel                       matlab.ui.control.Label
        JINDEXLabel                     matlab.ui.control.Label
        GMILabel                        matlab.ui.control.Label
        EFINDEXLabel                    matlab.ui.control.Label
        MAGEINDEXLabel                  matlab.ui.control.Label
        MAGEINDEXLabel_2                matlab.ui.control.Label
        MAGEINDEXLabel_3                matlab.ui.control.Label
        meanLabelG1                     matlab.ui.control.Label
        medianLabelG1                   matlab.ui.control.Label
        sdLabelG1                       matlab.ui.control.Label
        cvLabelG1                       matlab.ui.control.Label
        iqrLabelG1                      matlab.ui.control.Label
        rangeLabelG1                    matlab.ui.control.Label
        aucLabelG1                      matlab.ui.control.Label
        cvgaLabelG1                     matlab.ui.control.Label
        jindexLabelG1                   matlab.ui.control.Label
        efindexLabelG1                  matlab.ui.control.Label
        mageindexLabelG1                matlab.ui.control.Label
        mageplusindexLabelG1            matlab.ui.control.Label
        mageminusindexLabelG1           matlab.ui.control.Label
        gmiLabelG1                      matlab.ui.control.Label
        meanLabelG2                     matlab.ui.control.Label
        medianLabelG2                   matlab.ui.control.Label
        sdLabelG2                       matlab.ui.control.Label
        cvLabelG2                       matlab.ui.control.Label
        iqrLabelG2                      matlab.ui.control.Label
        rangeLabelG2                    matlab.ui.control.Label
        MEDIANLabel                     matlab.ui.control.Label
        SDLabel                         matlab.ui.control.Label
        CVLabel                         matlab.ui.control.Label
        IQRLabel                        matlab.ui.control.Label
        RANGELabel                      matlab.ui.control.Label
        SDROCLabel                      matlab.ui.control.Label
        SDDMINDEXLabel                  matlab.ui.control.Label
        SDWINDEXLabel                   matlab.ui.control.Label
        sdrocLabelG1                    matlab.ui.control.Label
        sddmindexLabelG1                matlab.ui.control.Label
        sdwindexLabelG1                 matlab.ui.control.Label
        sdrocLabelG2                    matlab.ui.control.Label
        sddmindexLabelG2                matlab.ui.control.Label
        sdwindexLabelG2                 matlab.ui.control.Label
        aucLabelG2                      matlab.ui.control.Label
        cvgaLabelG2                     matlab.ui.control.Label
        jindexLabelG2                   matlab.ui.control.Label
        gmiLabelG2                      matlab.ui.control.Label
        mageminusindexLabelG2           matlab.ui.control.Label
        mageplusindexLabelG2            matlab.ui.control.Label
        mageindexLabelG2                matlab.ui.control.Label
        efindexLabelG2                  matlab.ui.control.Label
        pmeanLabel                      matlab.ui.control.Label
        pmedianLabel                    matlab.ui.control.Label
        psdLabel                        matlab.ui.control.Label
        pcvLabel                        matlab.ui.control.Label
        piqrLabel                       matlab.ui.control.Label
        prangeLabel                     matlab.ui.control.Label
        psdrocLabel                     matlab.ui.control.Label
        psddmLabel                      matlab.ui.control.Label
        psdwLabel                       matlab.ui.control.Label
        paucLabel                       matlab.ui.control.Label
        pCVGALabel                      matlab.ui.control.Label
        pjLabel                         matlab.ui.control.Label
        pgmiLabel                       matlab.ui.control.Label
        pEFLabel                        matlab.ui.control.Label
        pmageLabel                      matlab.ui.control.Label
        pmagePlusLabel                  matlab.ui.control.Label
        pmageMinusLabel                 matlab.ui.control.Label
        CONGALabel                      matlab.ui.control.Label
        MODDLabel                       matlab.ui.control.Label
        congaLabelG1                    matlab.ui.control.Label
        moddLabelG1                     matlab.ui.control.Label
        moddLabelG2                     matlab.ui.control.Label
        congaLabelG2                    matlab.ui.control.Label
        pcongaLabel                     matlab.ui.control.Label
        pmoddLabel                      matlab.ui.control.Label
        COGILabel                       matlab.ui.control.Label
        cogiLabelG1                     matlab.ui.control.Label
        cogiLabelG2                     matlab.ui.control.Label
        pcogiLabel                      matlab.ui.control.Label
        RiskTab                         matlab.ui.container.Tab
        HBGILabel                       matlab.ui.control.Label
        ADRRLabel                       matlab.ui.control.Label
        BGRILabel                       matlab.ui.control.Label
        LBGILabel                       matlab.ui.control.Label
        adrrLabelG1                     matlab.ui.control.Label
        bgriLabelG1                     matlab.ui.control.Label
        lbgiLabelG1                     matlab.ui.control.Label
        hbgiLabelG1                     matlab.ui.control.Label
        adrrLabelG2                     matlab.ui.control.Label
        bgriLabelG2                     matlab.ui.control.Label
        lbgiLabelG2                     matlab.ui.control.Label
        hbgiLabelG2                     matlab.ui.control.Label
        padrrLabel                      matlab.ui.control.Label
        pbgriLabel                      matlab.ui.control.Label
        plbgiLabel                      matlab.ui.control.Label
        phbgiLabel                      matlab.ui.control.Label
        GRILabel                        matlab.ui.control.Label
        griLabelG1                      matlab.ui.control.Label
        griLabelG2                      matlab.ui.control.Label
        pgriLabel                       matlab.ui.control.Label
        TimeinRangesTab                 matlab.ui.container.Tab
        TIMEINLEVEL2HYPOGLYCEMIACGM54mgdLLabel  matlab.ui.control.Label
        TIMEINHYPOGLYCEMIACGM70mgdLLabel  matlab.ui.control.Label
        TIMEINHYPERGLYCEMIACGM180mgdLLabel  matlab.ui.control.Label
        TIMEINTARGETCGM70180mgdLLabel   matlab.ui.control.Label
        TIMEINLEVEL2HYPERGLYCEMIACGM250mgdLLabel  matlab.ui.control.Label
        TIMEINTIGHTTARGETCGM70140mgdLLabel  matlab.ui.control.Label
        timehypoLabelG1                 matlab.ui.control.Label
        timehyperLabelG1                matlab.ui.control.Label
        timetargetLabelG1               matlab.ui.control.Label
        timelevel2hyperLabelG1          matlab.ui.control.Label
        timetighttargetLabelG1          matlab.ui.control.Label
        timelevel2hypoLabelG1           matlab.ui.control.Label
        timehypoLabelG2                 matlab.ui.control.Label
        timehyperLabelG2                matlab.ui.control.Label
        timetargetLabelG2               matlab.ui.control.Label
        timelevel2hyperLabelG2          matlab.ui.control.Label
        timetighttargetLabelG2          matlab.ui.control.Label
        timelevel2hypoLabelG2           matlab.ui.control.Label
        phypoLabel                      matlab.ui.control.Label
        phyperLabel                     matlab.ui.control.Label
        ptargetLabel                    matlab.ui.control.Label
        pl2hypoLabel                    matlab.ui.control.Label
        pl2hyperLabel                   matlab.ui.control.Label
        pttargetLabel                   matlab.ui.control.Label
        TIMEINLEVEL1HYPOGLYCEMIACGM5470mgdLLabel  matlab.ui.control.Label
        timelevel1hypoLabelG1           matlab.ui.control.Label
        timelevel1hypoLabelG2           matlab.ui.control.Label
        pl1hypoLabel                    matlab.ui.control.Label
        TIMEINLEVEL1HYPERGLYCEMIACGM180250mgdLLabel  matlab.ui.control.Label
        timelevel1hyperLabelG1          matlab.ui.control.Label
        timelevel1hyperLabelG2          matlab.ui.control.Label
        pl1hyperLabel                   matlab.ui.control.Label
        GlycemicTransformationTab       matlab.ui.container.Tab
        GRADEHYPERGLYCEMIASCORELabel    matlab.ui.control.Label
        GRADESCORELabel                 matlab.ui.control.Label
        GRADEEUGLYCEMIASCORELabel       matlab.ui.control.Label
        GRADEHYPOGLYCEMIASCORELabel     matlab.ui.control.Label
        INDEXGLYCEMICCONTROLLabel       matlab.ui.control.Label
        HYPOINDEXLabel                  matlab.ui.control.Label
        gradeLabelG1                    matlab.ui.control.Label
        gradeeuLabelG1                  matlab.ui.control.Label
        gradehypoLabelG1                matlab.ui.control.Label
        igcLabelG1                      matlab.ui.control.Label
        hypoindexLabelG1                matlab.ui.control.Label
        gradehyperLabelG1               matlab.ui.control.Label
        HYPERINDEXLabel                 matlab.ui.control.Label
        hyperindexLabelG1               matlab.ui.control.Label
        MRVALUELabel                    matlab.ui.control.Label
        mrLabelG1                       matlab.ui.control.Label
        gradeLabelG2                    matlab.ui.control.Label
        gradeeuLabelG2                  matlab.ui.control.Label
        gradehypoLabelG2                matlab.ui.control.Label
        igcLabelG2                      matlab.ui.control.Label
        hypoindexLabelG2                matlab.ui.control.Label
        gradehyperLabelG2               matlab.ui.control.Label
        hyperindexLabelG2               matlab.ui.control.Label
        mrLabelG2                       matlab.ui.control.Label
        pgradeLabel                     matlab.ui.control.Label
        pgradeeuLabel                   matlab.ui.control.Label
        pgradeHypoLabel                 matlab.ui.control.Label
        pgradeHyperLabel                matlab.ui.control.Label
        pigcLabel                       matlab.ui.control.Label
        phypoindLabel                   matlab.ui.control.Label
        phyperindLabel                  matlab.ui.control.Label
        pmrLabel                        matlab.ui.control.Label
        DataQualityTab                  matlab.ui.container.Tab
        MISSINGGLUCOSEVALUESPERCENTAGELabel  matlab.ui.control.Label
        missingpercentageLabelG1        matlab.ui.control.Label
        NUMBEROFDAYSLabel               matlab.ui.control.Label
        numberDaysLabelG1               matlab.ui.control.Label
        missingpercentageLabelG2        matlab.ui.control.Label
        numberDaysLabelG2               matlab.ui.control.Label
        pmissingLabel                   matlab.ui.control.Label
        pnodLabel                       matlab.ui.control.Label
        EventsTab                       matlab.ui.container.Tab
        HYPOGLYCEMICEVENTSPERWEEKLabel  matlab.ui.control.Label
        HYPOGLYCEMICEVENTSMEANDURATIONLabel  matlab.ui.control.Label
        hypodurationLabelG1             matlab.ui.control.Label
        hypoperweekLabelG1              matlab.ui.control.Label
        HYPERGLYCEMICEVENTSPERWEEKLabel_2  matlab.ui.control.Label
        HYPERGLYCEMICEVENTSMEANDURATIONLabel_2  matlab.ui.control.Label
        hyperdurationLabelG1            matlab.ui.control.Label
        hyperperweekLabelG1             matlab.ui.control.Label
        EXTENDEDHYPOGLYCEMICEVENTSPERWEEKLabel  matlab.ui.control.Label
        EXTENDEDHYPOGLYCEMICEVENTSMEANDURATIONLabel  matlab.ui.control.Label
        extendedhypodurationLabelG1     matlab.ui.control.Label
        extendedhypoperweekLabelG1      matlab.ui.control.Label
        hypodurationLabelG2             matlab.ui.control.Label
        hypoperweekLabelG2              matlab.ui.control.Label
        hyperdurationLabelG2            matlab.ui.control.Label
        hyperperweekLabelG2             matlab.ui.control.Label
        extendedhypodurationLabelG2     matlab.ui.control.Label
        extendedhypoperweekLabelG2      matlab.ui.control.Label
        phypoeventdurationLabel         matlab.ui.control.Label
        phypoeventweekLabel             matlab.ui.control.Label
        pexthypoeventdurationLabel      matlab.ui.control.Label
        pexthypoeventweekLabel          matlab.ui.control.Label
        phypereventdurationLabel        matlab.ui.control.Label
        phypereventweekLabel            matlab.ui.control.Label
        LEVEL1HYPERGLYCEMICEVENTSPERWEEKLabel  matlab.ui.control.Label
        LEVEL1HYPERGLYCEMICEVENTSMEANDURATIONLabel  matlab.ui.control.Label
        l1hyperdurationLabelG1          matlab.ui.control.Label
        l1hyperperweekLabelG1           matlab.ui.control.Label
        l1hyperdurationLabelG2          matlab.ui.control.Label
        l1hyperperweekLabelG2           matlab.ui.control.Label
        pl1hypereventdurationLabel      matlab.ui.control.Label
        pl1hypereventweekLabel          matlab.ui.control.Label
        LEVEL2HYPERGLYCEMICEVENTSPERWEEKLabel  matlab.ui.control.Label
        LEVEL2HYPERGLYCEMICEVENTSMEANDURATIONLabel  matlab.ui.control.Label
        l2hyperdurationLabelG1          matlab.ui.control.Label
        l2hyperperweekLabelG1           matlab.ui.control.Label
        l2hyperdurationLabelG2          matlab.ui.control.Label
        l2hyperperweekLabelG2           matlab.ui.control.Label
        pl2hypereventdurationLabel      matlab.ui.control.Label
        pl2hypereventweekLabel          matlab.ui.control.Label
        LEVEL2HYPOGLYCEMICEVENTSPERWEEKLabel  matlab.ui.control.Label
        LEVEL2HYPOGLYCEMICEVENTSMEANDURATIONLabel  matlab.ui.control.Label
        l2hypodurationLabelG1           matlab.ui.control.Label
        l2hypoperweekLabelG1            matlab.ui.control.Label
        l2hypodurationLabelG2           matlab.ui.control.Label
        l2hypoperweekLabelG2            matlab.ui.control.Label
        pl2hypoeventdurationLabel       matlab.ui.control.Label
        pl2hypoeventweekLabel           matlab.ui.control.Label
        LEVEL1HYPOGLYCEMICEVENTSPERWEEKLabel  matlab.ui.control.Label
        LEVEL1HYPOGLYCEMICEVENTSMEANDURATIONLabel  matlab.ui.control.Label
        l1hypodurationLabelG1           matlab.ui.control.Label
        l1hypoperweekLabelG1            matlab.ui.control.Label
        l1hypodurationLabelG2           matlab.ui.control.Label
        l1hypoperweekLabelG2            matlab.ui.control.Label
        pl1hypoeventdurationLabel       matlab.ui.control.Label
        pl1hypoeventweekLabel           matlab.ui.control.Label
        SaveAnalysisResultsButton       matlab.ui.control.Button
        LoadingStatusLabelGroup1        matlab.ui.control.Label
        StatusLampLabel                 matlab.ui.control.Label
        StatusLamp                      matlab.ui.control.Lamp
        LoadDataPanelGroup2             matlab.ui.container.Panel
        SelectAndLoadDataButtonGroup2   matlab.ui.control.Button
        SelectAndLoadDataButtonLabelGroup2  matlab.ui.control.Label
        SelectCGMdatasourceDropDown_2Label  matlab.ui.control.Label
        SelectCGMdatasourceDropDownGroup2  matlab.ui.control.DropDown
        AddDataButtonGroup2             matlab.ui.control.Button
        DoneButton                      matlab.ui.control.Button
        LoadingStatusLabelGroup2        matlab.ui.control.Label
    end

    
    properties (Access = private)
        NTracesGroup1 %Number of loaded traces of group 1
        NTracesGroup2 %Number of loaded traces of group 2
        DataGroup1 % Glucose data to analyze of group 1
        DataGroup2 % Glucose data to analyze of group 2
        CallingApp %The app that called me
        AnalysisResults %The results of the analysis
        StatsResults %The results of the statistical analysis
    end
    
    methods (Access = private)
        
        
        function setLampColor(app)
            if(app.NTracesGroup2 > 0 && app.NTracesGroup1 > 0)
                app.StatusLamp.Color = 'g';
            else
                if(app.NTracesGroup2 == 0 && app.NTracesGroup1 == 0)
                    app.StatusLamp.Color = 'r';
                else
                    app.StatusLamp.Color = 'y';
                end
            end
        end
        
        function results = resetAnalysisDataPanel(app)
            %Set variability texts
            app.meanLabelG1.Text = '<mean>';
            app.medianLabelG1.Text = '<median>';
            app.sdLabelG1.Text = '<sd>';
            app.cvLabelG1.Text = '<cv>';
            app.iqrLabelG1.Text = '<iqr>';
            app.rangeLabelG1.Text = '<range>';
            app.sdrocLabelG1.Text = '<sdroc>';
            app.aucLabelG1.Text = '<auc>';
            app.cvgaLabelG1.Text = '<cvga>';
            app.jindexLabelG1.Text = '<jindex>';
            app.gmiLabelG1.Text = '<gmi>';
            app.sddmindexLabelG1.Text = '<sddmindex>';
            app.sdwindexLabelG1.Text = '<sdwindex>';
            app.efindexLabelG1.Text = '<efindex>';
            app.mageindexLabelG1.Text = '<mageindex>';
            app.mageplusindexLabelG1.Text = '<mageplus>';
            app.mageminusindexLabelG1.Text = '<mageminus>';
            app.congaLabelG1.Text = '<conga>';
            app.moddLabelG1.Text = '<modd>';
            
            %Set risk texts
            app.adrrLabelG1.Text = '<adrr>';
            app.bgriLabelG1.Text = '<bgri>';
            app.lbgiLabelG1.Text = '<lbgi>';
            app.hbgiLabelG1.Text = '<hbgi>';
            
            %Set time texts
            app.timehypoLabelG1.Text = '<timehypo>';
            app.timehyperLabelG1.Text = '<timehyper>';
            app.timetargetLabelG1.Text = '<timetarget>';
            app.timelevel2hypoLabelG1.Text = '<timeseverehypo>';
            app.timelevel2hyperLabelG1.Text = '<timeseverehyper>';
            app.timetighttargetLabelG1.Text = '<timetighttarget>';
            
            %Set glycemic transformation texts
            app.gradeLabelG1.Text = '<grade>';
            app.gradeeuLabelG1.Text = '<gradeeu>';
            app.gradehypoLabelG1.Text = '<gradehypo>';
            app.gradehyperLabelG1.Text = '<gradehyper>';
            app.igcLabelG1.Text = '<igc>';
            app.hypoindexLabelG1.Text = '<hypoindex>';
            app.hyperindexLabelG1.Text = '<hyperindex>';
            app.mrLabelG1.Text = '<mr>';
            
            %Set the data quality texts
            app.missingpercentageLabelG1.Text = '<missingpercentage>';
            app.numberDaysLabelG1.Text = '<numberdays>';
            
            %Set the events texts
            app.hypodurationLabelG1.Text = '<hypoduration>';
            app.hypoperweekLabelG1.Text = '<hypoperweek>';
            app.extendedhypodurationLabelG1.Text = '<prolongedhypoduration>';
            app.extendedhypoperweekLabelG1.Text = '<prolongedhypoperweek>';
            app.hyperdurationLabelG1.Text = '<hyperduration>';
            app.hyperperweekLabelG1.Text = '<hyperperweek>';
            
            %Set variability texts
            app.meanLabelG2.Text = '<mean>';
            app.medianLabelG2.Text = '<median>';
            app.sdLabelG2.Text = '<sd>';
            app.cvLabelG2.Text = '<cv>';
            app.iqrLabelG2.Text = '<iqr>';
            app.rangeLabelG2.Text = '<range>';
            app.sdrocLabelG2.Text = '<sdroc>';
            app.aucLabelG2.Text = '<auc>';
            app.cvgaLabelG2.Text = '<cvga>';
            app.jindexLabelG2.Text = '<jindex>';
            app.gmiLabelG2.Text = '<gmi>';
            app.sddmindexLabelG2.Text = '<sddmindex>';
            app.sdwindexLabelG2.Text = '<sdwindex>';
            app.efindexLabelG2.Text = '<efindex>';
            app.mageindexLabelG2.Text = '<mageindex>';
            app.mageplusindexLabelG2.Text = '<mageplus>';
            app.mageminusindexLabelG2.Text = '<mageminus>';
            app.congaLabelG2.Text = '<conga>';
            app.moddLabelG2.Text = '<modd>';
            
            %Set risk texts
            app.adrrLabelG2.Text = '<adrr>';
            app.bgriLabelG2.Text = '<bgri>';
            app.lbgiLabelG2.Text = '<lbgi>';
            app.hbgiLabelG2.Text = '<hbgi>';
            
            %Set time texts
            app.timehypoLabelG2.Text = '<timehypo>';
            app.timehyperLabelG2.Text = '<timehyper>';
            app.timetargetLabelG2.Text = '<timetarget>';
            app.timelevel2hypoLabelG2.Text = '<timeseverehypo>';
            app.timelevel2hyperLabelG2.Text = '<timeseverehyper>';
            app.timetighttargetLabelG2.Text = '<timetighttarget>';
            
            %Set glycemic transformation texts
            app.gradeLabelG2.Text = '<grade>';
            app.gradeeuLabelG2.Text = '<gradeeu>';
            app.gradehypoLabelG2.Text = '<gradehypo>';
            app.gradehyperLabelG2.Text = '<gradehyper>';
            app.igcLabelG2.Text = '<igc>';
            app.hypoindexLabelG2.Text = '<hypoindex>';
            app.hyperindexLabelG2.Text = '<hyperindex>';
            app.mrLabelG2.Text = '<mr>';
            
            %Set the data quality texts
            app.missingpercentageLabelG2.Text = '<missingpercentage>';
            app.numberDaysLabelG2.Text = '<numberdays>';
            
            %Set the events texts
            app.hypodurationLabelG2.Text = '<hypoduration>';
            app.hypoperweekLabelG2.Text = '<hypoperweek>';
            app.extendedhypodurationLabelG2.Text = '<prolongedhypoduration>';
            app.extendedhypoperweekLabelG2.Text = '<prolongedhypoperweek>';
            app.hyperdurationLabelG2.Text = '<hyperduration>';
            app.hyperperweekLabelG2.Text = '<hyperperweek>';
            
            %Set the pvalue texts
            app.pmeanLabel.Text = '<pvalue>';
            app.pmedianLabel.Text = '<pvalue>';
            app.psdLabel.Text = '<pvalue>';
            app.pcvLabel.Text = '<pvalue>';
            app.piqrLabel.Text = '<pvalue>';
            app.prangeLabel.Text = '<pvalue>';
            app.psdrocLabel.Text = '<pvalue>';
            app.paucLabel.Text = '<pvalue>';
            app.pCVGALabel.Text = '<pvalue>';
            app.pjLabel.Text = '<pvalue>';
            app.pgmiLabel.Text = '<pvalue>';
            app.psddmLabel.Text = '<pvalue>';
            app.psdwLabel.Text = '<pvalue>';
            app.pEFLabel.Text = '<pvalue>';
            app.pmageLabel.Text = '<pvalue>';
            app.pmagePlusLabel.Text = '<pvalue>';
            app.pmageMinusLabel.Text = '<pvalue>';
            
            app.padrrLabel.Text = '<pvalue>';
            app.plbgiLabel.Text = '<pvalue>';
            app.phbgiLabel.Text = '<pvalue>';
            app.pbgriLabel.Text = '<pvalue>';
            
            app.phypoLabel.Text = '<pvalue>';
            app.phyperLabel.Text = '<pvalue>';
            app.ptargetLabel.Text = '<pvalue>';
            app.pl2hypoLabel.Text = '<pvalue>';
            app.pl2hyperLabel.Text = '<pvalue>';
            app.pttargetLabel.Text = '<pvalue>';
            
            app.pgradeLabel.Text = '<pvalue>';
            app.pgradeeuLabel.Text = '<pvalue>';
            app.pgradeHyperLabel.Text = '<pvalue>';
            app.pgradeHypoLabel.Text = '<pvalue>';
            app.pigcLabel.Text = '<pvalue>';
            app.phypoindLabel.Text = '<pvalue>';
            app.phyperindLabel.Text = '<pvalue>';
            app.pmrLabel.Text = '<pvalue>';
            
            app.pmissingLabel.Text = '<pvalue>';
            app.pnodLabel.Text = '<pvalue>';
            
            app.phypoeventdurationLabel.Text = '<pvalue>';
            app.phypoeventweekLabel.Text = '<pvalue>';
            app.phypereventdurationLabel.Text = '<pvalue>';
            app.phypereventweekLabel.Text = '<pvalue>';
            app.pexthypoeventdurationLabel.Text = '<pvalue>';
            app.pexthypoeventweekLabel.Text = '<pvalue>';

            %Store the results in the workspace
            app.AnalysisResults = [];
            app.StatsResults = [];
            app.SaveAnalysisResultsButton.Enable = false;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            %Load AGATA core
            addpath(genpath('src'));
            
            %Store the calling app object
            app.NTracesGroup1 = 0;
            app.NTracesGroup2 = 0;
            app.CallingApp = mainapp;
            
            %Hide actions of the load data panel
            app.SelectCGMdatasourceDropDownLabel.Visible = false;
            app.SelectCGMdatasourceDropDownGroup1.Visible = false;
            app.SelectAndLoadDataButtonGroup1.Visible = false;
            app.SelectAndLoadDataButtonLabelGroup1.Visible = false;
            
            app.SelectCGMdatasourceDropDown_2Label.Visible = false;
            app.SelectCGMdatasourceDropDownGroup2.Visible = false;
            app.SelectAndLoadDataButtonGroup2.Visible = false;
            app.SelectAndLoadDataButtonLabelGroup2.Visible = false;
            app.DoneButton.Visible = false;
            
            %Hide other panels
            app.VisualizationDataPanel.Visible = false;
            app.ProcessingDataPanel.Visible = false;
            app.AnalysisDataPanel.Visible = false;
        end

        % Button pushed function: SelectAndLoadDataButtonGroup1
        function SelectAndLoadDataFileGroup1(app, event)
            
            %Get the file via UI
            if(strcmp(app.SelectCGMdatasourceDropDownGroup1.Value,'AGATA'))
                extension = '*.mat';
            else
                extension = '*';
            end
            [file,path] = uigetfile(extension);
            
            %If a file has been selected 
            if(file~=0)
                
                %Get the complete file path
                filename = [path file];
                
                %Update UI components' state
                app.SelectCGMdatasourceDropDownGroup1.Enable = false;
                app.SelectAndLoadDataButtonGroup1.Enable = false;
                app.LoadingStatusLabelGroup1.Text = 'Loading...';
                app.StatusLamp.Color = 'y';
                pause(0.001);
                
                %Load data using AGATA core
                try
                    switch app.SelectCGMdatasourceDropDownGroup1.Value
                        case 'AGATA'
                            load(filename);
                            
                            %Check preconditions 
                            if(~istimetable(data))
                                error('Loaded data must be in a timetable called data.');
                            end
                            if(~any(strcmp(fieldnames(data),'glucose')))
                                error('Loaded data must have a column named `glucose`.')
                            end
                            
                        case 'Dexcom'
                            data = readDexcomData(filename);
                        case 'Ascensia Eversense'
                            data = readEversenseData(filename);
                        case 'Abbott Freestyle Libre'
                            data = readFreestyleLibreData(filename);
                    end
                    %if data are in mmol/l convert them
                    if(nanmean(data.glucose) < 30)
                        data.glucose = data.glucose* 18.018;
                    end
                    
                    %Add data
                    app.DataGroup1{app.NTracesGroup1+1} = data;
                    app.NTracesGroup1 = app.NTracesGroup1 +1;
                    
                    %Update UI components' state
                    app.SelectCGMdatasourceDropDownGroup1.Enable = true;
                    app.SelectAndLoadDataButtonGroup1.Enable = true;
                
                    app.SelectCGMdatasourceDropDownGroup1.Visible = false;
                    app.SelectCGMdatasourceDropDownLabel.Visible = false;
                    
                    app.SelectAndLoadDataButtonLabelGroup1.Visible = false;
                    app.SelectAndLoadDataButtonGroup1.Visible = false;
                    
                    app.LoadingStatusLabelGroup1.Text = ['Group 1: Ready (N = ' num2str(app.NTracesGroup1) ')'];
                    if(app.NTracesGroup2 > 0)
                        %app.StatusLamp.Color = 'g';
                        app.DoneButton.Visible = true;
                    else
                        %app.StatusLamp.Color = 'y';
                        app.DoneButton.Visible = false;
                    end
                    
                    app.SelectCGMdatasourceDropDownGroup1.Value = '<Select a source>';
                    
                catch exception
                    %Update UI components' state
                    app.SelectCGMdatasourceDropDownGroup1.Enable = true;
                    app.SelectAndLoadDataButtonGroup1.Enable = true;
                    
                    if(app.NTracesGroup1 > 0)
                        app.LoadingStatusLabelGroup1.Text = ['Group 1: Ready (N = ' num2str(app.NTraces) ')'];
                    else
                        app.LoadingStatusLabelGroup1.Text = 'Group 1: No data';
                    end
                    errordlg('The selected file is not readable or is not coming from the specified CGM data source','Invalid data file');
                
                end
                setLampColor(app);
            end
            
            
        end

        % Value changed function: SelectCGMdatasourceDropDownGroup1
        function SelectCGMdatasourceDropDownGroup1ValueChanged(app, event)
            value = app.SelectCGMdatasourceDropDownGroup1.Value;
            if(~strcmp(value,'<Select a source>'))
                app.SelectAndLoadDataButtonGroup1.Visible = true;
                app.SelectAndLoadDataButtonLabelGroup1.Visible = true;
            else
                app.SelectAndLoadDataButtonGroup1.Visible = false;
                app.SelectAndLoadDataButtonLabelGroup1.Visible = false;
            end
            
        end

        % Button pushed function: RetimeDataButton
        function RetimeDataButtonPushed(app, event)
            
            %Update UI components' state
            app.RetimeDataButton.Enable = false;
            app.LoadingStatusLabelGroup1.Text = 'Processing...';
            app.LoadingStatusLabelGroup2.Text = 'Processing...';
            app.StatusLamp.Color = 'y';
            
            %Get the time step to be used to retime data
            prompt = {'Enter a value for time step (in minutes)'};
            dlgtitle = 'Time step value';
            definput = {''};
            dims = [1 40];
            timestep = inputdlg(prompt,dlgtitle,dims,definput);
            if(~isempty(timestep))
                
                %Get the actual number
                timestep = str2num(timestep{1});
            
                %Check the input validity and retime data
                if(~isempty(timestep) && timestep > 0 && isnumeric(timestep) && ((timestep - round(timestep)) == 0) )
                    for d = 1:app.NTracesGroup1
                        app.DataGroup1{d} = retimeGlucose(app.DataGroup1{d},timestep);
                    end
                    for d = 1:app.NTracesGroup2
                        app.DataGroup2{d} = retimeGlucose(app.DataGroup2{d},timestep);
                    end
                    %Once retimed, unlock new options
                    app.ImputeDataButton.Enable = true;
                    app.DetrendDataButton.Enable = true;
                    
                    app.AnalyzeGlucoseDataButton.Enable = true;
                    
                    app.CompareGlucoseDataAsOneDayButton.Enable = true;
                    app.PlotGroup1DataAsOneDayButton.Enable = true;
                    app.PlotGroup2DataAsOneDayButton.Enable = true;
                    
                else
                    errordlg('The time step value must be an integer > 0.','Invalid value');
                end
                
            end
            
            %Update UI components' state
            app.RetimeDataButton.Enable = true;
            app.LoadingStatusLabelGroup1.Text = ['Group 1: Ready (N = ' num2str(app.NTracesGroup1) ')'];
            app.LoadingStatusLabelGroup2.Text = ['Group 2: Ready (N = ' num2str(app.NTracesGroup2) ')'];
            app.StatusLamp.Color = 'g';
            
        end

        % Button pushed function: ImputeDataButton
        function ImputeDataButtonPushed(app, event)
            %Update UI components' state
            app.LoadingStatusLabelGroup1.Text = 'Processing...';
            app.LoadingStatusLabelGroup2.Text = 'Processing...';
            app.StatusLamp.Color = 'y';
            app.ImputeDataButton.Enable = false;
            
            %Get the time step to be used to retime data
            prompt = {'Enter a value for the maximum imputable gap (in minutes)'};
            dlgtitle = 'Max gap value';
            definput = {''};
            dims = [1 40];
            maxGap = inputdlg(prompt,dlgtitle,dims,definput);
            if(~isempty(maxGap))
                
                %Get the actual number
                maxGap = str2num(maxGap{1});
                
                try
                %Check the input validity and impute data
                if(~isempty(maxGap) && isnumeric(maxGap) && ((maxGap - round(maxGap)) == 0) )
                    for d = 1:app.NTracesGroup1
                        app.DataGroup1{d} = imputeGlucose(app.DataGroup1{d},maxGap);
                    end
                    for d = 1:app.NTracesGroup2
                        app.DataGroup2{d} = imputeGlucose(app.DataGroup2{d},maxGap);
                    end
                else
                    errordlg('The max gap value must be an integer.','Invalid value');
                end
                catch exception
                    errordlg('Imputation is not possible. Did you retime data first?','Impossible to impute');
                end
            end
            
            %Update UI components' state
            app.ImputeDataButton.Enable = true;
            app.LoadingStatusLabelGroup1.Text = ['Group 1: Ready (N = ' num2str(app.NTracesGroup1) ')'];
            app.LoadingStatusLabelGroup2.Text = ['Group 2: Ready (N = ' num2str(app.NTracesGroup2) ')'];
            app.StatusLamp.Color = 'g';
        end

        % Button pushed function: DetrendDataButton
        function DetrendDataButtonPushed(app, event)
            %Update UI components' state
            app.LoadingStatusLabelGroup1.Text = 'Processing...';
            app.LoadingStatusLabelGroup2.Text = 'Processing...';
            app.StatusLamp.Color = 'y';
            app.DetrendDataButton.Enable = false;
                
            try
                for d = 1:app.NTracesGroup1
                        app.DataGroup1{d} = detrendGlucose(app.DataGroup1{d});
                end
                for d = 1:app.NTracesGroup2
                        app.DataGroup2{d} = detrendGlucose(app.DataGroup2{d});
                end
            catch exception
                errordlg('Detrending is not possible. Did you retime data first?','Impossible to detrend');
            end
            
            %Update UI components' state
            app.DetrendDataButton.Enable = true;
            app.LoadingStatusLabelGroup1.Text = ['Group 1: Ready (N = ' num2str(app.NTracesGroup1) ')'];
            app.LoadingStatusLabelGroup2.Text = ['Group 2: Ready (N = ' num2str(app.NTracesGroup2) ')'];
            app.StatusLamp.Color = 'g';
        end

        % Button pushed function: CompareGlucoseDataAsOneDayButton
        function CompareGlucoseDataAsOneDayButtonPushed(app, event)
            
            %Update UI components' state
            app.CompareGlucoseDataAsOneDayButton.Enable = false;
            app.LoadingStatusLabelGroup1.Text = 'Plotting...';
            app.LoadingStatusLabelGroup2.Text = 'Plotting...';
            app.StatusLamp.Color = 'y';
            pause(0.001);
            
            try
                plotGlucoseArmAsOneDayComparison(app.DataGroup1,app.DataGroup2);
            catch exception
                errordlg('Impossible to generate the requested plot. Did you retimed data?','Impossible to plot');
            end
            
            %Update UI components' state
            app.CompareGlucoseDataAsOneDayButton.Enable = true;
            app.LoadingStatusLabelGroup1.Text = ['Group 1: Ready (N = ' num2str(app.NTracesGroup1) ')'];
            app.LoadingStatusLabelGroup2.Text = ['Group 2: Ready (N = ' num2str(app.NTracesGroup2) ')'];
            app.StatusLamp.Color = 'g';
        end

        % Button pushed function: CompareGlucoseCVGAButton
        function CompareGlucoseCVGAButtonPushed(app, event)
            
            %Update UI components' state
            app.CompareGlucoseCVGAButton.Enable = false;
            app.LoadingStatusLabelGroup1.Text = 'Plotting...';
            app.LoadingStatusLabelGroup2.Text = 'Plotting...';
            app.StatusLamp.Color = 'y';
            pause(0.001);
            
            plotCVGAComparison(app.DataGroup1,app.DataGroup2);
            
            %Update UI components' state
            app.CompareGlucoseCVGAButton.Enable = true;
            app.LoadingStatusLabelGroup1.Text = ['Group 1: Ready (N = ' num2str(app.NTracesGroup1) ')'];
            app.LoadingStatusLabelGroup2.Text = ['Group 2: Ready (N = ' num2str(app.NTracesGroup2) ')'];
            app.StatusLamp.Color = 'g';
        end

        % Button pushed function: AnalyzeGlucoseDataButton
        function AnalyzeGlucoseDataButtonPushed(app, event)
            
            %Update UI components' state
            app.AnalyzeGlucoseDataButton.Enable = false;
            app.LoadingStatusLabelGroup1.Text = 'Analyzing...';
            app.LoadingStatusLabelGroup2.Text = 'Analyzing...';
            app.StatusLamp.Color = 'y';
            pause(0.001);
            
            %Is it a paired or an unpaired test?
            prompt = {'Is the statistical test paired? (1: Paired, 0: Unpaired)'};
            dlgtitle = 'Paired test?';
            definput = {''};
            dims = [1 40];
            isPaired = inputdlg(prompt,dlgtitle,dims,definput);
            
            %Get the significance level
            prompt = {'Enter a value for the significance value to use (0 to 1):'};
            dlgtitle = 'Significance level';
            definput = {''};
            dims = [1 40];
            alpha = inputdlg(prompt,dlgtitle,dims,definput);
            
            if(~isempty(isPaired) && ~isempty(alpha))
                try
                    %Get the actual number
                    isPaired = str2num(isPaired{1});
                    %Get the actual number
                    alpha = str2num(alpha{1});
                    
                    if(~(isPaired == 0 || isPaired == 1) || ~(alpha >=0 && alpha <= 1))
                        error('Wrong parameter format.');
                    end
                
                    [results, stats] = compareTwoArms(app.DataGroup1,app.DataGroup2,isPaired,alpha);
                    %Set variability texts;
                    app.meanLabelG1.Text = [num2str(results.arm1.variability.meanGlucose.median) ' ['...
                        num2str(results.arm1.variability.meanGlucose.prc25) ', '...
                        num2str(results.arm1.variability.meanGlucose.prc75) ']'];
                    app.medianLabelG1.Text = [num2str(results.arm1.variability.medianGlucose.median) ' ['...
                        num2str(results.arm1.variability.medianGlucose.prc25) ', '...
                        num2str(results.arm1.variability.medianGlucose.prc75) ']'];
                    app.sdLabelG1.Text = [num2str(results.arm1.variability.stdGlucose.median) ' ['...
                        num2str(results.arm1.variability.stdGlucose.prc25) ', '...
                        num2str(results.arm1.variability.stdGlucose.prc75) ']'];
                    app.cvLabelG1.Text = [num2str(results.arm1.variability.cvGlucose.median) ' ['...
                        num2str(results.arm1.variability.cvGlucose.prc25) ', '...
                        num2str(results.arm1.variability.cvGlucose.prc75) ']'];
                    app.iqrLabelG1.Text = [num2str(results.arm1.variability.iqrGlucose.median) ' ['...
                        num2str(results.arm1.variability.iqrGlucose.prc25) ', '...
                        num2str(results.arm1.variability.iqrGlucose.prc75) ']'];
                    app.rangeLabelG1.Text = [num2str(results.arm1.variability.rangeGlucose.median) ' ['...
                        num2str(results.arm1.variability.rangeGlucose.prc25) ', '...
                        num2str(results.arm1.variability.rangeGlucose.prc75) ']'];
                    app.sdrocLabelG1.Text = [num2str(results.arm1.variability.stdGlucoseROC.median) ' ['...
                        num2str(results.arm1.variability.stdGlucoseROC.prc25) ', '...
                        num2str(results.arm1.variability.stdGlucoseROC.prc75) ']'];
                    app.aucLabelG1.Text = [num2str(results.arm1.variability.aucGlucose.median) ' ['...
                        num2str(results.arm1.variability.aucGlucose.prc25) ', '...
                        num2str(results.arm1.variability.aucGlucose.prc75) ']'];
                    app.cvgaLabelG1.Text = [num2str(results.arm1.variability.CVGA.median) ' ['...
                        num2str(results.arm1.variability.CVGA.prc25) ', '...
                        num2str(results.arm1.variability.CVGA.prc75) ']'];
                    app.jindexLabelG1.Text = [num2str(results.arm1.variability.jIndex.median) ' ['...
                        num2str(results.arm1.variability.jIndex.prc25) ', '...
                        num2str(results.arm1.variability.jIndex.prc75) ']'];
                    app.gmiLabelG1.Text = [num2str(results.arm1.variability.gmi.median) ' ['...
                        num2str(results.arm1.variability.gmi.prc25) ', '...
                        num2str(results.arm1.variability.gmi.prc75) ']'];
                    app.sddmindexLabelG1.Text = [num2str(results.arm1.variability.sddmIndex.median) ' ['...
                        num2str(results.arm1.variability.sddmIndex.prc25) ', '...
                        num2str(results.arm1.variability.sddmIndex.prc75) ']'];
                    app.sdwindexLabelG1.Text = [num2str(results.arm1.variability.sdwIndex.median) ' ['...
                        num2str(results.arm1.variability.sdwIndex.prc25) ', '...
                        num2str(results.arm1.variability.sdwIndex.prc75) ']'];
                    app.efindexLabelG1.Text =[num2str(results.arm1.variability.efIndex.median) ' ['...
                        num2str(results.arm1.variability.efIndex.prc25) ', '...
                        num2str(results.arm1.variability.efIndex.prc75) ']'];
                    app.mageindexLabelG1.Text = [num2str(results.arm1.variability.mageIndex.median) ' ['...
                        num2str(results.arm1.variability.mageIndex.prc25) ', '...
                        num2str(results.arm1.variability.mageIndex.prc75) ']'];
                    app.mageplusindexLabelG1.Text = [num2str(results.arm1.variability.magePlusIndex.median) ' ['...
                        num2str(results.arm1.variability.magePlusIndex.prc25) ', '...
                        num2str(results.arm1.variability.magePlusIndex.prc75) ']'];
                    app.mageminusindexLabelG1.Text = [num2str(results.arm1.variability.mageMinusIndex.median) ' ['...
                        num2str(results.arm1.variability.mageMinusIndex.prc25) ', '...
                        num2str(results.arm1.variability.mageMinusIndex.prc75) ']'];
                    app.moddLabelG1.Text = [num2str(results.arm1.variability.modd.median) ' ['...
                        num2str(results.arm1.variability.modd.prc25) ', '...
                        num2str(results.arm1.variability.modd.prc75) ']'];
                    app.congaLabelG1.Text = [num2str(results.arm1.variability.conga.median) ' ['...
                        num2str(results.arm1.variability.conga.prc25) ', '...
                        num2str(results.arm1.variability.conga.prc75) ']'];
                    app.cogiLabelG1.Text = [num2str(results.arm1.variability.cogi.median) ' ['...
                        num2str(results.arm1.variability.cogi.prc25) ', '...
                        num2str(results.arm1.variability.cogi.prc75) ']'];
                    
                    app.meanLabelG2.Text = [num2str(results.arm2.variability.meanGlucose.median) ' ['...
                        num2str(results.arm2.variability.meanGlucose.prc25) ', '...
                        num2str(results.arm2.variability.meanGlucose.prc75) ']'];
                    app.medianLabelG2.Text = [num2str(results.arm2.variability.medianGlucose.median) ' ['...
                        num2str(results.arm2.variability.medianGlucose.prc25) ', '...
                        num2str(results.arm2.variability.medianGlucose.prc75) ']'];
                    app.sdLabelG2.Text = [num2str(results.arm2.variability.stdGlucose.median) ' ['...
                        num2str(results.arm2.variability.stdGlucose.prc25) ', '...
                        num2str(results.arm2.variability.stdGlucose.prc75) ']'];
                    app.cvLabelG2.Text = [num2str(results.arm2.variability.cvGlucose.median) ' ['...
                        num2str(results.arm2.variability.cvGlucose.prc25) ', '...
                        num2str(results.arm2.variability.cvGlucose.prc75) ']'];
                    app.iqrLabelG2.Text = [num2str(results.arm2.variability.iqrGlucose.median) ' ['...
                        num2str(results.arm2.variability.iqrGlucose.prc25) ', '...
                        num2str(results.arm2.variability.iqrGlucose.prc75) ']'];
                    app.rangeLabelG2.Text = [num2str(results.arm2.variability.rangeGlucose.median) ' ['...
                        num2str(results.arm2.variability.rangeGlucose.prc25) ', '...
                        num2str(results.arm2.variability.rangeGlucose.prc75) ']'];
                    app.sdrocLabelG2.Text = [num2str(results.arm2.variability.stdGlucoseROC.median) ' ['...
                        num2str(results.arm2.variability.stdGlucoseROC.prc25) ', '...
                        num2str(results.arm2.variability.stdGlucoseROC.prc75) ']'];
                    app.aucLabelG2.Text = [num2str(results.arm2.variability.aucGlucose.median) ' ['...
                        num2str(results.arm2.variability.aucGlucose.prc25) ', '...
                        num2str(results.arm2.variability.aucGlucose.prc75) ']'];
                    app.cvgaLabelG2.Text = [num2str(results.arm2.variability.CVGA.median) ' ['...
                        num2str(results.arm2.variability.CVGA.prc25) ', '...
                        num2str(results.arm2.variability.CVGA.prc75) ']'];
                    app.jindexLabelG2.Text = [num2str(results.arm2.variability.jIndex.median) ' ['...
                        num2str(results.arm2.variability.jIndex.prc25) ', '...
                        num2str(results.arm2.variability.jIndex.prc75) ']'];
                    app.gmiLabelG2.Text = [num2str(results.arm2.variability.gmi.median) ' ['...
                        num2str(results.arm2.variability.gmi.prc25) ', '...
                        num2str(results.arm2.variability.gmi.prc75) ']'];
                    app.sddmindexLabelG2.Text = [num2str(results.arm2.variability.sddmIndex.median) ' ['...
                        num2str(results.arm2.variability.sddmIndex.prc25) ', '...
                        num2str(results.arm2.variability.sddmIndex.prc75) ']'];
                    app.sdwindexLabelG2.Text = [num2str(results.arm2.variability.sdwIndex.median) ' ['...
                        num2str(results.arm2.variability.sdwIndex.prc25) ', '...
                        num2str(results.arm2.variability.sdwIndex.prc75) ']'];
                    app.efindexLabelG2.Text =[num2str(results.arm2.variability.efIndex.median) ' ['...
                        num2str(results.arm2.variability.efIndex.prc25) ', '...
                        num2str(results.arm2.variability.efIndex.prc75) ']'];
                    app.mageindexLabelG2.Text = [num2str(results.arm2.variability.mageIndex.median) ' ['...
                        num2str(results.arm2.variability.mageIndex.prc25) ', '...
                        num2str(results.arm2.variability.mageIndex.prc75) ']'];
                    app.mageplusindexLabelG2.Text = [num2str(results.arm2.variability.magePlusIndex.median) ' ['...
                        num2str(results.arm2.variability.magePlusIndex.prc25) ', '...
                        num2str(results.arm2.variability.magePlusIndex.prc75) ']'];
                    app.mageminusindexLabelG2.Text = [num2str(results.arm2.variability.mageMinusIndex.median) ' ['...
                        num2str(results.arm2.variability.mageMinusIndex.prc25) ', '...
                        num2str(results.arm2.variability.mageMinusIndex.prc75) ']'];
                    app.moddLabelG2.Text = [num2str(results.arm2.variability.modd.median) ' ['...
                        num2str(results.arm2.variability.modd.prc25) ', '...
                        num2str(results.arm2.variability.modd.prc75) ']'];
                    app.congaLabelG2.Text = [num2str(results.arm2.variability.conga.median) ' ['...
                        num2str(results.arm2.variability.conga.prc25) ', '...
                        num2str(results.arm2.variability.conga.prc75) ']'];
                    app.cogiLabelG2.Text = [num2str(results.arm2.variability.cogi.median) ' ['...
                        num2str(results.arm2.variability.cogi.prc25) ', '...
                        num2str(results.arm2.variability.cogi.prc75) ']'];
                    
                    %Set risk texts
                    app.adrrLabelG1.Text = [num2str(results.arm1.risk.adrr.median) ' ['...
                        num2str(results.arm1.risk.adrr.prc25) ', '...
                        num2str(results.arm1.risk.adrr.prc75) ']'];
                    app.bgriLabelG1.Text = [num2str(results.arm1.risk.bgri.median) ' ['...
                        num2str(results.arm1.risk.bgri.prc25) ', '...
                        num2str(results.arm1.risk.bgri.prc75) ']'];
                    app.lbgiLabelG1.Text = [num2str(results.arm1.risk.lbgi.median) ' ['...
                        num2str(results.arm1.risk.lbgi.prc25) ', '...
                        num2str(results.arm1.risk.lbgi.prc75) ']'];
                    app.hbgiLabelG1.Text = [num2str(results.arm1.risk.hbgi.median) ' ['...
                        num2str(results.arm1.risk.hbgi.prc25) ', '...
                        num2str(results.arm1.risk.hbgi.prc75) ']'];
                    app.griLabelG1.Text = [num2str(results.arm1.risk.gri.median) ' ['...
                        num2str(results.arm1.risk.gri.prc25) ', '...
                        num2str(results.arm1.risk.gri.prc75) ']'];
                    
                    app.adrrLabelG2.Text = [num2str(results.arm2.risk.adrr.median) ' ['...
                        num2str(results.arm2.risk.adrr.prc25) ', '...
                        num2str(results.arm2.risk.adrr.prc75) ']'];
                    app.bgriLabelG2.Text = [num2str(results.arm2.risk.bgri.median) ' ['...
                        num2str(results.arm2.risk.bgri.prc25) ', '...
                        num2str(results.arm2.risk.bgri.prc75) ']'];
                    app.lbgiLabelG2.Text = [num2str(results.arm2.risk.lbgi.median) ' ['...
                        num2str(results.arm2.risk.lbgi.prc25) ', '...
                        num2str(results.arm2.risk.lbgi.prc75) ']'];
                    app.hbgiLabelG2.Text = [num2str(results.arm2.risk.hbgi.median) ' ['...
                        num2str(results.arm2.risk.hbgi.prc25) ', '...
                        num2str(results.arm2.risk.hbgi.prc75) ']'];
                    app.griLabelG2.Text = [num2str(results.arm2.risk.gri.median) ' ['...
                        num2str(results.arm2.risk.gri.prc25) ', '...
                        num2str(results.arm2.risk.gri.prc75) ']'];
                    
                    %Set time texts
                    app.timehypoLabelG1.Text = [num2str(results.arm1.time.timeInHypoglycemia.median) ' ['...
                        num2str(results.arm1.time.timeInHypoglycemia.prc25) ', '...
                        num2str(results.arm1.time.timeInHypoglycemia.prc75) ']'];
                    app.timelevel1hypoLabelG1.Text = [num2str(results.arm1.time.timeInL1Hypoglycemia.median) ' ['...
                        num2str(results.arm1.time.timeInL1Hypoglycemia.prc25) ', '...
                        num2str(results.arm1.time.timeInL1Hypoglycemia.prc75) ']'];
                    app.timelevel2hypoLabelG1.Text = [num2str(results.arm1.time.timeInL2Hypoglycemia.median) ' ['...
                        num2str(results.arm1.time.timeInL2Hypoglycemia.prc25) ', '...
                        num2str(results.arm1.time.timeInL2Hypoglycemia.prc75) ']'];
                    
                    app.timehyperLabelG1.Text = [num2str(results.arm1.time.timeInHyperglycemia.median) ' ['...
                        num2str(results.arm1.time.timeInHyperglycemia.prc25) ', '...
                        num2str(results.arm1.time.timeInHyperglycemia.prc75) ']'];
                    app.timelevel1hyperLabelG1.Text = [num2str(results.arm1.time.timeInL1Hyperglycemia.median) ' ['...
                        num2str(results.arm1.time.timeInL1Hyperglycemia.prc25) ', '...
                        num2str(results.arm1.time.timeInL1Hyperglycemia.prc75) ']'];
                    app.timelevel2hyperLabelG1.Text = [num2str(results.arm1.time.timeInL2Hyperglycemia.median) ' ['...
                        num2str(results.arm1.time.timeInL2Hyperglycemia.prc25) ', '...
                        num2str(results.arm1.time.timeInL2Hyperglycemia.prc75) ']'];
                    
                    app.timetargetLabelG1.Text = [num2str(results.arm1.time.timeInTarget.median) ' ['...
                        num2str(results.arm1.time.timeInTarget.prc25) ', '...
                        num2str(results.arm1.time.timeInTarget.prc75) ']'];
                    app.timetighttargetLabelG1.Text = [num2str(results.arm1.time.timeInTightTarget.median) ' ['...
                        num2str(results.arm1.time.timeInTightTarget.prc25) ', '...
                        num2str(results.arm1.time.timeInTightTarget.prc75) ']'];
                    
                    app.timehypoLabelG2.Text = [num2str(results.arm2.time.timeInHypoglycemia.median) ' ['...
                        num2str(results.arm2.time.timeInHypoglycemia.prc25) ', '...
                        num2str(results.arm2.time.timeInHypoglycemia.prc75) ']'];
                    app.timelevel1hypoLabelG2.Text = [num2str(results.arm2.time.timeInL1Hypoglycemia.median) ' ['...
                        num2str(results.arm2.time.timeInL1Hypoglycemia.prc25) ', '...
                        num2str(results.arm2.time.timeInL1Hypoglycemia.prc75) ']'];
                    app.timelevel2hypoLabelG2.Text = [num2str(results.arm2.time.timeInL2Hypoglycemia.median) ' ['...
                        num2str(results.arm2.time.timeInL2Hypoglycemia.prc25) ', '...
                        num2str(results.arm2.time.timeInL2Hypoglycemia.prc75) ']'];
                    
                    app.timehyperLabelG2.Text = [num2str(results.arm2.time.timeInHyperglycemia.median) ' ['...
                        num2str(results.arm2.time.timeInHyperglycemia.prc25) ', '...
                        num2str(results.arm2.time.timeInHyperglycemia.prc75) ']'];
                    app.timelevel1hyperLabelG2.Text = [num2str(results.arm2.time.timeInL1Hyperglycemia.median) ' ['...
                        num2str(results.arm2.time.timeInL1Hyperglycemia.prc25) ', '...
                        num2str(results.arm2.time.timeInL1Hyperglycemia.prc75) ']'];
                    app.timelevel2hyperLabelG2.Text = [num2str(results.arm2.time.timeInL2Hyperglycemia.median) ' ['...
                        num2str(results.arm2.time.timeInL2Hyperglycemia.prc25) ', '...
                        num2str(results.arm2.time.timeInL2Hyperglycemia.prc75) ']'];
                    
                    app.timetargetLabelG2.Text = [num2str(results.arm2.time.timeInTarget.median) ' ['...
                        num2str(results.arm2.time.timeInTarget.prc25) ', '...
                        num2str(results.arm2.time.timeInTarget.prc75) ']'];
                    app.timetighttargetLabelG2.Text = [num2str(results.arm2.time.timeInTightTarget.median) ' ['...
                        num2str(results.arm2.time.timeInTightTarget.prc25) ', '...
                        num2str(results.arm2.time.timeInTightTarget.prc75) ']'];
                    
                    %Set glycemic transformation texts
                    app.gradeLabelG1.Text = [num2str(results.arm1.glycemicTransformation.gradeScore.median) ' ['...
                        num2str(results.arm1.glycemicTransformation.gradeScore.prc25) ', '...
                        num2str(results.arm1.glycemicTransformation.gradeScore.prc75) ']'];
                    app.gradeeuLabelG1.Text = [num2str(results.arm1.glycemicTransformation.gradeEuScore.median) ' ['...
                        num2str(results.arm1.glycemicTransformation.gradeEuScore.prc25) ', '...
                        num2str(results.arm1.glycemicTransformation.gradeEuScore.prc75) ']'];
                    app.gradehypoLabelG1.Text = [num2str(results.arm1.glycemicTransformation.gradeHypoScore.median) ' ['...
                        num2str(results.arm1.glycemicTransformation.gradeHypoScore.prc25) ', '...
                        num2str(results.arm1.glycemicTransformation.gradeHypoScore.prc75) ']'];
                    app.gradehyperLabelG1.Text = [num2str(results.arm1.glycemicTransformation.gradeHyperScore.median) ' ['...
                        num2str(results.arm1.glycemicTransformation.gradeHyperScore.prc25) ', '...
                        num2str(results.arm1.glycemicTransformation.gradeHyperScore.prc75) ']'];
                    app.igcLabelG1.Text = [num2str(results.arm1.glycemicTransformation.igc.median) ' ['...
                        num2str(results.arm1.glycemicTransformation.igc.prc25) ', '...
                        num2str(results.arm1.glycemicTransformation.igc.prc75) ']'];
                    app.hypoindexLabelG1.Text = [num2str(results.arm1.glycemicTransformation.hypoIndex.median) ' ['...
                        num2str(results.arm1.glycemicTransformation.hypoIndex.prc25) ', '...
                        num2str(results.arm1.glycemicTransformation.hypoIndex.prc75) ']'];
                    app.hyperindexLabelG1.Text = [num2str(results.arm1.glycemicTransformation.hyperIndex.median) ' ['...
                        num2str(results.arm1.glycemicTransformation.hyperIndex.prc25) ', '...
                        num2str(results.arm1.glycemicTransformation.hyperIndex.prc75) ']'];
                    app.mrLabelG1.Text = [num2str(results.arm1.glycemicTransformation.mrIndex.median) ' ['...
                        num2str(results.arm1.glycemicTransformation.mrIndex.prc25) ', '...
                        num2str(results.arm1.glycemicTransformation.mrIndex.prc75) ']'];
                    
                    app.gradeLabelG2.Text = [num2str(results.arm2.glycemicTransformation.gradeScore.median) ' ['...
                        num2str(results.arm2.glycemicTransformation.gradeScore.prc25) ', '...
                        num2str(results.arm2.glycemicTransformation.gradeScore.prc75) ']'];
                    app.gradeeuLabelG2.Text = [num2str(results.arm2.glycemicTransformation.gradeEuScore.median) ' ['...
                        num2str(results.arm2.glycemicTransformation.gradeEuScore.prc25) ', '...
                        num2str(results.arm2.glycemicTransformation.gradeEuScore.prc75) ']'];
                    app.gradehypoLabelG2.Text = [num2str(results.arm2.glycemicTransformation.gradeHypoScore.median) ' ['...
                        num2str(results.arm2.glycemicTransformation.gradeHypoScore.prc25) ', '...
                        num2str(results.arm2.glycemicTransformation.gradeHypoScore.prc75) ']'];
                    app.gradehyperLabelG2.Text = [num2str(results.arm2.glycemicTransformation.gradeHyperScore.median) ' ['...
                        num2str(results.arm2.glycemicTransformation.gradeHyperScore.prc25) ', '...
                        num2str(results.arm2.glycemicTransformation.gradeHyperScore.prc75) ']'];
                    app.igcLabelG2.Text = [num2str(results.arm2.glycemicTransformation.igc.median) ' ['...
                        num2str(results.arm2.glycemicTransformation.igc.prc25) ', '...
                        num2str(results.arm2.glycemicTransformation.igc.prc75) ']'];
                    app.hypoindexLabelG2.Text = [num2str(results.arm2.glycemicTransformation.hypoIndex.median) ' ['...
                        num2str(results.arm2.glycemicTransformation.hypoIndex.prc25) ', '...
                        num2str(results.arm2.glycemicTransformation.hypoIndex.prc75) ']'];
                    app.hyperindexLabelG2.Text = [num2str(results.arm2.glycemicTransformation.hyperIndex.median) ' ['...
                        num2str(results.arm2.glycemicTransformation.hyperIndex.prc25) ', '...
                        num2str(results.arm2.glycemicTransformation.hyperIndex.prc75) ']'];
                    app.mrLabelG2.Text = [num2str(results.arm2.glycemicTransformation.mrIndex.median) ' ['...
                        num2str(results.arm2.glycemicTransformation.mrIndex.prc25) ', '...
                        num2str(results.arm2.glycemicTransformation.mrIndex.prc75) ']'];
                    
                    %Set the data quality texts
                    app.missingpercentageLabelG1.Text = [num2str(results.arm1.dataQuality.missingGlucosePercentage.median) ' ['...
                        num2str(results.arm1.dataQuality.missingGlucosePercentage.prc25) ', '...
                        num2str(results.arm1.dataQuality.missingGlucosePercentage.prc75) ']'];
                    app.numberDaysLabelG1.Text = [num2str(results.arm1.dataQuality.numberDaysOfObservation.median) ' ['...
                        num2str(results.arm1.dataQuality.numberDaysOfObservation.prc25) ', '...
                        num2str(results.arm1.dataQuality.numberDaysOfObservation.prc75) ']'];
                    
                    app.missingpercentageLabelG2.Text = [num2str(results.arm2.dataQuality.missingGlucosePercentage.median) ' ['...
                        num2str(results.arm2.dataQuality.missingGlucosePercentage.prc25) ', '...
                        num2str(results.arm2.dataQuality.missingGlucosePercentage.prc75) ']'];
                    app.numberDaysLabelG2.Text = [num2str(results.arm2.dataQuality.numberDaysOfObservation.median) ' ['...
                        num2str(results.arm2.dataQuality.numberDaysOfObservation.prc25) ', '...
                        num2str(results.arm2.dataQuality.numberDaysOfObservation.prc75) ']'];
                    
                    %Set the events texts
                    app.hypodurationLabelG1.Text = [num2str(results.arm1.event.hypoglycemicEvents.hypo.meanDuration.median) ' ['...
                        num2str(results.arm1.event.hypoglycemicEvents.hypo.meanDuration.prc25) ', '...
                        num2str(results.arm1.event.hypoglycemicEvents.hypo.meanDuration.prc75) ']'];
                    app.hypoperweekLabelG1.Text = [num2str(results.arm1.event.hypoglycemicEvents.hypo.eventsPerWeek.median) ' ['...
                        num2str(results.arm1.event.hypoglycemicEvents.hypo.eventsPerWeek.prc25) ', '...
                        num2str(results.arm1.event.hypoglycemicEvents.hypo.eventsPerWeek.prc75) ']'];
                    app.l1hypodurationLabelG1.Text = [num2str(results.arm1.event.hypoglycemicEvents.l1.meanDuration.median) ' ['...
                        num2str(results.arm1.event.hypoglycemicEvents.l1.meanDuration.prc25) ', '...
                        num2str(results.arm1.event.hypoglycemicEvents.l1.meanDuration.prc75) ']'];
                    app.l1hypoperweekLabelG1.Text = [num2str(results.arm1.event.hypoglycemicEvents.l1.eventsPerWeek.median) ' ['...
                        num2str(results.arm1.event.hypoglycemicEvents.l1.eventsPerWeek.prc25) ', '...
                        num2str(results.arm1.event.hypoglycemicEvents.l1.eventsPerWeek.prc75) ']'];
                    app.l2hypodurationLabelG1.Text = [num2str(results.arm1.event.hypoglycemicEvents.l2.meanDuration.median) ' ['...
                        num2str(results.arm1.event.hypoglycemicEvents.l2.meanDuration.prc25) ', '...
                        num2str(results.arm1.event.hypoglycemicEvents.l2.meanDuration.prc75) ']'];
                    app.l2hypoperweekLabelG1.Text = [num2str(results.arm1.event.hypoglycemicEvents.l2.eventsPerWeek.median) ' ['...
                        num2str(results.arm1.event.hypoglycemicEvents.l2.eventsPerWeek.prc25) ', '...
                        num2str(results.arm1.event.hypoglycemicEvents.l2.eventsPerWeek.prc75) ']'];
                    
                    app.extendedhypodurationLabelG1.Text = [num2str(results.arm1.event.extendedHypoglycemicEvents.meanDuration.median) ' ['...
                        num2str(results.arm1.event.extendedHypoglycemicEvents.meanDuration.prc25) ', '...
                        num2str(results.arm1.event.extendedHypoglycemicEvents.meanDuration.prc75) ']'];
                    app.extendedhypoperweekLabelG1.Text = [num2str(results.arm1.event.extendedHypoglycemicEvents.eventsPerWeek.median) ' ['...
                        num2str(results.arm1.event.extendedHypoglycemicEvents.eventsPerWeek.prc25) ', '...
                        num2str(results.arm1.event.extendedHypoglycemicEvents.eventsPerWeek.prc75) ']'];
                    
                    app.hyperdurationLabelG1.Text = [num2str(results.arm1.event.hyperglycemicEvents.hyper.meanDuration.median) ' ['...
                        num2str(results.arm1.event.hyperglycemicEvents.hyper.meanDuration.prc25) ', '...
                        num2str(results.arm1.event.hyperglycemicEvents.hyper.meanDuration.prc75) ']'];
                    app.hyperperweekLabelG1.Text = [num2str(results.arm1.event.hyperglycemicEvents.hyper.eventsPerWeek.median) ' ['...
                        num2str(results.arm1.event.hyperglycemicEvents.hyper.eventsPerWeek.prc25) ', '...
                        num2str(results.arm1.event.hyperglycemicEvents.hyper.eventsPerWeek.prc75) ']'];
                    app.l1hyperdurationLabelG1.Text = [num2str(results.arm1.event.hyperglycemicEvents.l1.meanDuration.median) ' ['...
                        num2str(results.arm1.event.hyperglycemicEvents.l1.meanDuration.prc25) ', '...
                        num2str(results.arm1.event.hyperglycemicEvents.l1.meanDuration.prc75) ']'];
                    app.l1hyperperweekLabelG1.Text = [num2str(results.arm1.event.hyperglycemicEvents.l1.eventsPerWeek.median) ' ['...
                        num2str(results.arm1.event.hyperglycemicEvents.l1.eventsPerWeek.prc25) ', '...
                        num2str(results.arm1.event.hyperglycemicEvents.l1.eventsPerWeek.prc75) ']'];
                    app.l2hyperdurationLabelG1.Text = [num2str(results.arm1.event.hyperglycemicEvents.l2.meanDuration.median) ' ['...
                        num2str(results.arm1.event.hyperglycemicEvents.l2.meanDuration.prc25) ', '...
                        num2str(results.arm1.event.hyperglycemicEvents.l2.meanDuration.prc75) ']'];
                    app.l2hyperperweekLabelG1.Text = [num2str(results.arm1.event.hyperglycemicEvents.l2.eventsPerWeek.median) ' ['...
                        num2str(results.arm1.event.hyperglycemicEvents.l2.eventsPerWeek.prc25) ', '...
                        num2str(results.arm1.event.hyperglycemicEvents.l2.eventsPerWeek.prc75) ']'];
                    
                    app.hypodurationLabelG2.Text = [num2str(results.arm2.event.hypoglycemicEvents.hypo.meanDuration.median) ' ['...
                        num2str(results.arm2.event.hypoglycemicEvents.hypo.meanDuration.prc25) ', '...
                        num2str(results.arm2.event.hypoglycemicEvents.hypo.meanDuration.prc75) ']'];
                    app.hypoperweekLabelG2.Text = [num2str(results.arm2.event.hypoglycemicEvents.hypo.eventsPerWeek.median) ' ['...
                        num2str(results.arm2.event.hypoglycemicEvents.hypo.eventsPerWeek.prc25) ', '...
                        num2str(results.arm2.event.hypoglycemicEvents.hypo.eventsPerWeek.prc75) ']'];
                    app.l1hypodurationLabelG2.Text = [num2str(results.arm2.event.hypoglycemicEvents.l1.meanDuration.median) ' ['...
                        num2str(results.arm2.event.hypoglycemicEvents.l1.meanDuration.prc25) ', '...
                        num2str(results.arm2.event.hypoglycemicEvents.l1.meanDuration.prc75) ']'];
                    app.l1hypoperweekLabelG2.Text = [num2str(results.arm2.event.hypoglycemicEvents.l1.eventsPerWeek.median) ' ['...
                        num2str(results.arm2.event.hypoglycemicEvents.l1.eventsPerWeek.prc25) ', '...
                        num2str(results.arm2.event.hypoglycemicEvents.l1.eventsPerWeek.prc75) ']'];
                    app.l2hypodurationLabelG2.Text = [num2str(results.arm2.event.hypoglycemicEvents.l2.meanDuration.median) ' ['...
                        num2str(results.arm2.event.hypoglycemicEvents.l2.meanDuration.prc25) ', '...
                        num2str(results.arm2.event.hypoglycemicEvents.l2.meanDuration.prc75) ']'];
                    app.l2hypoperweekLabelG2.Text = [num2str(results.arm2.event.hypoglycemicEvents.l2.eventsPerWeek.median) ' ['...
                        num2str(results.arm2.event.hypoglycemicEvents.l2.eventsPerWeek.prc25) ', '...
                        num2str(results.arm2.event.hypoglycemicEvents.l2.eventsPerWeek.prc75) ']'];
                    
                    
                    app.extendedhypodurationLabelG2.Text = [num2str(results.arm2.event.extendedHypoglycemicEvents.meanDuration.median) ' ['...
                        num2str(results.arm2.event.extendedHypoglycemicEvents.meanDuration.prc25) ', '...
                        num2str(results.arm2.event.extendedHypoglycemicEvents.meanDuration.prc75) ']'];
                    app.extendedhypoperweekLabelG2.Text = [num2str(results.arm2.event.extendedHypoglycemicEvents.eventsPerWeek.median) ' ['...
                        num2str(results.arm2.event.extendedHypoglycemicEvents.eventsPerWeek.prc25) ', '...
                        num2str(results.arm2.event.extendedHypoglycemicEvents.eventsPerWeek.prc75) ']'];
                    
                    app.hyperdurationLabelG2.Text = [num2str(results.arm2.event.hyperglycemicEvents.hyper.meanDuration.median) ' ['...
                        num2str(results.arm2.event.hyperglycemicEvents.hyper.meanDuration.prc25) ', '...
                        num2str(results.arm2.event.hyperglycemicEvents.hyper.meanDuration.prc75) ']'];
                    app.hyperperweekLabelG2.Text = [num2str(results.arm2.event.hyperglycemicEvents.hyper.eventsPerWeek.median) ' ['...
                        num2str(results.arm2.event.hyperglycemicEvents.hyper.eventsPerWeek.prc25) ', '...
                        num2str(results.arm2.event.hyperglycemicEvents.hyper.eventsPerWeek.prc75) ']'];
                    app.l1hyperdurationLabelG2.Text = [num2str(results.arm2.event.hyperglycemicEvents.l1.meanDuration.median) ' ['...
                        num2str(results.arm2.event.hyperglycemicEvents.l1.meanDuration.prc25) ', '...
                        num2str(results.arm2.event.hyperglycemicEvents.l1.meanDuration.prc75) ']'];
                    app.l1hyperperweekLabelG2.Text = [num2str(results.arm2.event.hyperglycemicEvents.l1.eventsPerWeek.median) ' ['...
                        num2str(results.arm2.event.hyperglycemicEvents.l1.eventsPerWeek.prc25) ', '...
                        num2str(results.arm2.event.hyperglycemicEvents.l1.eventsPerWeek.prc75) ']'];
                    app.l2hyperdurationLabelG2.Text = [num2str(results.arm2.event.hyperglycemicEvents.l2.meanDuration.median) ' ['...
                        num2str(results.arm2.event.hyperglycemicEvents.l2.meanDuration.prc25) ', '...
                        num2str(results.arm2.event.hyperglycemicEvents.l2.meanDuration.prc75) ']'];
                    app.l2hyperperweekLabelG2.Text = [num2str(results.arm2.event.hyperglycemicEvents.l2.eventsPerWeek.median) ' ['...
                        num2str(results.arm2.event.hyperglycemicEvents.l2.eventsPerWeek.prc25) ', '...
                        num2str(results.arm2.event.hyperglycemicEvents.l2.eventsPerWeek.prc75) ']'];
                    
                    %Set the pvalue texts
                    app.pmeanLabel.Text = num2str(stats.variability.meanGlucose.p);
                    if(stats.variability.meanGlucose.h == true)
                        app.pmeanLabel.Text = [app.pmeanLabel.Text '*'];
                        app.pmeanLabel.FontWeight = 'bold';
                    end
                    app.pmedianLabel.Text = num2str(stats.variability.medianGlucose.p);
                    if(stats.variability.medianGlucose.h == true)
                        app.pmedianLabel.Text = [app.pmedianLabel.Text '*'];
                        app.pmedianLabel.FontWeight = 'bold';
                    end
                    app.psdLabel.Text = num2str(stats.variability.stdGlucose.p);
                    if(stats.variability.stdGlucose.h == true)
                        app.psdLabel.Text = [app.psdLabel.Text '*'];
                        app.psdLabel.FontWeight = 'bold';
                    end
                    app.pcvLabel.Text = num2str(stats.variability.cvGlucose.p);
                    if(stats.variability.cvGlucose.h == true)
                        app.pcvLabel.Text = [app.pcvLabel.Text '*'];
                        app.pcvLabel.FontWeight = 'bold';
                    end
                    app.piqrLabel.Text = num2str(stats.variability.iqrGlucose.p);
                    if(stats.variability.iqrGlucose.h == true)
                        app.piqrLabel.Text = [app.piqrLabel.Text '*'];
                        app.piqrLabel.FontWeight = 'bold';
                    end
                    app.prangeLabel.Text = num2str(stats.variability.rangeGlucose.p);
                    if(stats.variability.rangeGlucose.h == true)
                        app.prangeLabel.Text = [app.prangeLabel.Text '*'];
                        app.prangeLabel.FontWeight = 'bold';
                    end
                    app.psdrocLabel.Text = num2str(stats.variability.stdGlucoseROC.p);
                    if(stats.variability.stdGlucoseROC.h == true)
                        app.psdrocLabel.Text = [app.psdrocLabel.Text '*'];
                        app.psdrocLabel.FontWeight = 'bold';
                    end
                    app.paucLabel.Text = num2str(stats.variability.aucGlucose.p);
                    if(stats.variability.aucGlucose.h == true)
                        app.paucLabel.Text = [app.paucLabel.Text '*'];
                        app.paucLabel.FontWeight = 'bold';
                    end
                    app.pCVGALabel.Text = num2str(stats.variability.CVGA.p);
                    if(stats.variability.CVGA.h == true)
                        app.pCVGALabel.Text = [app.pCVGALabel.Text '*'];
                        app.pCVGALabel.FontWeight = 'bold';
                    end
                    app.pjLabel.Text = num2str(stats.variability.jIndex.p);
                    if(stats.variability.jIndex.h == true)
                        app.pjLabel.Text = [app.pjLabel.Text '*'];
                        app.pjLabel.FontWeight = 'bold';
                    end
                    app.pgmiLabel.Text = num2str(stats.variability.gmi.p);
                    if(stats.variability.gmi.h == true)
                        app.pgmiLabel.Text = [app.pgmiLabel.Text '*'];
                        app.pgmiLabel.FontWeight = 'bold';
                    end
                    app.psddmLabel.Text = num2str(stats.variability.sddmIndex.p);
                    if(stats.variability.sddmIndex.h == true)
                        app.psddmLabel.Text = [app.psddmLabel.Text '*'];
                        app.psddmLabel.FontWeight = 'bold';
                    end
                    app.psdwLabel.Text = num2str(stats.variability.sdwIndex.p);
                    if(stats.variability.sdwIndex.h == true)
                        app.psdwLabel.Text = [app.psdwLabel.Text '*'];
                        app.psdwLabel.FontWeight = 'bold';
                    end
                    app.pEFLabel.Text = num2str(stats.variability.efIndex.p);
                    if(stats.variability.efIndex.h == true)
                        app.pEFLabel.Text = [app.pEFLabel.Text '*'];
                        app.pEFLabel.FontWeight = 'bold';
                    end
                    app.pmageLabel.Text = num2str(stats.variability.mageIndex.p);
                    if(stats.variability.mageIndex.h == true)
                        app.pmageLabel.Text = [app.pmageLabel.Text '*'];
                        app.pmageLabel.FontWeight = 'bold';
                    end
                    app.pmagePlusLabel.Text = num2str(stats.variability.magePlusIndex.p);
                    if(stats.variability.magePlusIndex.h == true)
                        app.pmagePlusLabel.Text = [app.pmagePlusLabel.Text '*'];
                        app.pmagePlusLabel.FontWeight = 'bold';
                    end
                    app.pmageMinusLabel.Text = num2str(stats.variability.mageMinusIndex.p);
                    if(stats.variability.mageMinusIndex.h == true)
                        app.pmageMinusLabel.Text = [app.pmageMinusLabel.Text '*'];
                        app.pmageMinusLabel.FontWeight = 'bold';
                    end
                    app.pcongaLabel.Text = num2str(stats.variability.conga.p);
                    if(stats.variability.conga.h == true)
                        app.pcongaLabel.Text = [app.pcongaLabel.Text '*'];
                        app.pcongaLabel.FontWeight = 'bold';
                    end
                    app.pmoddLabel.Text = num2str(stats.variability.modd.p);
                    if(stats.variability.modd.h == true)
                        app.pmoddLabel.Text = [app.pmoddLabel.Text '*'];
                        app.pmoddLabel.FontWeight = 'bold';
                    end
                    app.pcogiLabel.Text = num2str(stats.variability.cogi.p);
                    if(stats.variability.cogi.h == true)
                        app.pcogiLabel.Text = [app.pcogiLabel.Text '*'];
                        app.pcogiLabel.FontWeight = 'bold';
                    end
                    
                    app.padrrLabel.Text = num2str(stats.risk.adrr.p);
                    if(stats.risk.adrr.h == true)
                        app.padrrLabel.Text = [app.padrrLabel.Text '*'];
                        app.padrrLabel.FontWeight = 'bold';
                    end
                    app.plbgiLabel.Text = num2str(stats.risk.lbgi.p);
                    if(stats.risk.lbgi.h == true)
                        app.plbgiLabel.Text = [app.plbgiLabel.Text '*'];
                        app.plbgiLabel.FontWeight = 'bold';
                    end
                    app.phbgiLabel.Text = num2str(stats.risk.hbgi.p);
                    if(stats.risk.hbgi.h == true)
                        app.phbgiLabel.Text = [app.phbgiLabel.Text '*'];
                        app.phbgiLabel.FontWeight = 'bold';
                    end
                    app.pbgriLabel.Text = num2str(stats.risk.bgri.p);
                    if(stats.risk.bgri.h == true)
                        app.pbgriLabel.Text = [app.pbgriLabel.Text '*'];
                        app.pbgriLabel.FontWeight = 'bold';
                    end
                    app.pgriLabel.Text = num2str(stats.risk.gri.p);
                    if(stats.risk.gri.h == true)
                        app.pgriLabel.Text = [app.pgriLabel.Text '*'];
                        app.pgriLabel.FontWeight = 'bold';
                    end
                    
                    
                    app.phypoLabel.Text = num2str(stats.time.timeInHypoglycemia.p);
                    if(stats.time.timeInHypoglycemia.h == true)
                        app.phypoLabel.Text = [app.phypoLabel.Text '*'];
                        app.phypoLabel.FontWeight = 'bold';
                    end
                    app.phyperLabel.Text = num2str(stats.time.timeInHyperglycemia.p);
                    if(stats.time.timeInHyperglycemia.h == true)
                        app.phyperLabel.Text = [app.phyperLabel.Text '*'];
                        app.phyperLabel.FontWeight = 'bold';
                    end
                    app.pl1hypoLabel.Text = num2str(stats.time.timeInL1Hypoglycemia.p);
                    if(stats.time.timeInL1Hypoglycemia.h == true)
                        app.pl1hypoLabel.Text = [app.pl1hypoLabel.Text '*'];
                        app.pl1hypoLabel.FontWeight = 'bold';
                    end
                    app.pl1hyperLabel.Text = num2str(stats.time.timeInL1Hyperglycemia.p);
                    if(stats.time.timeInL1Hyperglycemia.h == true)
                        app.pl1hyperLabel.Text = [app.pl1hyperLabel.Text '*'];
                        app.pl1hyperLabel.FontWeight = 'bold';
                    end
                    app.ptargetLabel.Text = num2str(stats.time.timeInTarget.p);
                    if(stats.time.timeInTarget.h == true)
                        app.ptargetLabel.Text = [app.ptargetLabel.Text '*'];
                        app.ptargetLabel.FontWeight = 'bold';
                    end
                    app.pl2hypoLabel.Text = num2str(stats.time.timeInL2Hypoglycemia.p);
                    if(stats.time.timeInL2Hypoglycemia.h == true)
                        app.pl2hypoLabel.Text = [app.pl2hypoLabel.Text '*'];
                        app.pl2hypoLabel.FontWeight = 'bold';
                    end
                    app.pl2hyperLabel.Text = num2str(stats.time.timeInL2Hyperglycemia.p);
                    if(stats.time.timeInL2Hyperglycemia.h == true)
                        app.pl2hyperLabel.Text = [app.pl2hyperLabel.Text '*'];
                        app.pl2hyperLabel.FontWeight = 'bold';
                    end
                    app.pttargetLabel.Text = num2str(stats.time.timeInTightTarget.p);
                    if(stats.time.timeInTightTarget.h == true)
                        app.pttargetLabel.Text = [app.pttargetLabel.Text '*'];
                        app.pttargetLabel.FontWeight = 'bold';
                    end
                    
                    app.pgradeLabel.Text = num2str(stats.glycemicTransformation.gradeScore.p);
                    if(stats.glycemicTransformation.gradeScore.h == true)
                        app.pgradeLabel.Text = [app.pgradeLabel.Text '*'];
                        app.pgradeLabel.FontWeight = 'bold';
                    end
                    app.pgradeeuLabel.Text = num2str(stats.glycemicTransformation.gradeEuScore.p);
                    if(stats.glycemicTransformation.gradeEuScore.h == true)
                        app.pgradeeuLabel.Text = [app.pgradeeuLabel.Text '*'];
                        app.pgradeeuLabel.FontWeight = 'bold';
                    end
                    app.pgradeHyperLabel.Text = num2str(stats.glycemicTransformation.gradeHyperScore.p);
                    if(stats.glycemicTransformation.gradeHyperScore.h == true)
                        app.pgradeHyperLabel.Text = [app.pgradeHyperLabel.Text '*'];
                        app.pgradeHyperLabel.FontWeight = 'bold';
                    end
                    app.pgradeHypoLabel.Text = '<pvalue>';
                    app.pgradeHypoLabel.Text = num2str(stats.glycemicTransformation.gradeHypoScore.p);
                    if(stats.glycemicTransformation.gradeHypoScore.h == true)
                        app.pgradeHypoLabel.Text = [app.pgradeHypoLabel.Text '*'];
                        app.pgradeHypoLabel.FontWeight = 'bold';
                    end
                    app.pigcLabel.Text = num2str(stats.glycemicTransformation.igc.p);
                    if(stats.glycemicTransformation.igc.h == true)
                        app.pigcLabel.Text = [app.pigcLabel.Text '*'];
                        app.pigcLabel.FontWeight = 'bold';
                    end
                    app.phypoindLabel.Text = num2str(stats.glycemicTransformation.hypoIndex.p);
                    if(stats.glycemicTransformation.hypoIndex.h == true)
                        app.phypoindLabel.Text = [app.phypoindLabel.Text '*'];
                        app.phypoindLabel.FontWeight = 'bold';
                    end
                    app.phyperindLabel.Text = num2str(stats.glycemicTransformation.hyperIndex.p);
                    if(stats.glycemicTransformation.hyperIndex.h == true)
                        app.phyperindLabel.Text = [app.phyperindLabel.Text '*'];
                        app.phyperindLabel.FontWeight = 'bold';
                    end
                    app.pmrLabel.Text = num2str(stats.glycemicTransformation.mrIndex.p);
                    if(stats.glycemicTransformation.mrIndex.h == true)
                        app.pmrLabel.Text = [app.pmrLabel.Text '*'];
                        app.pmrLabel.FontWeight = 'bold';
                    end
                    
                    app.pmissingLabel.Text = num2str(stats.dataQuality.missingGlucosePercentage.p);
                    if(stats.dataQuality.missingGlucosePercentage.h == true)
                        app.pmissingLabel.Text = [app.pmissingLabel.Text '*'];
                        app.pmissingLabel.FontWeight = 'bold';
                    end
                    app.pnodLabel.Text = num2str(stats.dataQuality.numberDaysOfObservation.p);
                    if(stats.dataQuality.numberDaysOfObservation.h == true)
                        app.pnodLabel.Text = [app.pnodLabel.Text '*'];
                        app.pnodLabel.FontWeight = 'bold';
                    end
                    
                    app.phypoeventdurationLabel.Text = num2str(stats.event.hypoglycemicEvents.hypo.meanDuration.p);
                    if(stats.event.hypoglycemicEvents.hypo.meanDuration.h == true)
                        app.phypoeventdurationLabel.Text = [app.phypoeventdurationLabel.Text '*'];
                        app.phypoeventdurationLabel.FontWeight = 'bold';
                    end
                    app.phypoeventweekLabel.Text = num2str(stats.event.hypoglycemicEvents.hypo.eventsPerWeek.p);
                    if(stats.event.hypoglycemicEvents.hypo.eventsPerWeek.h == true)
                        app.phypoeventweekLabel.Text = [app.phypoeventweekLabel.Text '*'];
                        app.phypoeventweekLabel.FontWeight = 'bold';
                    end
                    app.pl1hypoeventdurationLabel.Text = num2str(stats.event.hypoglycemicEvents.l1.meanDuration.p);
                    if(stats.event.hypoglycemicEvents.l1.meanDuration.h == true)
                        app.pl1hypoeventdurationLabel.Text = [app.pl1hypoeventdurationLabel.Text '*'];
                        app.pl1hypoeventdurationLabel.FontWeight = 'bold';
                    end
                    app.pl1hypoeventweekLabel.Text = num2str(stats.event.hypoglycemicEvents.l1.eventsPerWeek.p);
                    if(stats.event.hypoglycemicEvents.l1.eventsPerWeek.h == true)
                        app.pl1hypoeventweekLabel.Text = [app.pl1hypoeventweekLabel.Text '*'];
                        app.pl1hypoeventweekLabel.FontWeight = 'bold';
                    end
                    app.pl2hypoeventdurationLabel.Text = num2str(stats.event.hypoglycemicEvents.l2.meanDuration.p);
                    if(stats.event.hypoglycemicEvents.l2.meanDuration.h == true)
                        app.pl2hypoeventdurationLabel.Text = [app.pl2hypoeventdurationLabel.Text '*'];
                        app.pl2hypoeventdurationLabel.FontWeight = 'bold';
                    end
                    app.pl2hypoeventweekLabel.Text = num2str(stats.event.hypoglycemicEvents.l2.eventsPerWeek.p);
                    if(stats.event.hypoglycemicEvents.l2.eventsPerWeek.h == true)
                        app.pl2hypoeventweekLabel.Text = [app.pl2hypoeventweekLabel.Text '*'];
                        app.pl2hypoeventweekLabel.FontWeight = 'bold';
                    end
                    
                    app.phypereventdurationLabel.Text = num2str(stats.event.hyperglycemicEvents.hyper.meanDuration.p);
                    if(stats.event.hyperglycemicEvents.hyper.meanDuration.h == true)
                        app.phypereventdurationLabel.Text = [app.phypereventdurationLabel.Text '*'];
                        app.phypereventdurationLabel.FontWeight = 'bold';
                    end
                    app.phypereventweekLabel.Text = num2str(stats.event.hyperglycemicEvents.hyper.eventsPerWeek.p);
                    if(stats.event.hyperglycemicEvents.hyper.eventsPerWeek.h == true)
                        app.phypereventweekLabel.Text = [app.phypereventweekLabel.Text '*'];
                        app.phypereventweekLabel.FontWeight = 'bold';
                    end
                    app.pl1hypereventdurationLabel.Text = num2str(stats.event.hyperglycemicEvents.l1.meanDuration.p);
                    if(stats.event.hyperglycemicEvents.l1.meanDuration.h == true)
                        app.pl1hypereventdurationLabel.Text = [app.pl1hypereventdurationLabel.Text '*'];
                        app.pl1hypereventdurationLabel.FontWeight = 'bold';
                    end
                    app.pl1hypereventweekLabel.Text = num2str(stats.event.hyperglycemicEvents.l1.eventsPerWeek.p);
                    if(stats.event.hyperglycemicEvents.l1.eventsPerWeek.h == true)
                        app.pl1hypereventweekLabel.Text = [app.pl1hypereventweekLabel.Text '*'];
                        app.pl1hypereventweekLabel.FontWeight = 'bold';
                    end
                    app.pl2hypereventdurationLabel.Text = num2str(stats.event.hyperglycemicEvents.l2.meanDuration.p);
                    if(stats.event.hyperglycemicEvents.l2.meanDuration.h == true)
                        app.pl2hypereventdurationLabel.Text = [app.pl2hypereventdurationLabel.Text '*'];
                        app.pl2hypereventdurationLabel.FontWeight = 'bold';
                    end
                    app.pl2hypereventweekLabel.Text = num2str(stats.event.hyperglycemicEvents.l2.eventsPerWeek.p);
                    if(stats.event.hyperglycemicEvents.l2.eventsPerWeek.h == true)
                        app.pl2hypereventweekLabel.Text = [app.pl2hypereventweekLabel.Text '*'];
                        app.pl2hypereventweekLabel.FontWeight = 'bold';
                    end
                    
                    app.pexthypoeventdurationLabel.Text = num2str(stats.event.extendedHypoglycemicEvents.meanDuration.p);
                    if(stats.event.extendedHypoglycemicEvents.meanDuration.h == true)
                        app.pexthypoeventdurationLabel.Text = [app.pexthypoeventdurationLabel.Text '*'];
                        app.pexthypoeventdurationLabel.FontWeight = 'bold';
                    end
                    app.pexthypoeventweekLabel.Text = num2str(stats.event.extendedHypoglycemicEvents.eventsPerWeek.p);
                    if(stats.event.extendedHypoglycemicEvents.eventsPerWeek.h == true)
                        app.pexthypoeventweekLabel.Text = [app.pexthypoeventweekLabel.Text '*'];
                        app.pexthypoeventweekLabel.FontWeight = 'bold';
                    end
            
                    %Store the results in the workspace
                    app.AnalysisResults = results;
                    app.StatsResults = stats;
                    app.SaveAnalysisResultsButton.Enable = true;
                    
                catch exception
                    errordlg('Impossible to analyze data using the given parameters.','Impossible to analyze');
                end
            end
            %Update UI components' state
            app.AnalyzeGlucoseDataButton.Enable = true;
            app.LoadingStatusLabelGroup1.Text = ['Group 1: Ready (N = ' num2str(app.NTracesGroup1) ')'];
            app.LoadingStatusLabelGroup2.Text = ['Group 2: Ready (N = ' num2str(app.NTracesGroup2) ')'];
            app.StatusLamp.Color = 'g';
        end

        % Button pushed function: SaveAnalysisResultsButton
        function SaveAnalysisResultsButtonPushed(app, event)
            results = app.AnalysisResults;
            stats = app.StatsResults;
            uisave({'results','stats'},'twoGroupComparisonAnalysisResults')
        end

        % Button pushed function: SaveDataButton
        function SaveDataButtonPushed(app, event)
            dataGroup1 = app.DataGroup1;
            dataGroup2 = app.DataGroup2;
            uisave({'dataGroup1','dataGroup2'},'data')
        end

        % Button pushed function: AddDataButtonGroup1
        function AddDataButtonGroup1Pushed(app, event)
            app.SelectCGMdatasourceDropDownGroup1.Visible = true;
            app.SelectCGMdatasourceDropDownLabel.Visible = true;
            
            app.SelectAndLoadDataButtonGroup1.Visible = false;
            app.SelectAndLoadDataButtonLabelGroup1.Visible = false;
            
        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            
            if(strcmp(app.DoneButton.Text,'Done'))
                
                app.AddDataButtonGroup1.Visible = false;
                app.SelectCGMdatasourceDropDownGroup1.Value = '<Select a source>';
                app.AddDataButtonGroup2.Visible = false;
                app.SelectCGMdatasourceDropDownGroup2.Value = '<Select a source>';
                app.DoneButton.Text = 'Reset';
                    
            
                %Visualize other panels
                app.VisualizationDataPanel.Visible = true;
                app.ProcessingDataPanel.Visible = true;
                app.AnalysisDataPanel.Visible = true;
                app.SaveAnalysisResultsButton.Enable = false;
            
            
                %While not retimed, lock some options
                flag = true;
                
                for d = 1:app.NTracesGroup1
                    data = app.DataGroup1{d};
                    if(var(seconds(diff(data.Time))) > 0 || isnan(var(seconds(diff(data.Time)))))
                        flag = false;
                    end
                end
                for d = 1:app.NTracesGroup2
                    data = app.DataGroup2{d};
                    if(var(seconds(diff(data.Time))) > 0 || isnan(var(seconds(diff(data.Time)))))
                        flag = false;
                    end
                end
                
                if(~flag)
                
                    app.ImputeDataButton.Enable = false;
                    app.DetrendDataButton.Enable = false;
                    
                    app.AnalyzeGlucoseDataButton.Enable = false;
                   
                    app.CompareGlucoseDataAsOneDayButton.Enable = false;
                    app.PlotGroup1DataAsOneDayButton.Enable = false;
                    app.PlotGroup2DataAsOneDayButton.Enable = false;
           
                
                else
                    
                    app.ImputeDataButton.Enable = true;
                    app.DetrendDataButton.Enable = true;
                    app.AnalyzeGlucoseDataButton.Enable = true;
                    app.AnalyzeGlucoseDataButton.Enable = false;
                    
                    app.CompareGlucoseDataAsOneDayButton.Enable = true;
                    app.PlotGroup1DataAsOneDayButton.Enable = true;
                    app.PlotGroup2DataAsOneDayButton.Enable = true;
                    
                end
                    
            else %Reset data
                
                %Update UI components' state
                app.AddDataButtonGroup1.Visible = true;
                app.AddDataButtonGroup2.Visible = true;
                app.StatusLamp.Color = 'r';
                app.LoadingStatusLabelGroup1.Text = 'Group 1: No data';
                app.LoadingStatusLabelGroup2.Text = 'Group 2: No data';
                
                %Set the text button back to Done
                app.DoneButton.Text = 'Done';
                app.DoneButton.Visible = false;
                
                %Remove data from the memory
                app.DataGroup1 = [];
                app.NTracesGroup1 = 0;
                app.DataGroup2 = [];
                app.NTracesGroup2 = 0;
                
                %Hide other panels
                app.ProcessingDataPanel.Visible = false;
                app.VisualizationDataPanel.Visible = false;
                app.AnalysisDataPanel.Visible = false;
                resetAnalysisDataPanel(app);
                
                %Disable action buttons
                app.ImputeDataButton.Enable = false;
                app.DetrendDataButton.Enable = false;
                app.AnalyzeGlucoseDataButton.Enable = false;
                app.SaveAnalysisResultsButton.Enable = false;
                
                app.CompareGlucoseDataAsOneDayButton.Enable = false;
                    
            end
            
        end

        % Button pushed function: SelectAndLoadDataButtonGroup2
        function SelectAndLoadDataFileGroup2(app, event)
            %Get the file via UI
            if(strcmp(app.SelectCGMdatasourceDropDownGroup2.Value,'AGATA'))
                extension = '*.mat';
            else
                extension = '*';
            end
            [file,path] = uigetfile(extension);
            
            %If a file has been selected 
            if(file~=0)
                
                %Get the complete file path
                filename = [path file];
                
                %Update UI components' state
                app.SelectCGMdatasourceDropDownGroup2.Enable = false;
                app.SelectAndLoadDataButtonGroup2.Enable = false;
                app.LoadingStatusLabelGroup2.Text = 'Loading...';
                app.StatusLamp.Color = 'y';
                pause(0.001);
                
                %Load data using AGATA core
                try
                    switch app.SelectCGMdatasourceDropDownGroup2.Value
                        case 'AGATA'
                            load(filename);
                            
                            %Check preconditions 
                            if(~istimetable(data))
                                error('Loaded data must be in a timetable called data.');
                            end
                            if(~any(strcmp(fieldnames(data),'glucose')))
                                error('Loaded data must have a column named `glucose`.')
                            end
                            
                        case 'Dexcom'
                            data = readDexcomData(filename);
                        case 'Ascensia Eversense'
                            data = readEversenseData(filename);
                        case 'Abbott Freestyle Libre'
                            data = readFreestyleLibreData(filename);
                    end
                    
                    %if data are in mmol/l convert them
                    if(nanmean(data.glucose) < 30)
                        data.glucose = data.glucose* 18.018;
                    end
                    
                    %Add data
                    app.DataGroup2{app.NTracesGroup2+1} = data;
                    app.NTracesGroup2 = app.NTracesGroup2 +1;
                    
                    %Update UI components' state
                    app.SelectCGMdatasourceDropDownGroup2.Enable = true;
                    app.SelectAndLoadDataButtonGroup2.Enable = true;
                
                    app.SelectCGMdatasourceDropDownGroup2.Visible = false;
                    app.SelectCGMdatasourceDropDown_2Label.Visible = false;
                    
                    app.SelectAndLoadDataButtonLabelGroup2.Visible = false;
                    app.SelectAndLoadDataButtonGroup2.Visible = false;
                    
                    app.LoadingStatusLabelGroup2.Text = ['Group 2: Ready (N = ' num2str(app.NTracesGroup2) ')'];
                    if(app.NTracesGroup1 > 0)
                        %app.StatusLamp.Color = 'g';
                        app.DoneButton.Visible = true;
                    else
                        %app.StatusLamp.Color = 'y';
                        app.DoneButton.Visible = false;
                    end
                    
                    app.SelectCGMdatasourceDropDownGroup2.Value = '<Select a source>';
                    
                catch exception
                    %Update UI components' state
                    app.SelectCGMdatasourceDropDownGroup2.Enable = true;
                    app.SelectAndLoadDataButtonGroup2.Enable = true;
                    
                    if(app.NTracesGroup2 > 0)
                        app.LoadingStatusLabelGroup2.Text = ['Group 2: Ready (N = ' num2str(app.NTraces) ')'];
                    else
                        app.LoadingStatusLabelGroup2.Text = 'Group 2: No data';
                    end
                    errordlg('The selected file is not readable or is not coming from the specified CGM data source','Invalid data file');
                
                end
                setLampColor(app);
            end
        end

        % Button pushed function: AddDataButtonGroup2
        function AddDataButtonGroup2Pushed(app, event)
            app.SelectCGMdatasourceDropDownGroup2.Visible = true;
            app.SelectCGMdatasourceDropDown_2Label.Visible = true;
            
            app.SelectAndLoadDataButtonGroup2.Visible = false;
            app.SelectAndLoadDataButtonLabelGroup2.Visible = false;
        end

        % Value changed function: SelectCGMdatasourceDropDownGroup2
        function SelectCGMdatasourceDropDownGroup2ValueChanged(app, event)
            value = app.SelectCGMdatasourceDropDownGroup2.Value;
            if(~strcmp(value,'<Select a source>'))
                app.SelectAndLoadDataButtonGroup2.Visible = true;
                app.SelectAndLoadDataButtonLabelGroup2.Visible = true;
            else
                app.SelectAndLoadDataButtonGroup2.Visible = false;
                app.SelectAndLoadDataButtonLabelGroup2.Visible = false;
            end
        end

        % Button pushed function: PlotGroup1DataAsOneDayButton
        function PlotGroup1DataAsOneDayButtonPushed(app, event)
            %Update UI components' state
            app.PlotGroup1DataAsOneDayButton.Enable = false;
            app.LoadingStatusLabelGroup1.Text = 'Plotting...';
            app.StatusLamp.Color = 'y';
            pause(0.001);
            
            try
                plotGlucoseArmAsOneDay(app.DataGroup1);
            catch exception
                errordlg('Impossible to generate the requested plot. Did you retimed data?','Impossible to plot');
            end
            
            %Update UI components' state
            app.PlotGroup1DataAsOneDayButton.Enable = true;
            app.LoadingStatusLabelGroup1.Text = ['Group 1: Ready (N = ' num2str(app.NTracesGroup1) ')'];
            app.StatusLamp.Color = 'g';
        end

        % Button pushed function: PlotGroup2DataAsOneDayButton
        function PlotGroup2DataAsOneDayButtonPushed(app, event)
            %Update UI components' state
            app.PlotGroup2DataAsOneDayButton.Enable = false;
            app.LoadingStatusLabelGroup1.Text = 'Plotting...';
            app.StatusLamp.Color = 'y';
            pause(0.001);
            
            try
                plotGlucoseArmAsOneDay(app.DataGroup2);
            catch exception
                errordlg('Impossible to generate the requested plot. Did you retimed data?','Impossible to plot');
            end
            
            %Update UI components' state
            app.PlotGroup2DataAsOneDayButton.Enable = true;
            app.LoadingStatusLabelGroup2.Text = ['Group 2: Ready (N = ' num2str(app.NTracesGroup2) ')'];
            app.StatusLamp.Color = 'g';
        end

        % Button pushed function: CompareGlucoseGRIButton
        function CompareGlucoseGRIButtonPushed(app, event)
            %Update UI components' state
            app.CompareGlucoseGRIButton.Enable = false;
            app.LoadingStatusLabelGroup1.Text = 'Plotting...';
            app.LoadingStatusLabelGroup2.Text = 'Plotting...';
            app.StatusLamp.Color = 'y';
            pause(0.001);
            
            plotGRIComparison(app.DataGroup1,app.DataGroup2);
            
            %Update UI components' state
            app.CompareGlucoseGRIButton.Enable = true;
            app.LoadingStatusLabelGroup1.Text = ['Group 1: Ready (N = ' num2str(app.NTracesGroup1) ')'];
            app.LoadingStatusLabelGroup2.Text = ['Group 2: Ready (N = ' num2str(app.NTracesGroup2) ')'];
            app.StatusLamp.Color = 'g';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AGATAMultipleProfileGUIUIFigure = uifigure('Visible', 'off');
            app.AGATAMultipleProfileGUIUIFigure.Name = 'AGATA Multiple Profile GUI';
            app.LoadDataPanelGroup1 = uipanel(app.AGATAMultipleProfileGUIUIFigure);
            app.LoadDataPanelGroup1.Title = 'Load Data Panel - Group 1';
            app.SelectAndLoadDataButtonGroup1 = uibutton(app.LoadDataPanelGroup1, 'push');
            app.SelectAndLoadDataButtonGroup1.ButtonPushedFcn = createCallbackFcn(app, @SelectAndLoadDataFileGroup1, true);
            app.SelectAndLoadDataButtonGroup1.Text = 'Select & Load';
            app.SelectAndLoadDataButtonLabelGroup1 = uilabel(app.LoadDataPanelGroup1);
            app.SelectAndLoadDataButtonLabelGroup1.Text = '2. Select and load data';
            app.SelectCGMdatasourceDropDownLabel = uilabel(app.LoadDataPanelGroup1);
            app.SelectCGMdatasourceDropDownLabel.Text = '1. Select CGM data source';
            app.SelectCGMdatasourceDropDownGroup1 = uidropdown(app.LoadDataPanelGroup1);
            app.SelectCGMdatasourceDropDownGroup1.Items = {'<Select a source>', 'AGATA', 'Dexcom', 'Ascensia Eversense', 'Abbott Freestyle Libre'};
            app.SelectCGMdatasourceDropDownGroup1.ValueChangedFcn = createCallbackFcn(app, @SelectCGMdatasourceDropDownGroup1ValueChanged, true);
            app.SelectCGMdatasourceDropDownGroup1.Value = '<Select a source>';
            app.AddDataButtonGroup1 = uibutton(app.LoadDataPanelGroup1, 'push');
            app.AddDataButtonGroup1.ButtonPushedFcn = createCallbackFcn(app, @AddDataButtonGroup1Pushed, true);
            app.AddDataButtonGroup1.Text = 'Add';
            app.VisualizationDataPanel = uipanel(app.AGATAMultipleProfileGUIUIFigure);
            app.VisualizationDataPanel.Title = 'Visualization Data Panel';
            app.CompareGlucoseDataAsOneDayButton = uibutton(app.VisualizationDataPanel, 'push');
            app.CompareGlucoseDataAsOneDayButton.ButtonPushedFcn = createCallbackFcn(app, @CompareGlucoseDataAsOneDayButtonPushed, true);
            app.CompareGlucoseDataAsOneDayButton.Text = 'Compare Glucose Data As One Day';
            app.CompareGlucoseCVGAButton = uibutton(app.VisualizationDataPanel, 'push');
            app.CompareGlucoseCVGAButton.ButtonPushedFcn = createCallbackFcn(app, @CompareGlucoseCVGAButtonPushed, true);
            app.CompareGlucoseCVGAButton.Text = 'Compare Glucose CVGA';
            app.PlotGroup1DataAsOneDayButton = uibutton(app.VisualizationDataPanel, 'push');
            app.PlotGroup1DataAsOneDayButton.ButtonPushedFcn = createCallbackFcn(app, @PlotGroup1DataAsOneDayButtonPushed, true);
            app.PlotGroup1DataAsOneDayButton.Text = 'Plot Group 1 Data As One Day';
            app.PlotGroup2DataAsOneDayButton = uibutton(app.VisualizationDataPanel, 'push');
            app.PlotGroup2DataAsOneDayButton.ButtonPushedFcn = createCallbackFcn(app, @PlotGroup2DataAsOneDayButtonPushed, true);
            app.PlotGroup2DataAsOneDayButton.Text = 'Plot Group 2 Data As One Day';
            app.CompareGlucoseGRIButton = uibutton(app.VisualizationDataPanel, 'push');
            app.CompareGlucoseGRIButton.ButtonPushedFcn = createCallbackFcn(app, @CompareGlucoseGRIButtonPushed, true);
            app.CompareGlucoseGRIButton.Text = 'Compare Glucose GRI';
            app.ProcessingDataPanel = uipanel(app.AGATAMultipleProfileGUIUIFigure);
            app.ProcessingDataPanel.Title = 'Processing Data Panel';
            app.RetimeDataButton = uibutton(app.ProcessingDataPanel, 'push');
            app.RetimeDataButton.ButtonPushedFcn = createCallbackFcn(app, @RetimeDataButtonPushed, true);
            app.RetimeDataButton.Text = 'Retime Data';
            app.ImputeDataButton = uibutton(app.ProcessingDataPanel, 'push');
            app.ImputeDataButton.ButtonPushedFcn = createCallbackFcn(app, @ImputeDataButtonPushed, true);
            app.ImputeDataButton.Text = 'Impute Data';
            app.DetrendDataButton = uibutton(app.ProcessingDataPanel, 'push');
            app.DetrendDataButton.ButtonPushedFcn = createCallbackFcn(app, @DetrendDataButtonPushed, true);
            app.DetrendDataButton.Text = 'Detrend Data';
            app.SaveDataButton = uibutton(app.ProcessingDataPanel, 'push');
            app.SaveDataButton.ButtonPushedFcn = createCallbackFcn(app, @SaveDataButtonPushed, true);
            app.SaveDataButton.Text = 'Save Data';
            app.AnalysisDataPanel = uipanel(app.AGATAMultipleProfileGUIUIFigure);
            app.AnalysisDataPanel.Title = 'Analysis Data Panel';
            app.AnalyzeGlucoseDataButton = uibutton(app.AnalysisDataPanel, 'push');
            app.AnalyzeGlucoseDataButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeGlucoseDataButtonPushed, true);
            app.AnalyzeGlucoseDataButton.Text = 'Analyze Glucose Data';
            app.TabGroup = uitabgroup(app.AnalysisDataPanel);
            app.VariabilityTab = uitab(app.TabGroup);
            app.VariabilityTab.Title = 'Variability';
            app.AUCLabel = uilabel(app.VariabilityTab);
            app.AUCLabel.Text = 'AUC: ';
            app.CVGALabel = uilabel(app.VariabilityTab);
            app.CVGALabel.Text = 'CVGA: ';
            app.MEANLabel = uilabel(app.VariabilityTab);
            app.MEANLabel.Text = 'MEAN:';
            app.JINDEXLabel = uilabel(app.VariabilityTab);
            app.JINDEXLabel.Text = 'J-INDEX: ';
            app.GMILabel = uilabel(app.VariabilityTab);
            app.GMILabel.Text = 'GMI:';
            app.EFINDEXLabel = uilabel(app.VariabilityTab);
            app.EFINDEXLabel.Text = 'EF INDEX: ';
            app.MAGEINDEXLabel = uilabel(app.VariabilityTab);
            app.MAGEINDEXLabel.Text = 'MAGE INDEX: ';
            app.MAGEINDEXLabel_2 = uilabel(app.VariabilityTab);
            app.MAGEINDEXLabel_2.Text = 'MAGE+ INDEX: ';
            app.MAGEINDEXLabel_3 = uilabel(app.VariabilityTab);
            app.MAGEINDEXLabel_3.Text = 'MAGE- INDEX: ';
            app.meanLabelG1 = uilabel(app.VariabilityTab);
            app.meanLabelG1.Text = '<mean>';
            app.medianLabelG1 = uilabel(app.VariabilityTab);
            app.medianLabelG1.Text = '<median>';
            app.sdLabelG1 = uilabel(app.VariabilityTab);
            app.sdLabelG1.Text = '<sd>';
            app.cvLabelG1 = uilabel(app.VariabilityTab);
            app.cvLabelG1.Text = '<cv>';
            app.iqrLabelG1 = uilabel(app.VariabilityTab);
            app.iqrLabelG1.Text = '<iqr>';
            app.rangeLabelG1 = uilabel(app.VariabilityTab);
            app.rangeLabelG1.Text = '<range>';
            app.aucLabelG1 = uilabel(app.VariabilityTab);
            app.aucLabelG1.Text = '<auc>';
            app.cvgaLabelG1 = uilabel(app.VariabilityTab);
            app.cvgaLabelG1.Text = '<cvga>';
            app.jindexLabelG1 = uilabel(app.VariabilityTab);
            app.jindexLabelG1.Text = '<jindex>';
            app.efindexLabelG1 = uilabel(app.VariabilityTab);
            app.efindexLabelG1.Text = '<efindex>';
            app.mageindexLabelG1 = uilabel(app.VariabilityTab);
            app.mageindexLabelG1.Text = '<mageindex>';
            app.mageplusindexLabelG1 = uilabel(app.VariabilityTab);
            app.mageplusindexLabelG1.Text = '<mageplusindex>';
            app.mageminusindexLabelG1 = uilabel(app.VariabilityTab);
            app.mageminusindexLabelG1.Text = '<mageminusindex>';
            app.gmiLabelG1 = uilabel(app.VariabilityTab);
            app.gmiLabelG1.Text = '<gmi>';
            app.meanLabelG2 = uilabel(app.VariabilityTab);
            app.meanLabelG2.Text = '<mean>';
            app.medianLabelG2 = uilabel(app.VariabilityTab);
            app.medianLabelG2.Text = '<median>';
            app.sdLabelG2 = uilabel(app.VariabilityTab);
            app.sdLabelG2.Text = '<sd>';
            app.cvLabelG2 = uilabel(app.VariabilityTab);
            app.cvLabelG2.Text = '<cv>';
            app.iqrLabelG2 = uilabel(app.VariabilityTab);
            app.iqrLabelG2.Text = '<iqr>';
            app.rangeLabelG2 = uilabel(app.VariabilityTab);
            app.rangeLabelG2.Text = '<range>';
            app.MEDIANLabel = uilabel(app.VariabilityTab);
            app.MEDIANLabel.Text = 'MEDIAN:';
            app.SDLabel = uilabel(app.VariabilityTab);
            app.SDLabel.Text = 'SD:';
            app.CVLabel = uilabel(app.VariabilityTab);
            app.CVLabel.Text = 'CV: ';
            app.IQRLabel = uilabel(app.VariabilityTab);
            app.IQRLabel.Text = 'IQR: ';
            app.RANGELabel = uilabel(app.VariabilityTab);
            app.RANGELabel.Text = 'RANGE: ';
            app.SDROCLabel = uilabel(app.VariabilityTab);
            app.SDROCLabel.Text = 'SD(ROC):';
            app.SDDMINDEXLabel = uilabel(app.VariabilityTab);
            app.SDDMINDEXLabel.Text = 'SDDM INDEX:';
            app.SDWINDEXLabel = uilabel(app.VariabilityTab);
            app.SDWINDEXLabel.Text = 'SDW INDEX:';
            app.sdrocLabelG1 = uilabel(app.VariabilityTab);
            app.sdrocLabelG1.Text = '<sdroc>';
            app.sddmindexLabelG1 = uilabel(app.VariabilityTab);
            app.sddmindexLabelG1.Text = '<sddmindex>';
            app.sdwindexLabelG1 = uilabel(app.VariabilityTab);
            app.sdwindexLabelG1.Text = '<sdwindex>';
            app.sdrocLabelG2 = uilabel(app.VariabilityTab);
            app.sdrocLabelG2.Text = '<sdroc>';
            app.sddmindexLabelG2 = uilabel(app.VariabilityTab);
            app.sddmindexLabelG2.Text = '<sddmindex>';
            app.sdwindexLabelG2 = uilabel(app.VariabilityTab);
            app.sdwindexLabelG2.Text = '<sdwindex>';
            app.aucLabelG2 = uilabel(app.VariabilityTab);
            app.aucLabelG2.Text = '<auc>';
            app.cvgaLabelG2 = uilabel(app.VariabilityTab);
            app.cvgaLabelG2.Text = '<cvga>';
            app.jindexLabelG2 = uilabel(app.VariabilityTab);
            app.jindexLabelG2.Text = '<jindex>';
            app.gmiLabelG2 = uilabel(app.VariabilityTab);
            app.gmiLabelG2.Text = '<gmi>';
            app.mageminusindexLabelG2 = uilabel(app.VariabilityTab);
            app.mageminusindexLabelG2.Text = '<mageminusindex>';
            app.mageplusindexLabelG2 = uilabel(app.VariabilityTab);
            app.mageplusindexLabelG2.Text = '<mageplusindex>';
            app.mageindexLabelG2 = uilabel(app.VariabilityTab);
            app.mageindexLabelG2.Text = '<mageindex>';
            app.efindexLabelG2 = uilabel(app.VariabilityTab);
            app.efindexLabelG2.Text = '<efindex>';
            app.pmeanLabel = uilabel(app.VariabilityTab);
            app.pmeanLabel.Text = '<pvalue>';
            app.pmedianLabel = uilabel(app.VariabilityTab);
            app.pmedianLabel.Text = '<pvalue>';
            app.psdLabel = uilabel(app.VariabilityTab);
            app.psdLabel.Text = '<pvalue>';
            app.pcvLabel = uilabel(app.VariabilityTab);
            app.pcvLabel.Text = '<pvalue>';
            app.piqrLabel = uilabel(app.VariabilityTab);
            app.piqrLabel.Text = '<pvalue>';
            app.prangeLabel = uilabel(app.VariabilityTab);
            app.prangeLabel.Text = '<pvalue>';
            app.psdrocLabel = uilabel(app.VariabilityTab);
            app.psdrocLabel.Text = '<pvalue>';
            app.psddmLabel = uilabel(app.VariabilityTab);
            app.psddmLabel.Text = '<pvalue>';
            app.psdwLabel = uilabel(app.VariabilityTab);
            app.psdwLabel.Text = '<pvalue>';
            app.paucLabel = uilabel(app.VariabilityTab);
            app.paucLabel.Text = '<pvalue>';
            app.pCVGALabel = uilabel(app.VariabilityTab);
            app.pCVGALabel.Text = '<pvalue>';
            app.pjLabel = uilabel(app.VariabilityTab);
            app.pjLabel.Text = '<pvalue>';
            app.pgmiLabel = uilabel(app.VariabilityTab);
            app.pgmiLabel.Text = '<pvalue>';
            app.pEFLabel = uilabel(app.VariabilityTab);
            app.pEFLabel.Text = '<pvalue>';
            app.pmageLabel = uilabel(app.VariabilityTab);
            app.pmageLabel.Text = '<pvalue>';
            app.pmagePlusLabel = uilabel(app.VariabilityTab);
            app.pmagePlusLabel.Text = '<pvalue>';
            app.pmageMinusLabel = uilabel(app.VariabilityTab);
            app.pmageMinusLabel.Text = '<pvalue>';
            app.CONGALabel = uilabel(app.VariabilityTab);
            app.CONGALabel.Text = 'CONGA:';
            app.MODDLabel = uilabel(app.VariabilityTab);
            app.MODDLabel.Text = 'MODD:';
            app.congaLabelG1 = uilabel(app.VariabilityTab);
            app.congaLabelG1.Text = '<conga>';
            app.moddLabelG1 = uilabel(app.VariabilityTab);
            app.moddLabelG1.Text = '<modd>';
            app.moddLabelG2 = uilabel(app.VariabilityTab);
            app.moddLabelG2.Text = '<modd>';
            app.congaLabelG2 = uilabel(app.VariabilityTab);
            app.congaLabelG2.Text = '<conga>';
            app.pcongaLabel = uilabel(app.VariabilityTab);
            app.pcongaLabel.Text = '<pvalue>';
            app.pmoddLabel = uilabel(app.VariabilityTab);
            app.pmoddLabel.Text = '<pvalue>';
            app.COGILabel = uilabel(app.VariabilityTab);
            app.COGILabel.Text = 'COGI:';
            app.cogiLabelG1 = uilabel(app.VariabilityTab);
            app.cogiLabelG1.Text = '<modd>';
            app.cogiLabelG2 = uilabel(app.VariabilityTab);
            app.cogiLabelG2.Text = '<modd>';
            app.pcogiLabel = uilabel(app.VariabilityTab);
            app.pcogiLabel.Text = '<pvalue>';
            app.RiskTab = uitab(app.TabGroup);
            app.RiskTab.Title = 'Risk';
            app.HBGILabel = uilabel(app.RiskTab);
            app.HBGILabel.Text = 'HBGI: ';
            app.ADRRLabel = uilabel(app.RiskTab);
            app.ADRRLabel.Text = 'ADRR:';
            app.BGRILabel = uilabel(app.RiskTab);
            app.BGRILabel.Text = 'BGRI:';
            app.LBGILabel = uilabel(app.RiskTab);
            app.LBGILabel.Text = 'LBGI:';
            app.adrrLabelG1 = uilabel(app.RiskTab);
            app.adrrLabelG1.Text = '<adrr>';
            app.bgriLabelG1 = uilabel(app.RiskTab);
            app.bgriLabelG1.Text = '<bgri>';
            app.lbgiLabelG1 = uilabel(app.RiskTab);
            app.lbgiLabelG1.Text = '<lbgi>';
            app.hbgiLabelG1 = uilabel(app.RiskTab);
            app.hbgiLabelG1.Text = '<hbgi>';
            app.adrrLabelG2 = uilabel(app.RiskTab);
            app.adrrLabelG2.Text = '<adrr>';
            app.bgriLabelG2 = uilabel(app.RiskTab);
            app.bgriLabelG2.Text = '<bgri>';
            app.lbgiLabelG2 = uilabel(app.RiskTab);
            app.lbgiLabelG2.Text = '<lbgi>';
            app.hbgiLabelG2 = uilabel(app.RiskTab);
            app.hbgiLabelG2.Text = '<hbgi>';
            app.padrrLabel = uilabel(app.RiskTab);
            app.padrrLabel.Text = '<pvalue>';
            app.pbgriLabel = uilabel(app.RiskTab);
            app.pbgriLabel.Text = '<pvalue>';
            app.plbgiLabel = uilabel(app.RiskTab);
            app.plbgiLabel.Text = '<pvalue>';
            app.phbgiLabel = uilabel(app.RiskTab);
            app.phbgiLabel.Text = '<pvalue>';
            app.GRILabel = uilabel(app.RiskTab);
            app.GRILabel.Text = 'GRI: ';
            app.griLabelG1 = uilabel(app.RiskTab);
            app.griLabelG1.Text = '<gri>';
            app.griLabelG2 = uilabel(app.RiskTab);
            app.griLabelG2.Text = '<gri>';
            app.pgriLabel = uilabel(app.RiskTab);
            app.pgriLabel.Text = '<pvalue>';
            app.TimeinRangesTab = uitab(app.TabGroup);
            app.TimeinRangesTab.Title = 'Time in Ranges';
            app.TIMEINLEVEL2HYPOGLYCEMIACGM54mgdLLabel = uilabel(app.TimeinRangesTab);
            app.TIMEINLEVEL2HYPOGLYCEMIACGM54mgdLLabel.Text = 'TIME IN LEVEL 2 HYPOGLYCEMIA (CGM < 54 mg/dL): ';
            app.TIMEINHYPOGLYCEMIACGM70mgdLLabel = uilabel(app.TimeinRangesTab);
            app.TIMEINHYPOGLYCEMIACGM70mgdLLabel.Text = 'TIME IN HYPOGLYCEMIA (CGM < 70 mg/dL):';
            app.TIMEINHYPERGLYCEMIACGM180mgdLLabel = uilabel(app.TimeinRangesTab);
            app.TIMEINHYPERGLYCEMIACGM180mgdLLabel.Text = 'TIME IN HYPERGLYCEMIA (CGM > 180 mg/dL):';
            app.TIMEINTARGETCGM70180mgdLLabel = uilabel(app.TimeinRangesTab);
            app.TIMEINTARGETCGM70180mgdLLabel.Text = 'TIME IN TARGET (CGM 70-180 mg/dL):';
            app.TIMEINLEVEL2HYPERGLYCEMIACGM250mgdLLabel = uilabel(app.TimeinRangesTab);
            app.TIMEINLEVEL2HYPERGLYCEMIACGM250mgdLLabel.Text = 'TIME IN LEVEL 2 HYPERGLYCEMIA (CGM > 250 mg/dL): ';
            app.TIMEINTIGHTTARGETCGM70140mgdLLabel = uilabel(app.TimeinRangesTab);
            app.TIMEINTIGHTTARGETCGM70140mgdLLabel.Text = 'TIME IN TIGHT TARGET (CGM 70-140 mg/dL): ';
            app.timehypoLabelG1 = uilabel(app.TimeinRangesTab);
            app.timehypoLabelG1.Text = '<timehypo>';
            app.timehyperLabelG1 = uilabel(app.TimeinRangesTab);
            app.timehyperLabelG1.Text = '<timehyper>';
            app.timetargetLabelG1 = uilabel(app.TimeinRangesTab);
            app.timetargetLabelG1.Text = '<timetarget>';
            app.timelevel2hyperLabelG1 = uilabel(app.TimeinRangesTab);
            app.timelevel2hyperLabelG1.Text = '<timelevel2hyper>';
            app.timetighttargetLabelG1 = uilabel(app.TimeinRangesTab);
            app.timetighttargetLabelG1.Text = '<timetighttarget>';
            app.timelevel2hypoLabelG1 = uilabel(app.TimeinRangesTab);
            app.timelevel2hypoLabelG1.Text = '<timelevel2hypo>';
            app.timehypoLabelG2 = uilabel(app.TimeinRangesTab);
            app.timehypoLabelG2.Text = '<timehypo>';
            app.timehyperLabelG2 = uilabel(app.TimeinRangesTab);
            app.timehyperLabelG2.Text = '<timehyper>';
            app.timetargetLabelG2 = uilabel(app.TimeinRangesTab);
            app.timetargetLabelG2.Text = '<timetarget>';
            app.timelevel2hyperLabelG2 = uilabel(app.TimeinRangesTab);
            app.timelevel2hyperLabelG2.Text = '<timelevel2hyper>';
            app.timetighttargetLabelG2 = uilabel(app.TimeinRangesTab);
            app.timetighttargetLabelG2.Text = '<timetighttarget>';
            app.timelevel2hypoLabelG2 = uilabel(app.TimeinRangesTab);
            app.timelevel2hypoLabelG2.Text = '<timelevel2hypo>';
            app.phypoLabel = uilabel(app.TimeinRangesTab);
            app.phypoLabel.Text = '<pvalue>';
            app.phyperLabel = uilabel(app.TimeinRangesTab);
            app.phyperLabel.Text = '<pvalue>';
            app.ptargetLabel = uilabel(app.TimeinRangesTab);
            app.ptargetLabel.Text = '<pvalue>';
            app.pl2hypoLabel = uilabel(app.TimeinRangesTab);
            app.pl2hypoLabel.Text = '<pvalue>';
            app.pl2hyperLabel = uilabel(app.TimeinRangesTab);
            app.pl2hyperLabel.Text = '<pvalue>';
            app.pttargetLabel = uilabel(app.TimeinRangesTab);
            app.pttargetLabel.Text = '<pvalue>';
            app.TIMEINLEVEL1HYPOGLYCEMIACGM5470mgdLLabel = uilabel(app.TimeinRangesTab);
            app.TIMEINLEVEL1HYPOGLYCEMIACGM5470mgdLLabel.Text = 'TIME IN LEVEL 1 HYPOGLYCEMIA (CGM 54-70 mg/dL): ';
            app.timelevel1hypoLabelG1 = uilabel(app.TimeinRangesTab);
            app.timelevel1hypoLabelG1.Text = '<timelevel1hypo>';
            app.timelevel1hypoLabelG2 = uilabel(app.TimeinRangesTab);
            app.timelevel1hypoLabelG2.Text = '<timelevel1hypo>';
            app.pl1hypoLabel = uilabel(app.TimeinRangesTab);
            app.pl1hypoLabel.Text = '<pvalue>';
            app.TIMEINLEVEL1HYPERGLYCEMIACGM180250mgdLLabel = uilabel(app.TimeinRangesTab);
            app.TIMEINLEVEL1HYPERGLYCEMIACGM180250mgdLLabel.Text = 'TIME IN LEVEL 1 HYPERGLYCEMIA (CGM 180-250 mg/dL): ';
            app.timelevel1hyperLabelG1 = uilabel(app.TimeinRangesTab);
            app.timelevel1hyperLabelG1.Text = '<timelevel1hyper>';
            app.timelevel1hyperLabelG2 = uilabel(app.TimeinRangesTab);
            app.timelevel1hyperLabelG2.Text = '<timelevel1hyper>';
            app.pl1hyperLabel = uilabel(app.TimeinRangesTab);
            app.pl1hyperLabel.Text = '<pvalue>';
            app.GlycemicTransformationTab = uitab(app.TabGroup);
            app.GlycemicTransformationTab.Title = 'Glycemic Transformation';
            app.GRADEHYPERGLYCEMIASCORELabel = uilabel(app.GlycemicTransformationTab);
            app.GRADEHYPERGLYCEMIASCORELabel.Text = 'GRADE HYPERGLYCEMIA SCORE:';
            app.GRADESCORELabel = uilabel(app.GlycemicTransformationTab);
            app.GRADESCORELabel.Text = 'GRADE SCORE:';
            app.GRADEEUGLYCEMIASCORELabel = uilabel(app.GlycemicTransformationTab);
            app.GRADEEUGLYCEMIASCORELabel.Text = 'GRADE EUGLYCEMIA SCORE:';
            app.GRADEHYPOGLYCEMIASCORELabel = uilabel(app.GlycemicTransformationTab);
            app.GRADEHYPOGLYCEMIASCORELabel.Text = 'GRADE HYPOGLYCEMIA SCORE:';
            app.INDEXGLYCEMICCONTROLLabel = uilabel(app.GlycemicTransformationTab);
            app.INDEXGLYCEMICCONTROLLabel.Text = 'INDEX GLYCEMIC CONTROL: ';
            app.HYPOINDEXLabel = uilabel(app.GlycemicTransformationTab);
            app.HYPOINDEXLabel.Text = 'HYPO INDEX:';
            app.gradeLabelG1 = uilabel(app.GlycemicTransformationTab);
            app.gradeLabelG1.Text = '<grade>';
            app.gradeeuLabelG1 = uilabel(app.GlycemicTransformationTab);
            app.gradeeuLabelG1.Text = '<gradeeu>';
            app.gradehypoLabelG1 = uilabel(app.GlycemicTransformationTab);
            app.gradehypoLabelG1.Text = '<gradehypo>';
            app.igcLabelG1 = uilabel(app.GlycemicTransformationTab);
            app.igcLabelG1.Text = '<igc>';
            app.hypoindexLabelG1 = uilabel(app.GlycemicTransformationTab);
            app.hypoindexLabelG1.Text = '<hypoindex>';
            app.gradehyperLabelG1 = uilabel(app.GlycemicTransformationTab);
            app.gradehyperLabelG1.Text = '<gradehyper>';
            app.HYPERINDEXLabel = uilabel(app.GlycemicTransformationTab);
            app.HYPERINDEXLabel.Text = 'HYPER INDEX:';
            app.hyperindexLabelG1 = uilabel(app.GlycemicTransformationTab);
            app.hyperindexLabelG1.Text = '<hyperindex>';
            app.MRVALUELabel = uilabel(app.GlycemicTransformationTab);
            app.MRVALUELabel.Text = 'MR VALUE:';
            app.mrLabelG1 = uilabel(app.GlycemicTransformationTab);
            app.mrLabelG1.Text = '<mr>';
            app.gradeLabelG2 = uilabel(app.GlycemicTransformationTab);
            app.gradeLabelG2.Text = '<grade>';
            app.gradeeuLabelG2 = uilabel(app.GlycemicTransformationTab);
            app.gradeeuLabelG2.Text = '<gradeeu>';
            app.gradehypoLabelG2 = uilabel(app.GlycemicTransformationTab);
            app.gradehypoLabelG2.Text = '<gradehypo>';
            app.igcLabelG2 = uilabel(app.GlycemicTransformationTab);
            app.igcLabelG2.Text = '<igc>';
            app.hypoindexLabelG2 = uilabel(app.GlycemicTransformationTab);
            app.hypoindexLabelG2.Text = '<hypoindex>';
            app.gradehyperLabelG2 = uilabel(app.GlycemicTransformationTab);
            app.gradehyperLabelG2.Text = '<gradehyper>';
            app.hyperindexLabelG2 = uilabel(app.GlycemicTransformationTab);
            app.hyperindexLabelG2.Text = '<hyperindex>';
            app.mrLabelG2 = uilabel(app.GlycemicTransformationTab);
            app.mrLabelG2.Text = '<mr>';
            app.pgradeLabel = uilabel(app.GlycemicTransformationTab);
            app.pgradeLabel.Text = '<pvalue>';
            app.pgradeeuLabel = uilabel(app.GlycemicTransformationTab);
            app.pgradeeuLabel.Text = '<pvalue>';
            app.pgradeHypoLabel = uilabel(app.GlycemicTransformationTab);
            app.pgradeHypoLabel.Text = '<pvalue>';
            app.pgradeHyperLabel = uilabel(app.GlycemicTransformationTab);
            app.pgradeHyperLabel.Text = '<pvalue>';
            app.pigcLabel = uilabel(app.GlycemicTransformationTab);
            app.pigcLabel.Text = '<pvalue>';
            app.phypoindLabel = uilabel(app.GlycemicTransformationTab);
            app.phypoindLabel.Text = '<pvalue>';
            app.phyperindLabel = uilabel(app.GlycemicTransformationTab);
            app.phyperindLabel.Text = '<pvalue>';
            app.pmrLabel = uilabel(app.GlycemicTransformationTab);
            app.pmrLabel.Text = '<pvalue>';
            app.DataQualityTab = uitab(app.TabGroup);
            app.DataQualityTab.Title = 'Data Quality';
            app.MISSINGGLUCOSEVALUESPERCENTAGELabel = uilabel(app.DataQualityTab);
            app.MISSINGGLUCOSEVALUESPERCENTAGELabel.Text = 'MISSING GLUCOSE VALUES PERCENTAGE:';
            app.missingpercentageLabelG1 = uilabel(app.DataQualityTab);
            app.missingpercentageLabelG1.Text = '<missingpercentage>';
            app.NUMBEROFDAYSLabel = uilabel(app.DataQualityTab);
            app.NUMBEROFDAYSLabel.Text = 'NUMBER OF DAYS:';
            app.numberDaysLabelG1 = uilabel(app.DataQualityTab);
            app.numberDaysLabelG1.Text = '<numberDays>';
            app.missingpercentageLabelG2 = uilabel(app.DataQualityTab);
            app.missingpercentageLabelG2.Text = '<missingpercentage>';
            app.numberDaysLabelG2 = uilabel(app.DataQualityTab);
            app.numberDaysLabelG2.Text = '<numberDays>';
            app.pmissingLabel = uilabel(app.DataQualityTab);
            app.pmissingLabel.Text = '<pvalue>';
            app.pnodLabel = uilabel(app.DataQualityTab);
            app.pnodLabel.Text = '<pvalue>';
            app.EventsTab = uitab(app.TabGroup);
            app.EventsTab.Title = 'Events';
            app.HYPOGLYCEMICEVENTSPERWEEKLabel = uilabel(app.EventsTab);
            app.HYPOGLYCEMICEVENTSPERWEEKLabel.Text = 'HYPOGLYCEMIC EVENTS PER WEEK:';
            app.HYPOGLYCEMICEVENTSMEANDURATIONLabel = uilabel(app.EventsTab);
            app.HYPOGLYCEMICEVENTSMEANDURATIONLabel.Text = 'HYPOGLYCEMIC EVENTS MEAN DURATION:';
            app.hypodurationLabelG1 = uilabel(app.EventsTab);
            app.hypodurationLabelG1.Text = '<hypoduration>';
            app.hypoperweekLabelG1 = uilabel(app.EventsTab);
            app.hypoperweekLabelG1.Text = '<hypoperweek>';
            app.HYPERGLYCEMICEVENTSPERWEEKLabel_2 = uilabel(app.EventsTab);
            app.HYPERGLYCEMICEVENTSPERWEEKLabel_2.Text = 'HYPERGLYCEMIC EVENTS PER WEEK:';
            app.HYPERGLYCEMICEVENTSMEANDURATIONLabel_2 = uilabel(app.EventsTab);
            app.HYPERGLYCEMICEVENTSMEANDURATIONLabel_2.Text = 'HYPERGLYCEMIC EVENTS MEAN DURATION:';
            app.hyperdurationLabelG1 = uilabel(app.EventsTab);
            app.hyperdurationLabelG1.Text = '<hyperduration>';
            app.hyperperweekLabelG1 = uilabel(app.EventsTab);
            app.hyperperweekLabelG1.Text = '<hyperperweek>';
            app.EXTENDEDHYPOGLYCEMICEVENTSPERWEEKLabel = uilabel(app.EventsTab);
            app.EXTENDEDHYPOGLYCEMICEVENTSPERWEEKLabel.Text = 'EXTENDED HYPOGLYCEMIC EVENTS PER WEEK:';
            app.EXTENDEDHYPOGLYCEMICEVENTSMEANDURATIONLabel = uilabel(app.EventsTab);
            app.EXTENDEDHYPOGLYCEMICEVENTSMEANDURATIONLabel.Text = 'EXTENDED HYPOGLYCEMIC EVENTS MEAN DURATION:';
            app.extendedhypodurationLabelG1 = uilabel(app.EventsTab);
            app.extendedhypodurationLabelG1.Text = '<extendedhypoduration>';
            app.extendedhypoperweekLabelG1 = uilabel(app.EventsTab);
            app.extendedhypoperweekLabelG1.Text = '<extendedhypoperweek>';
            app.hypodurationLabelG2 = uilabel(app.EventsTab);
            app.hypodurationLabelG2.Text = '<hypoduration>';
            app.hypoperweekLabelG2 = uilabel(app.EventsTab);
            app.hypoperweekLabelG2.Text = '<hypoperweek>';
            app.hyperdurationLabelG2 = uilabel(app.EventsTab);
            app.hyperdurationLabelG2.Text = '<hyperduration>';
            app.hyperperweekLabelG2 = uilabel(app.EventsTab);
            app.hyperperweekLabelG2.Text = '<hyperperweek>';
            app.extendedhypodurationLabelG2 = uilabel(app.EventsTab);
            app.extendedhypodurationLabelG2.Text = '<extendedhypoduration>';
            app.extendedhypoperweekLabelG2 = uilabel(app.EventsTab);
            app.extendedhypoperweekLabelG2.Text = '<extendedhypoperweek>';
            app.phypoeventdurationLabel = uilabel(app.EventsTab);
            app.phypoeventdurationLabel.Text = '<pvalue>';
            app.phypoeventweekLabel = uilabel(app.EventsTab);
            app.phypoeventweekLabel.Text = '<pvalue>';
            app.pexthypoeventdurationLabel = uilabel(app.EventsTab);
            app.pexthypoeventdurationLabel.Text = '<pvalue>';
            app.pexthypoeventweekLabel = uilabel(app.EventsTab);
            app.pexthypoeventweekLabel.Text = '<pvalue>';
            app.phypereventdurationLabel = uilabel(app.EventsTab);
            app.phypereventdurationLabel.Text = '<pvalue>';
            app.phypereventweekLabel = uilabel(app.EventsTab);
            app.phypereventweekLabel.Text = '<pvalue>';
            app.LEVEL1HYPERGLYCEMICEVENTSPERWEEKLabel = uilabel(app.EventsTab);
            app.LEVEL1HYPERGLYCEMICEVENTSPERWEEKLabel.Text = 'LEVEL 1 HYPERGLYCEMIC EVENTS PER WEEK:';
            app.LEVEL1HYPERGLYCEMICEVENTSMEANDURATIONLabel = uilabel(app.EventsTab);
            app.LEVEL1HYPERGLYCEMICEVENTSMEANDURATIONLabel.Text = 'LEVEL 1 HYPERGLYCEMIC EVENTS MEAN DURATION:';
            app.l1hyperdurationLabelG1 = uilabel(app.EventsTab);
            app.l1hyperdurationLabelG1.Text = '<l1hyperduration>';
            app.l1hyperperweekLabelG1 = uilabel(app.EventsTab);
            app.l1hyperperweekLabelG1.Text = '<l1hyperperweek>';
            app.l1hyperdurationLabelG2 = uilabel(app.EventsTab);
            app.l1hyperdurationLabelG2.Text = '<l1hyperduration>';
            app.l1hyperperweekLabelG2 = uilabel(app.EventsTab);
            app.l1hyperperweekLabelG2.Text = '<l1hyperperweek>';
            app.pl1hypereventdurationLabel = uilabel(app.EventsTab);
            app.pl1hypereventdurationLabel.Text = '<pvalue>';
            app.pl1hypereventweekLabel = uilabel(app.EventsTab);
            app.pl1hypereventweekLabel.Text = '<pvalue>';
            app.LEVEL2HYPERGLYCEMICEVENTSPERWEEKLabel = uilabel(app.EventsTab);
            app.LEVEL2HYPERGLYCEMICEVENTSPERWEEKLabel.Text = 'LEVEL 2 HYPERGLYCEMIC EVENTS PER WEEK:';
            app.LEVEL2HYPERGLYCEMICEVENTSMEANDURATIONLabel = uilabel(app.EventsTab);
            app.LEVEL2HYPERGLYCEMICEVENTSMEANDURATIONLabel.Text = 'LEVEL 2 HYPERGLYCEMIC EVENTS MEAN DURATION:';
            app.l2hyperdurationLabelG1 = uilabel(app.EventsTab);
            app.l2hyperdurationLabelG1.Text = '<l2hyperduration>';
            app.l2hyperperweekLabelG1 = uilabel(app.EventsTab);
            app.l2hyperperweekLabelG1.Text = '<l2hyperperweek>';
            app.l2hyperdurationLabelG2 = uilabel(app.EventsTab);
            app.l2hyperdurationLabelG2.Text = '<l2hyperduration>';
            app.l2hyperperweekLabelG2 = uilabel(app.EventsTab);
            app.l2hyperperweekLabelG2.Text = '<l2hyperperweek>';
            app.pl2hypereventdurationLabel = uilabel(app.EventsTab);
            app.pl2hypereventdurationLabel.Text = '<pvalue>';
            app.pl2hypereventweekLabel = uilabel(app.EventsTab);
            app.pl2hypereventweekLabel.Text = '<pvalue>';
            app.LEVEL2HYPOGLYCEMICEVENTSPERWEEKLabel = uilabel(app.EventsTab);
            app.LEVEL2HYPOGLYCEMICEVENTSPERWEEKLabel.Text = 'LEVEL 2 HYPOGLYCEMIC EVENTS PER WEEK:';
            app.LEVEL2HYPOGLYCEMICEVENTSMEANDURATIONLabel = uilabel(app.EventsTab);
            app.LEVEL2HYPOGLYCEMICEVENTSMEANDURATIONLabel.Text = 'LEVEL 2 HYPOGLYCEMIC EVENTS MEAN DURATION:';
            app.l2hypodurationLabelG1 = uilabel(app.EventsTab);
            app.l2hypodurationLabelG1.Text = '<hypoduration>';
            app.l2hypoperweekLabelG1 = uilabel(app.EventsTab);
            app.l2hypoperweekLabelG1.Text = '<hypoperweek>';
            app.l2hypodurationLabelG2 = uilabel(app.EventsTab);
            app.l2hypodurationLabelG2.Text = '<hypoduration>';
            app.l2hypoperweekLabelG2 = uilabel(app.EventsTab);
            app.l2hypoperweekLabelG2.Text = '<hypoperweek>';
            app.pl2hypoeventdurationLabel = uilabel(app.EventsTab);
            app.pl2hypoeventdurationLabel.Text = '<pvalue>';
            app.pl2hypoeventweekLabel = uilabel(app.EventsTab);
            app.pl2hypoeventweekLabel.Text = '<pvalue>';
            app.LEVEL1HYPOGLYCEMICEVENTSPERWEEKLabel = uilabel(app.EventsTab);
            app.LEVEL1HYPOGLYCEMICEVENTSPERWEEKLabel.Text = 'LEVEL 1 HYPOGLYCEMIC EVENTS PER WEEK:';
            app.LEVEL1HYPOGLYCEMICEVENTSMEANDURATIONLabel = uilabel(app.EventsTab);
            app.LEVEL1HYPOGLYCEMICEVENTSMEANDURATIONLabel.Text = 'LEVEL 1 HYPOGLYCEMIC EVENTS MEAN DURATION:';
            app.l1hypodurationLabelG1 = uilabel(app.EventsTab);
            app.l1hypodurationLabelG1.Text = '<hypoduration>';
            app.l1hypoperweekLabelG1 = uilabel(app.EventsTab);
            app.l1hypoperweekLabelG1.Text = '<hypoperweek>';
            app.l1hypodurationLabelG2 = uilabel(app.EventsTab);
            app.l1hypodurationLabelG2.Text = '<hypoduration>';
            app.l1hypoperweekLabelG2 = uilabel(app.EventsTab);
            app.l1hypoperweekLabelG2.Text = '<hypoperweek>';
            app.pl1hypoeventdurationLabel = uilabel(app.EventsTab);
            app.pl1hypoeventdurationLabel.Text = '<pvalue>';
            app.pl1hypoeventweekLabel = uilabel(app.EventsTab);
            app.pl1hypoeventweekLabel.Text = '<pvalue>';
            app.SaveAnalysisResultsButton = uibutton(app.AnalysisDataPanel, 'push');
            app.SaveAnalysisResultsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveAnalysisResultsButtonPushed, true);
            app.SaveAnalysisResultsButton.Text = 'Save Analysis Results';
            app.LoadingStatusLabelGroup1 = uilabel(app.AGATAMultipleProfileGUIUIFigure);
            app.LoadingStatusLabelGroup1.Text = 'Group 1: No data';
            app.StatusLampLabel = uilabel(app.AGATAMultipleProfileGUIUIFigure);
            app.StatusLampLabel.Text = 'Status';
            app.StatusLamp = uilamp(app.AGATAMultipleProfileGUIUIFigure);
            app.LoadDataPanelGroup2 = uipanel(app.AGATAMultipleProfileGUIUIFigure);
            app.LoadDataPanelGroup2.Title = 'Load Data Panel - Group 2';
            app.SelectAndLoadDataButtonGroup2 = uibutton(app.LoadDataPanelGroup2, 'push');
            app.SelectAndLoadDataButtonGroup2.ButtonPushedFcn = createCallbackFcn(app, @SelectAndLoadDataFileGroup2, true);
            app.SelectAndLoadDataButtonGroup2.Text = 'Select & Load';
            app.SelectAndLoadDataButtonLabelGroup2 = uilabel(app.LoadDataPanelGroup2);
            app.SelectAndLoadDataButtonLabelGroup2.Text = '2. Select and load data';
            app.SelectCGMdatasourceDropDown_2Label = uilabel(app.LoadDataPanelGroup2);
            app.SelectCGMdatasourceDropDown_2Label.Text = '1. Select CGM data source';
            app.SelectCGMdatasourceDropDownGroup2 = uidropdown(app.LoadDataPanelGroup2);
            app.SelectCGMdatasourceDropDownGroup2.Items = {'<Select a source>', 'AGATA', 'Dexcom', 'Ascensia Eversense', 'Abbott Freestyle Libre'};
            app.SelectCGMdatasourceDropDownGroup2.ValueChangedFcn = createCallbackFcn(app, @SelectCGMdatasourceDropDownGroup2ValueChanged, true);
            app.SelectCGMdatasourceDropDownGroup2.Value = '<Select a source>';
            app.AddDataButtonGroup2 = uibutton(app.LoadDataPanelGroup2, 'push');
            app.AddDataButtonGroup2.ButtonPushedFcn = createCallbackFcn(app, @AddDataButtonGroup2Pushed, true);
            app.AddDataButtonGroup2.Text = 'Add';
            app.DoneButton = uibutton(app.AGATAMultipleProfileGUIUIFigure, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done';
            app.LoadingStatusLabelGroup2 = uilabel(app.AGATAMultipleProfileGUIUIFigure);
            app.LoadingStatusLabelGroup2.Text = 'Group 2: No data';
            % Show the figure after all components are created
            app.AGATAMultipleProfileGUIUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = agataCompareMultipleProfile(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AGATAMultipleProfileGUIUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AGATAMultipleProfileGUIUIFigure)
        end
    end
end