classdef agataSingleProfile < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AGATASingleProfileGUIUIFigure   matlab.ui.Figure
        LoadDataPanel                   matlab.ui.container.Panel
        SelectAndLoadDataButton         matlab.ui.control.Button
        SelectAndLoadDataButtonLabel    matlab.ui.control.Label
        SelectCGMdatasourceDropDownLabel  matlab.ui.control.Label
        SelectCGMdatasourceDropDown     matlab.ui.control.DropDown
        StatusLampLabel                 matlab.ui.control.Label
        StatusLamp                      matlab.ui.control.Lamp
        LoadingStatusLabel              matlab.ui.control.Label
        VisualizationDataPanel          matlab.ui.container.Panel
        PlotGlucoseDataButton           matlab.ui.control.Button
        GenerateAGPButton               matlab.ui.control.Button
        PlotGlucoseRateofChangeButton   matlab.ui.control.Button
        PlotGlucoseRateofChangeHistogramButton  matlab.ui.control.Button
        PlotAggregatedGlucoseDataButton  matlab.ui.control.Button
        PlotGlucoseDataAsOneDayButton   matlab.ui.control.Button
        PlotGlucoseCVGAButton           matlab.ui.control.Button
        PlotGlucoseGRIButton            matlab.ui.control.Button
        ProcessingDataPanel             matlab.ui.container.Panel
        RetimeDataButton                matlab.ui.control.Button
        ImputeDataButton                matlab.ui.control.Button
        DetrendDataButton               matlab.ui.control.Button
        SaveDataButton                  matlab.ui.control.Button
        AnalysisDataPanel               matlab.ui.container.Panel
        AnalyzeGlucoseDataButton        matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        VariabilityTab                  matlab.ui.container.Tab
        AUCLabel                        matlab.ui.control.Label
        CVGALabel                       matlab.ui.control.Label
        CVLabel                         matlab.ui.control.Label
        MEANLabel                       matlab.ui.control.Label
        MEDIANLabel                     matlab.ui.control.Label
        SDLabel                         matlab.ui.control.Label
        JINDEXLabel                     matlab.ui.control.Label
        GMILabel                        matlab.ui.control.Label
        IQRLabel                        matlab.ui.control.Label
        RANGELabel                      matlab.ui.control.Label
        SDROCLabel                      matlab.ui.control.Label
        SDDMINDEXLabel                  matlab.ui.control.Label
        SDWINDEXLabel                   matlab.ui.control.Label
        EFINDEXLabel                    matlab.ui.control.Label
        MAGEINDEXLabel                  matlab.ui.control.Label
        MAGEINDEXLabel_2                matlab.ui.control.Label
        MAGEINDEXLabel_3                matlab.ui.control.Label
        meanLabel                       matlab.ui.control.Label
        medianLabel                     matlab.ui.control.Label
        sdLabel                         matlab.ui.control.Label
        cvLabel                         matlab.ui.control.Label
        iqrLabel                        matlab.ui.control.Label
        rangeLabel                      matlab.ui.control.Label
        sdrocLabel                      matlab.ui.control.Label
        aucLabel                        matlab.ui.control.Label
        cvgaLabel                       matlab.ui.control.Label
        jindexLabel                     matlab.ui.control.Label
        sddmindexLabel                  matlab.ui.control.Label
        sdwindexLabel                   matlab.ui.control.Label
        efindexLabel                    matlab.ui.control.Label
        mageindexLabel                  matlab.ui.control.Label
        mageplusindexLabel              matlab.ui.control.Label
        mageminusindexLabel             matlab.ui.control.Label
        gmiLabel                        matlab.ui.control.Label
        CONGALabel                      matlab.ui.control.Label
        MODDLabel                       matlab.ui.control.Label
        congaLabel                      matlab.ui.control.Label
        moddLabel                       matlab.ui.control.Label
        COGILabel                       matlab.ui.control.Label
        cogiLabel                       matlab.ui.control.Label
        RiskTab                         matlab.ui.container.Tab
        HBGILabel                       matlab.ui.control.Label
        ADRRLabel                       matlab.ui.control.Label
        BGRILabel                       matlab.ui.control.Label
        LBGILabel                       matlab.ui.control.Label
        adrrLabel                       matlab.ui.control.Label
        bgriLabel                       matlab.ui.control.Label
        lbgiLabel                       matlab.ui.control.Label
        hbgiLabel                       matlab.ui.control.Label
        GRILabel                        matlab.ui.control.Label
        griLabel                        matlab.ui.control.Label
        TimeinRangesTab                 matlab.ui.container.Tab
        TIMEINLEVEL2HYPOGLYCEMIACGM54mgdLLabel  matlab.ui.control.Label
        TIMEINHYPOGLYCEMIACGM70mgdLLabel  matlab.ui.control.Label
        TIMEINHYPERGLYCEMIACGM180mgdLLabel  matlab.ui.control.Label
        TIMEINTARGETCGM70180mgdLLabel   matlab.ui.control.Label
        TIMEINLEVEL2HYPERGLYCEMIACGM250mgdLLabel  matlab.ui.control.Label
        TIMEINTIGHTTARGETCGM70140mgdLLabel  matlab.ui.control.Label
        timehypoLabel                   matlab.ui.control.Label
        timehyperLabel                  matlab.ui.control.Label
        timetargetLabel                 matlab.ui.control.Label
        timelevel2hyperLabel            matlab.ui.control.Label
        timetighttargetLabel            matlab.ui.control.Label
        timelevel2hypoLabel             matlab.ui.control.Label
        TIMEINLEVEL1HYPOGLYCEMIACGM5470mgdLLabel  matlab.ui.control.Label
        timelevel1hypoLabel             matlab.ui.control.Label
        TIMEINLEVEL1HYPERGLYCEMIACGM180250mgdLLabel  matlab.ui.control.Label
        timelevel1hyperLabel            matlab.ui.control.Label
        GlycemicTransformationTab       matlab.ui.container.Tab
        GRADEHYPERGLYCEMIASCORELabel    matlab.ui.control.Label
        GRADESCORELabel                 matlab.ui.control.Label
        GRADEEUGLYCEMIASCORELabel       matlab.ui.control.Label
        GRADEHYPOGLYCEMIASCORELabel     matlab.ui.control.Label
        INDEXGLYCEMICCONTROLLabel       matlab.ui.control.Label
        HYPOINDEXLabel                  matlab.ui.control.Label
        gradeLabel                      matlab.ui.control.Label
        gradeeuLabel                    matlab.ui.control.Label
        gradehypoLabel                  matlab.ui.control.Label
        igcLabel                        matlab.ui.control.Label
        hypoindexLabel                  matlab.ui.control.Label
        gradehyperLabel                 matlab.ui.control.Label
        HYPERINDEXLabel                 matlab.ui.control.Label
        hyperindexLabel                 matlab.ui.control.Label
        MRVALUELabel                    matlab.ui.control.Label
        mrLabel                         matlab.ui.control.Label
        DataQualityTab                  matlab.ui.container.Tab
        MISSINGGLUCOSEVALUESPERCENTAGELabel  matlab.ui.control.Label
        missingpercentageLabel          matlab.ui.control.Label
        NUMBEROFDAYSLabel               matlab.ui.control.Label
        numberDaysLabel                 matlab.ui.control.Label
        EventsTab                       matlab.ui.container.Tab
        HYPOGLYCEMICEVENTSPERWEEKLabel  matlab.ui.control.Label
        HYPOGLYCEMICEVENTSMEANDURATIONLabel  matlab.ui.control.Label
        hypodurationLabel               matlab.ui.control.Label
        hypoperweekLabel                matlab.ui.control.Label
        HYPERGLYCEMICEVENTSPERWEEKLabel_2  matlab.ui.control.Label
        HYPERGLYCEMICEVENTSMEANDURATIONLabel_2  matlab.ui.control.Label
        hyperdurationLabel              matlab.ui.control.Label
        hyperperweekLabel               matlab.ui.control.Label
        EXTENDEDHYPOGLYCEMICEVENTSPERWEEKLabel  matlab.ui.control.Label
        EXTENDEDHYPOGLYCEMICEVENTSMEANDURATIONLabel  matlab.ui.control.Label
        extendedhypodurationLabel       matlab.ui.control.Label
        extendedhypoperweekLabel        matlab.ui.control.Label
        LEVEL1HYPERGLYCEMICEVENTSPERWEEKLabel  matlab.ui.control.Label
        LEVEL1HYPERGLYCEMICEVENTSMEANDURATIONLabel  matlab.ui.control.Label
        l1hyperdurationLabel            matlab.ui.control.Label
        l1hyperperweekLabel             matlab.ui.control.Label
        LEVEL2HYPERGLYCEMICEVENTSPERWEEKLabel  matlab.ui.control.Label
        LEVEL2HYPERGLYCEMICEVENTSMEANDURATIONLabel  matlab.ui.control.Label
        l2hyperdurationLabel            matlab.ui.control.Label
        l2hyperperweekLabel             matlab.ui.control.Label
        LEVEL1HYPOGLYCEMICEVENTSPERWEEKLabel  matlab.ui.control.Label
        LEVEL1HYPOGLYCEMICEVENTSMEANDURATIONLabel  matlab.ui.control.Label
        l1hypodurationLabel             matlab.ui.control.Label
        l1hypoperweekLabel              matlab.ui.control.Label
        LEVEL2HYPOGLYCEMICEVENTSPERWEEKLabel  matlab.ui.control.Label
        LEVEL2HYPOGLYCEMICEVENTSMEANDURATIONLabel  matlab.ui.control.Label
        l2hypodurationLabel             matlab.ui.control.Label
        l2hypoperweekLabel              matlab.ui.control.Label
        SaveAnalysisResultsButton       matlab.ui.control.Button
    end

    
    properties (Access = private)
        Data % Glucose data to analyze
        CallingApp %The app that called me
        AnalysisResults %The results of the analysis
    end
    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            %Load AGATA core
            addpath(genpath('src'));
            
            %Store the calling app object
            app.CallingApp = mainapp;
            
            %Hide actions of the load data panel
            app.SelectAndLoadDataButton.Visible = false;
            app.SelectAndLoadDataButtonLabel.Visible = false;
            
            %Hide other panels
            app.VisualizationDataPanel.Visible = false;
            app.ProcessingDataPanel.Visible = false;
            app.AnalysisDataPanel.Visible = false;
        end

        % Button pushed function: SelectAndLoadDataButton
        function SelectAndLoadDataFile(app, event)
            
            %Get the file via UI
            if(strcmp(app.SelectCGMdatasourceDropDown.Value,'AGATA'))
                extension = '*.mat';
            else
                extension = '*';
            end
            [file,path] = uigetfile(extension);
            
            %If a file has been selected 
            if(file~=0)
                
                %Get the complete file path
                filename = [path file];
                
                %Update UI components' state
                app.SelectCGMdatasourceDropDown.Enable = false;
                app.SelectAndLoadDataButton.Enable = false;
                app.LoadingStatusLabel.Text = 'Loading...';
                app.StatusLamp.Color = 'y';
                pause(0.001);
                
                %Load data using AGATA core
                try
                    switch app.SelectCGMdatasourceDropDown.Value
                        case 'AGATA'
                            load(filename);
                            app.Data = data;
                            
                            %Check preconditions 
                            if(~istimetable(data))
                                error('Loaded data must be in a timetable called data.');
                            end
                            if(~any(strcmp(fieldnames(data),'glucose')))
                                error('Loaded data must have a column named `glucose`.')
                            end
                            
                        case 'Dexcom'
                            app.Data = readDexcomData(filename);
                        case 'Ascensia Eversense'
                            app.Data = readEversenseData(filename);
                        case 'Abbott Freestyle Libre'
                            app.Data = readFreestyleLibreData(filename);
                    end
                    %Update UI components' state
                    app.SelectCGMdatasourceDropDown.Enable = true;
                    app.SelectAndLoadDataButton.Enable = true;
                    app.LoadingStatusLabel.Text = 'Ready';
                    app.StatusLamp.Color = 'g';
                    
                    %Visualize other panels
                    app.VisualizationDataPanel.Visible = true;
                    app.ProcessingDataPanel.Visible = true;
                    app.AnalysisDataPanel.Visible = true;
                    app.SaveAnalysisResultsButton.Enable = false;
                    app.SelectCGMdatasourceDropDown.Value = '<Select a source>';
                    
                    %While not retimed, lock some options
                    data = app.Data;
                    if(var(seconds(diff(data.Time))) > 0 || isnan(var(seconds(diff(data.Time)))))
                    
                        app.ImputeDataButton.Enable = false;
                        app.DetrendDataButton.Enable = false;
                        
                        app.AnalyzeGlucoseDataButton.Enable = false;
                        
                        app.PlotAggregatedGlucoseDataButton.Enable = false;
                        app.PlotGlucoseRateofChangeButton.Enable = false;
                        app.PlotGlucoseDataAsOneDayButton.Enable = false;
                        app.PlotGlucoseRateofChangeHistogramButton.Enable = false;
                        app.PlotGlucoseGRIButton.Enable = false;
                        app.GenerateAGPButton.Enable = false;
                    
                    else
                        
                        app.ImputeDataButton.Enable = true;
                        app.DetrendDataButton.Enable = true;
                        
                        app.AnalyzeGlucoseDataButton.Enable = true;
                        
                        app.PlotAggregatedGlucoseDataButton.Enable = true;
                        app.PlotGlucoseRateofChangeButton.Enable = true;
                        app.PlotGlucoseDataAsOneDayButton.Enable = true;
                        app.PlotGlucoseRateofChangeHistogramButton.Enable = true;
                        app.PlotGlucoseGRIButton.Enable = true;
                        app.GenerateAGPButton.Enable = true;
                        
                    end
                    
                    %if data are in mmol/l convert them
                    if(nanmean(data.glucose) < 30)
                        data.glucose = data.glucose* 18.018;
                        app.Data = data;
                    end
                    
                catch exception
                    %Update UI components' state
                    app.LoadingStatusLabel.Text = 'No data';
                    app.StatusLamp.Color = 'r';
                    app.SelectCGMdatasourceDropDown.Enable = true;
                    app.SelectAndLoadDataButton.Enable = true;
                    
                    %Hide other panels
                    app.VisualizationDataPanel.Visible = false;
                    app.ProcessingDataPanel.Visible = false;
                    app.AnalysisDataPanel.Visible = false;
                    
                    %While not retimed, lock some options
                    app.ImputeDataButton.Enable = false;
                    app.DetrendDataButton.Enable = false;
                    
                    app.AnalyzeGlucoseDataButton.Enable = false;
                    
                    app.PlotAggregatedGlucoseDataButton.Enable = false;
                    app.PlotGlucoseRateofChangeButton.Enable = false;
                    app.PlotGlucoseDataAsOneDayButton.Enable = false;
                    app.PlotGlucoseRateofChangeHistogramButton.Enable = false;
                    app.PlotGlucoseGRIButton.Enable = false;
                    app.GenerateAGPButton.Enable = false;
                    
                    errordlg('The selected file is not readable or is not coming from the specified CGM data source','Invalid data file');
                
                end
                
            end
            
            
        end

        % Value changed function: SelectCGMdatasourceDropDown
        function SelectCGMdatasourceDropDownValueChanged(app, event)
            value = app.SelectCGMdatasourceDropDown.Value;
            if(~strcmp(value,'<Select a source>'))
                app.SelectAndLoadDataButton.Visible = true;
                app.SelectAndLoadDataButtonLabel.Visible = true;
            else
                app.SelectAndLoadDataButton.Visible = false;
                app.SelectAndLoadDataButtonLabel.Visible = false;
            end
            
        end

        % Button pushed function: PlotGlucoseDataButton
        function PlotGlucoseDataButtonPushed(app, event)
            
            %Update UI components' state
            app.PlotGlucoseDataButton.Enable = false;
            app.LoadingStatusLabel.Text = 'Plotting...';
            app.StatusLamp.Color = 'y';
            pause(0.001);
            
            plotGlucose(app.Data);
            
            %Update UI components' state
            app.PlotGlucoseDataButton.Enable = true;
            app.LoadingStatusLabel.Text = 'Ready';
            app.StatusLamp.Color = 'g';
        end

        % Button pushed function: RetimeDataButton
        function RetimeDataButtonPushed(app, event)
            
            %Update UI components' state
            app.RetimeDataButton.Enable = false;
            app.LoadingStatusLabel.Text = 'Processing...';
            app.StatusLamp.Color = 'y';
            
            %Get the time step to be used to retime data
            prompt = {'Enter a value for time step (in minutes)'};
            dlgtitle = 'Time step value';
            definput = {''};
            dims = [1 40];
            timestep = inputdlg(prompt,dlgtitle,dims,definput);
            if(~isempty(timestep))
                
                %Get the actual number
                timestep = str2num(timestep{1});
            
                %Check the input validity and retime data
                if(~isempty(timestep) && timestep > 0 && isnumeric(timestep) && ((timestep - round(timestep)) == 0) )
                    app.Data = retimeGlucose(app.Data,timestep);
                    
                    %Once retimed, unlock new options
                    app.ImputeDataButton.Enable = true;
                    app.DetrendDataButton.Enable = true;
                    
                    app.AnalyzeGlucoseDataButton.Enable = true;
                    
                    app.PlotAggregatedGlucoseDataButton.Enable = true;
                    app.PlotGlucoseRateofChangeButton.Enable = true;
                    app.PlotGlucoseDataAsOneDayButton.Enable = true;
                    app.PlotGlucoseRateofChangeHistogramButton.Enable = true;
                    app.PlotGlucoseGRIButton.Enable = true;
                    app.GenerateAGPButton.Enable = true;
                    
                else
                    errordlg('The time step value must be an integer > 0.','Invalid value');
                end
                
            end
            
            %Update UI components' state
            app.RetimeDataButton.Enable = true;
            app.LoadingStatusLabel.Text = 'Ready';
            app.StatusLamp.Color = 'g';
            
        end

        % Button pushed function: ImputeDataButton
        function ImputeDataButtonPushed(app, event)
            %Update UI components' state
            app.LoadingStatusLabel.Text = 'Processing...';
            app.StatusLamp.Color = 'y';
            app.ImputeDataButton.Enable = false;
            
            %Get the time step to be used to retime data
            prompt = {'Enter a value for the maximum imputable gap (in minutes)'};
            dlgtitle = 'Max gap value';
            definput = {''};
            dims = [1 40];
            maxGap = inputdlg(prompt,dlgtitle,dims,definput);
            if(~isempty(maxGap))
                
                %Get the actual number
                maxGap = str2num(maxGap{1});
                
                try
                %Check the input validity and impute data
                if(~isempty(maxGap) && isnumeric(maxGap) && ((maxGap - round(maxGap)) == 0) )
                    app.Data = imputeGlucose(app.Data,maxGap);
                else
                    errordlg('The max gap value must be an integer.','Invalid value');
                end
                catch exception
                    errordlg('Imputation is not possible. Did you retime data first?','Impossible to impute');
                end
            end
            
            %Update UI components' state
            app.ImputeDataButton.Enable = true;
            app.LoadingStatusLabel.Text = 'Ready';
            app.StatusLamp.Color = 'g';
        end

        % Button pushed function: DetrendDataButton
        function DetrendDataButtonPushed(app, event)
            %Update UI components' state
            app.LoadingStatusLabel.Text = 'Processing...';
            app.StatusLamp.Color = 'y';
            app.DetrendDataButton.Enable = false;
                
            try
                app.Data = detrendGlucose(app.Data);
            catch exception
                errordlg('Detrending is not possible. Did you retime data first?','Impossible to detrend');
            end
            
            %Update UI components' state
            app.DetrendDataButton.Enable = true;
            app.LoadingStatusLabel.Text = 'Ready';
            app.StatusLamp.Color = 'g';
        end

        % Button pushed function: PlotGlucoseRateofChangeButton
        function PlotGlucoseRateofChangeButtonPushed(app, event)
            %Update UI components' state
            app.PlotGlucoseRateofChangeButton.Enable = false;
            app.LoadingStatusLabel.Text = 'Plotting...';
            app.StatusLamp.Color = 'y';
            pause(0.001);
            
            try
                plotGlucoseROC(app.Data);
            catch exception
                errordlg('Impossible to generate the requested plot. Did you retimed data?','Impossible to plot');
            end
            
            %Update UI components' state
            app.PlotGlucoseRateofChangeButton.Enable = true;
            app.LoadingStatusLabel.Text = 'Ready';
            app.StatusLamp.Color = 'g';
        end

        % Button pushed function: GenerateAGPButton
        function GenerateAGPButtonPushed(app, event)
            
            %Update UI components' state
            app.GenerateAGPButton.Enable = false;
            app.LoadingStatusLabel.Text = 'Plotting...';
            app.StatusLamp.Color = 'y';
            pause(0.001);
            
            try
                generateAGP(app.Data);
            catch exception
                errordlg('Impossible to generate the requested plot. Did you retimed data?','Impossible to plot');
            end
            
            %Update UI components' state
            app.GenerateAGPButton.Enable = true;
            app.LoadingStatusLabel.Text = 'Ready';
            app.StatusLamp.Color = 'g';
        end

        % Button pushed function: 
        % PlotGlucoseRateofChangeHistogramButton
        function PlotGlucoseRateofChangeHistogramButtonPushed(app, event)
            
            %Update UI components' state
            app.PlotGlucoseRateofChangeHistogramButton.Enable = false;
            app.LoadingStatusLabel.Text = 'Plotting...';
            app.StatusLamp.Color = 'y';
            pause(0.001);
            
            try
                histGlucoseROC(app.Data);
            catch exception
                errordlg('Impossible to generate the requested plot. Did you retimed data?','Impossible to plot');
            end
            
            %Update UI components' state
            app.PlotGlucoseRateofChangeHistogramButton.Enable = true;
            app.LoadingStatusLabel.Text = 'Ready';
            app.StatusLamp.Color = 'g';
        end

        % Button pushed function: PlotAggregatedGlucoseDataButton
        function PlotAggregatedGlucoseDataButtonPushed(app, event)
            
            %Update UI components' state
            app.PlotAggregatedGlucoseDataButton.Enable = false;
            app.LoadingStatusLabel.Text = 'Plotting...';
            app.StatusLamp.Color = 'y';
            pause(0.001);
            
            try
                plotAggregatedGlucose(app.Data);
            catch exception
                errordlg('Impossible to generate the requested plot. Did you retimed data?','Impossible to plot');
            end
            %Update UI components' state
            app.PlotAggregatedGlucoseDataButton.Enable = true;
            app.LoadingStatusLabel.Text = 'Ready';
            app.StatusLamp.Color = 'g';
        end

        % Button pushed function: PlotGlucoseDataAsOneDayButton
        function PlotGlucoseDataAsOneDayButtonPushed(app, event)
            
            %Update UI components' state
            app.PlotGlucoseDataAsOneDayButton.Enable = false;
            app.LoadingStatusLabel.Text = 'Plotting...';
            app.StatusLamp.Color = 'y';
            pause(0.001);
            
            try
                plotGlucoseAsOneDay(app.Data);
            catch exception
                errordlg('Impossible to generate the requested plot. Did you retimed data?','Impossible to plot');
            end
            
            %Update UI components' state
            app.PlotGlucoseDataAsOneDayButton.Enable = true;
            app.LoadingStatusLabel.Text = 'Ready';
            app.StatusLamp.Color = 'g';
        end

        % Button pushed function: PlotGlucoseCVGAButton
        function PlotGlucoseCVGAButtonPushed(app, event)
            
            %Update UI components' state
            app.PlotGlucoseCVGAButton.Enable = false;
            app.LoadingStatusLabel.Text = 'Plotting...';
            app.StatusLamp.Color = 'y';
            pause(0.001);
            
            plotCVGA({app.Data});
            
            %Update UI components' state
            app.PlotGlucoseCVGAButton.Enable = true;
            app.LoadingStatusLabel.Text = 'Ready';
            app.StatusLamp.Color = 'g';
        end

        % Button pushed function: AnalyzeGlucoseDataButton
        function AnalyzeGlucoseDataButtonPushed(app, event)
            
            %Update UI components' state
            app.AnalyzeGlucoseDataButton.Enable = false;
            app.LoadingStatusLabel.Text = 'Analyzing...';
            app.StatusLamp.Color = 'y';
            pause(0.001);
            
            try
                results = analyzeGlucoseProfile(app.Data);
                
                %Set variability texts
                app.meanLabel.Text = num2str(results.variability.meanGlucose);
                app.medianLabel.Text = num2str(results.variability.medianGlucose);
                app.sdLabel.Text = num2str(results.variability.stdGlucose);
                app.cvLabel.Text = num2str(results.variability.cvGlucose);
                app.iqrLabel.Text = num2str(results.variability.iqrGlucose);
                app.rangeLabel.Text = num2str(results.variability.rangeGlucose);
                app.sdrocLabel.Text = num2str(results.variability.stdGlucoseROC);
                app.aucLabel.Text = num2str(results.variability.aucGlucose);
                app.cvgaLabel.Text = num2str(results.variability.CVGA);
                app.cogiLabel.Text = num2str(results.variability.cogi);
                app.jindexLabel.Text = num2str(results.variability.jIndex);
                app.gmiLabel.Text = num2str(results.variability.gmi);
                app.sddmindexLabel.Text = num2str(results.variability.sddmIndex);
                app.sdwindexLabel.Text = num2str(results.variability.sdwIndex);
                app.efindexLabel.Text = num2str(results.variability.efIndex);
                app.mageindexLabel.Text = num2str(results.variability.mageIndex);
                app.mageplusindexLabel.Text = num2str(results.variability.magePlusIndex);
                app.mageminusindexLabel.Text = num2str(results.variability.mageMinusIndex);
                app.moddLabel.Text = num2str(results.variability.modd);
                app.congaLabel.Text = num2str(results.variability.conga);
                
                %Set risk texts
                app.adrrLabel.Text = num2str(results.risk.adrr);
                app.bgriLabel.Text = num2str(results.risk.bgri);
                app.lbgiLabel.Text = num2str(results.risk.lbgi);
                app.hbgiLabel.Text = num2str(results.risk.hbgi);
                app.griLabel.Text = num2str(results.risk.gri);
                
                %Set time texts
                app.timehypoLabel.Text = num2str(results.time.timeInHypoglycemia);
                app.timehyperLabel.Text = num2str(results.time.timeInHyperglycemia);
                app.timetargetLabel.Text = num2str(results.time.timeInTarget);
                app.timelevel1hypoLabel.Text = num2str(results.time.timeInL1Hypoglycemia);
                app.timelevel1hyperLabel.Text = num2str(results.time.timeInL1Hyperglycemia);
                app.timelevel2hypoLabel.Text = num2str(results.time.timeInL2Hypoglycemia);
                app.timelevel2hyperLabel.Text = num2str(results.time.timeInL2Hyperglycemia);
                app.timetighttargetLabel.Text = num2str(results.time.timeInTightTarget);
                
                %Set glycemic transformation texts
                app.gradeLabel.Text = num2str(results.glycemicTransformation.gradeScore);
                app.gradeeuLabel.Text = num2str(results.glycemicTransformation.gradeEuScore);
                app.gradehypoLabel.Text = num2str(results.glycemicTransformation.gradeHypoScore);
                app.gradehyperLabel.Text = num2str(results.glycemicTransformation.gradeHyperScore);
                app.igcLabel.Text = num2str(results.glycemicTransformation.igc);
                app.hypoindexLabel.Text = num2str(results.glycemicTransformation.hypoIndex);
                app.hyperindexLabel.Text = num2str(results.glycemicTransformation.hyperIndex);
                app.mrLabel.Text = num2str(results.glycemicTransformation.mrIndex);
                
                %Set the data quality texts
                app.missingpercentageLabel.Text = num2str(results.dataQuality.missingGlucosePercentage);
                app.numberDaysLabel.Text = num2str(results.dataQuality.numberDaysOfObservation);
                
                %Set the events texts
                app.hypodurationLabel.Text = num2str(results.event.hypoglycemicEvents.hypo.meanDuration);
                app.hypoperweekLabel.Text = num2str(results.event.hypoglycemicEvents.hypo.eventsPerWeek);
                app.l1hypodurationLabel.Text = num2str(results.event.hypoglycemicEvents.l1.meanDuration);
                app.l1hypoperweekLabel.Text = num2str(results.event.hypoglycemicEvents.l1.eventsPerWeek);
                app.l2hypodurationLabel.Text = num2str(results.event.hypoglycemicEvents.l2.meanDuration);
                app.l2hypoperweekLabel.Text = num2str(results.event.hypoglycemicEvents.l2.eventsPerWeek);
                
                app.extendedhypodurationLabel.Text = num2str(results.event.extendedHypoglycemicEvents.meanDuration);
                app.extendedhypoperweekLabel.Text = num2str(results.event.extendedHypoglycemicEvents.eventsPerWeek);
                app.hyperdurationLabel.Text = num2str(results.event.hyperglycemicEvents.hyper.meanDuration);
                app.hyperperweekLabel.Text = num2str(results.event.hyperglycemicEvents.hyper.eventsPerWeek);
                app.l1hyperdurationLabel.Text = num2str(results.event.hyperglycemicEvents.l1.meanDuration);
                app.l1hyperperweekLabel.Text = num2str(results.event.hyperglycemicEvents.l1.eventsPerWeek);
                app.l2hyperdurationLabel.Text = num2str(results.event.hyperglycemicEvents.l2.meanDuration);
                app.l2hyperperweekLabel.Text = num2str(results.event.hyperglycemicEvents.l2.eventsPerWeek);
                
                %Store the results in the workspace
                app.AnalysisResults = results;
                app.SaveAnalysisResultsButton.Enable = true;
                
            catch exception
                errordlg('Impossible to analyze data. Did you retimed them first?','Impossible to analyze');
            end
            %Update UI components' state
            app.AnalyzeGlucoseDataButton.Enable = true;
            app.LoadingStatusLabel.Text = 'Ready';
            app.StatusLamp.Color = 'g';
        end

        % Button pushed function: SaveAnalysisResultsButton
        function SaveAnalysisResultsButtonPushed(app, event)
            results = app.AnalysisResults;
            uisave({'results'},'singleProfileAnalysisResults')
        end

        % Button pushed function: SaveDataButton
        function SaveDataButtonPushed(app, event)
            data = app.Data;
            uisave({'data'},'data')
        end

        % Button pushed function: PlotGlucoseGRIButton
        function PlotGlucoseGRIButtonPushed(app, event)
            %Update UI components' state
            app.PlotGlucoseGRIButton.Enable = false;
            app.LoadingStatusLabel.Text = 'Plotting...';
            app.StatusLamp.Color = 'y';
            pause(0.001);
            
            plotGRI({app.Data});
            
            %Update UI components' state
            app.PlotGlucoseGRIButton.Enable = true;
            app.LoadingStatusLabel.Text = 'Ready';
            app.StatusLamp.Color = 'g';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AGATASingleProfileGUIUIFigure = uifigure('Visible', 'off');
            app.AGATASingleProfileGUIUIFigure.Name = 'AGATA Single Profile GUI';
            app.LoadDataPanel = uipanel(app.AGATASingleProfileGUIUIFigure);
            app.LoadDataPanel.Title = 'Load Data Panel';
            app.SelectAndLoadDataButton = uibutton(app.LoadDataPanel, 'push');
            app.SelectAndLoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @SelectAndLoadDataFile, true);
            app.SelectAndLoadDataButton.Text = 'Select & Load';
            app.SelectAndLoadDataButtonLabel = uilabel(app.LoadDataPanel);
            app.SelectAndLoadDataButtonLabel.Text = '2. Select and load data';
            app.SelectCGMdatasourceDropDownLabel = uilabel(app.LoadDataPanel);
            app.SelectCGMdatasourceDropDownLabel.Text = '1. Select CGM data source';
            app.SelectCGMdatasourceDropDown = uidropdown(app.LoadDataPanel);
            app.SelectCGMdatasourceDropDown.Items = {'<Select a source>', 'AGATA', 'Dexcom', 'Ascensia Eversense', 'Abbott Freestyle Libre'};
            app.SelectCGMdatasourceDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectCGMdatasourceDropDownValueChanged, true);
            app.SelectCGMdatasourceDropDown.Value = '<Select a source>';
            app.StatusLampLabel = uilabel(app.LoadDataPanel);
            app.StatusLampLabel.Text = 'Status';
            app.StatusLamp = uilamp(app.LoadDataPanel);
            app.LoadingStatusLabel = uilabel(app.LoadDataPanel);
            app.LoadingStatusLabel.Text = 'No data';
            app.VisualizationDataPanel = uipanel(app.AGATASingleProfileGUIUIFigure);
            app.VisualizationDataPanel.Title = 'Visualization Data Panel';
            app.PlotGlucoseDataButton = uibutton(app.VisualizationDataPanel, 'push');
            app.PlotGlucoseDataButton.ButtonPushedFcn = createCallbackFcn(app, @PlotGlucoseDataButtonPushed, true);
            app.PlotGlucoseDataButton.Text = 'Plot Glucose Data';
            app.GenerateAGPButton = uibutton(app.VisualizationDataPanel, 'push');
            app.GenerateAGPButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateAGPButtonPushed, true);
            app.GenerateAGPButton.Text = 'Generate AGP';
            app.PlotGlucoseRateofChangeButton = uibutton(app.VisualizationDataPanel, 'push');
            app.PlotGlucoseRateofChangeButton.ButtonPushedFcn = createCallbackFcn(app, @PlotGlucoseRateofChangeButtonPushed, true);
            app.PlotGlucoseRateofChangeButton.Text = 'Plot Glucose Rate-of-Change';
            app.PlotGlucoseRateofChangeHistogramButton = uibutton(app.VisualizationDataPanel, 'push');
            app.PlotGlucoseRateofChangeHistogramButton.ButtonPushedFcn = createCallbackFcn(app, @PlotGlucoseRateofChangeHistogramButtonPushed, true);
            app.PlotGlucoseRateofChangeHistogramButton.Text = 'Plot Glucose Rate-of-Change Histogram';
            app.PlotAggregatedGlucoseDataButton = uibutton(app.VisualizationDataPanel, 'push');
            app.PlotAggregatedGlucoseDataButton.ButtonPushedFcn = createCallbackFcn(app, @PlotAggregatedGlucoseDataButtonPushed, true);
            app.PlotAggregatedGlucoseDataButton.Text = 'Plot Aggregated Glucose Data';
            app.PlotGlucoseDataAsOneDayButton = uibutton(app.VisualizationDataPanel, 'push');
            app.PlotGlucoseDataAsOneDayButton.ButtonPushedFcn = createCallbackFcn(app, @PlotGlucoseDataAsOneDayButtonPushed, true);
            app.PlotGlucoseDataAsOneDayButton.Text = 'Plot Glucose Data As One Day';
            app.PlotGlucoseCVGAButton = uibutton(app.VisualizationDataPanel, 'push');
            app.PlotGlucoseCVGAButton.ButtonPushedFcn = createCallbackFcn(app, @PlotGlucoseCVGAButtonPushed, true);
            app.PlotGlucoseCVGAButton.Text = 'Plot Glucose CVGA';
            app.PlotGlucoseGRIButton = uibutton(app.VisualizationDataPanel, 'push');
            app.PlotGlucoseGRIButton.ButtonPushedFcn = createCallbackFcn(app, @PlotGlucoseGRIButtonPushed, true);
            app.PlotGlucoseGRIButton.Text = 'Plot Glucose GRI';
            app.ProcessingDataPanel = uipanel(app.AGATASingleProfileGUIUIFigure);
            app.ProcessingDataPanel.Title = 'Processing Data Panel';
            app.RetimeDataButton = uibutton(app.ProcessingDataPanel, 'push');
            app.RetimeDataButton.ButtonPushedFcn = createCallbackFcn(app, @RetimeDataButtonPushed, true);
            app.RetimeDataButton.Text = 'Retime Data';
            app.ImputeDataButton = uibutton(app.ProcessingDataPanel, 'push');
            app.ImputeDataButton.ButtonPushedFcn = createCallbackFcn(app, @ImputeDataButtonPushed, true);
            app.ImputeDataButton.Text = 'Impute Data';
            app.DetrendDataButton = uibutton(app.ProcessingDataPanel, 'push');
            app.DetrendDataButton.ButtonPushedFcn = createCallbackFcn(app, @DetrendDataButtonPushed, true);
            app.DetrendDataButton.Text = 'Detrend Data';
            app.SaveDataButton = uibutton(app.ProcessingDataPanel, 'push');
            app.SaveDataButton.ButtonPushedFcn = createCallbackFcn(app, @SaveDataButtonPushed, true);
            app.SaveDataButton.Text = 'Save Data';
            app.AnalysisDataPanel = uipanel(app.AGATASingleProfileGUIUIFigure);
            app.AnalysisDataPanel.Title = 'Analysis Data Panel';
            app.AnalyzeGlucoseDataButton = uibutton(app.AnalysisDataPanel, 'push');
            app.AnalyzeGlucoseDataButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeGlucoseDataButtonPushed, true);
            app.AnalyzeGlucoseDataButton.Text = 'Analyze Glucose Data';
            app.TabGroup = uitabgroup(app.AnalysisDataPanel);
            app.VariabilityTab = uitab(app.TabGroup);
            app.VariabilityTab.Title = 'Variability';
            app.AUCLabel = uilabel(app.VariabilityTab);
            app.AUCLabel.Text = 'AUC: ';
            app.CVGALabel = uilabel(app.VariabilityTab);
            app.CVGALabel.Text = 'CVGA: ';
            app.CVLabel = uilabel(app.VariabilityTab);
            app.CVLabel.Text = 'CV: ';
            app.MEANLabel = uilabel(app.VariabilityTab);
            app.MEANLabel.Text = 'MEAN:';
            app.MEDIANLabel = uilabel(app.VariabilityTab);
            app.MEDIANLabel.Text = 'MEDIAN:';
            app.SDLabel = uilabel(app.VariabilityTab);
            app.SDLabel.Text = 'SD:';
            app.JINDEXLabel = uilabel(app.VariabilityTab);
            app.JINDEXLabel.Text = 'J-INDEX: ';
            app.GMILabel = uilabel(app.VariabilityTab);
            app.GMILabel.Text = 'GMI:';
            app.IQRLabel = uilabel(app.VariabilityTab);
            app.IQRLabel.Text = 'IQR: ';
            app.RANGELabel = uilabel(app.VariabilityTab);
            app.RANGELabel.Text = 'RANGE: ';
            app.SDROCLabel = uilabel(app.VariabilityTab);
            app.SDROCLabel.Text = 'SD(ROC):';
            app.SDDMINDEXLabel = uilabel(app.VariabilityTab);
            app.SDDMINDEXLabel.Text = 'SDDM INDEX:';
            app.SDWINDEXLabel = uilabel(app.VariabilityTab);
            app.SDWINDEXLabel.Text = 'SDW INDEX:';
            app.EFINDEXLabel = uilabel(app.VariabilityTab);
            app.EFINDEXLabel.Text = 'EF INDEX: ';
            app.MAGEINDEXLabel = uilabel(app.VariabilityTab);
            app.MAGEINDEXLabel.Text = 'MAGE INDEX: ';
            app.MAGEINDEXLabel_2 = uilabel(app.VariabilityTab);
            app.MAGEINDEXLabel_2.Text = 'MAGE+ INDEX: ';
            app.MAGEINDEXLabel_3 = uilabel(app.VariabilityTab);
            app.MAGEINDEXLabel_3.Text = 'MAGE- INDEX: ';
            app.meanLabel = uilabel(app.VariabilityTab);
            app.meanLabel.Text = '<mean>';
            app.medianLabel = uilabel(app.VariabilityTab);
            app.medianLabel.Text = '<median>';
            app.sdLabel = uilabel(app.VariabilityTab);
            app.sdLabel.Text = '<sd>';
            app.cvLabel = uilabel(app.VariabilityTab);
            app.cvLabel.Text = '<cv>';
            app.iqrLabel = uilabel(app.VariabilityTab);
            app.iqrLabel.Text = '<iqr>';
            app.rangeLabel = uilabel(app.VariabilityTab);
            app.rangeLabel.Text = '<range>';
            app.sdrocLabel = uilabel(app.VariabilityTab);
            app.sdrocLabel.Text = '<sdroc>';
            app.aucLabel = uilabel(app.VariabilityTab);
            app.aucLabel.Text = '<auc>';
            app.cvgaLabel = uilabel(app.VariabilityTab);
            app.cvgaLabel.Text = '<cvga>';
            app.jindexLabel = uilabel(app.VariabilityTab);
            app.jindexLabel.Text = '<jindex>';
            app.sddmindexLabel = uilabel(app.VariabilityTab);
            app.sddmindexLabel.Text = '<sddmindex>';
            app.sdwindexLabel = uilabel(app.VariabilityTab);
            app.sdwindexLabel.Text = '<sdwindex>';
            app.efindexLabel = uilabel(app.VariabilityTab);
            app.efindexLabel.Text = '<efindex>';
            app.mageindexLabel = uilabel(app.VariabilityTab);
            app.mageindexLabel.Text = '<mageindex>';
            app.mageplusindexLabel = uilabel(app.VariabilityTab);
            app.mageplusindexLabel.Text = '<mageplusindex>';
            app.mageminusindexLabel = uilabel(app.VariabilityTab);
            app.mageminusindexLabel.Text = '<mageminusindex>';
            app.gmiLabel = uilabel(app.VariabilityTab);
            app.gmiLabel.Text = '<gmi>';
            app.CONGALabel = uilabel(app.VariabilityTab);
            app.CONGALabel.Text = 'CONGA:';
            app.MODDLabel = uilabel(app.VariabilityTab);
            app.MODDLabel.Text = 'MODD:';
            app.congaLabel = uilabel(app.VariabilityTab);
            app.congaLabel.Text = '<conga>';
            app.moddLabel = uilabel(app.VariabilityTab);
            app.moddLabel.Text = '<modd>';
            app.COGILabel = uilabel(app.VariabilityTab);
            app.COGILabel.Text = 'COGI:';
            app.cogiLabel = uilabel(app.VariabilityTab);
            app.cogiLabel.Text = '<cogi>';
            app.RiskTab = uitab(app.TabGroup);
            app.RiskTab.Title = 'Risk';
            app.HBGILabel = uilabel(app.RiskTab);
            app.HBGILabel.Text = 'HBGI: ';
            app.ADRRLabel = uilabel(app.RiskTab);
            app.ADRRLabel.Text = 'ADRR:';
            app.BGRILabel = uilabel(app.RiskTab);
            app.BGRILabel.Text = 'BGRI:';
            app.LBGILabel = uilabel(app.RiskTab);
            app.LBGILabel.Text = 'LBGI:';
            app.adrrLabel = uilabel(app.RiskTab);
            app.adrrLabel.Text = '<adrr>';
            app.bgriLabel = uilabel(app.RiskTab);
            app.bgriLabel.Text = '<bgri>';
            app.lbgiLabel = uilabel(app.RiskTab);
            app.lbgiLabel.Text = '<lbgi>';
            app.hbgiLabel = uilabel(app.RiskTab);
            app.hbgiLabel.Text = '<hbgi>';
            app.GRILabel = uilabel(app.RiskTab);
            app.GRILabel.Text = 'GRI: ';
            app.griLabel = uilabel(app.RiskTab);
            app.griLabel.Text = '<gri>';
            app.TimeinRangesTab = uitab(app.TabGroup);
            app.TimeinRangesTab.Title = 'Time in Ranges';
            app.TIMEINLEVEL2HYPOGLYCEMIACGM54mgdLLabel = uilabel(app.TimeinRangesTab);
            app.TIMEINLEVEL2HYPOGLYCEMIACGM54mgdLLabel.Text = 'TIME IN LEVEL 2 HYPOGLYCEMIA (CGM < 54 mg/dL): ';
            app.TIMEINHYPOGLYCEMIACGM70mgdLLabel = uilabel(app.TimeinRangesTab);
            app.TIMEINHYPOGLYCEMIACGM70mgdLLabel.Text = 'TIME IN HYPOGLYCEMIA (CGM < 70 mg/dL):';
            app.TIMEINHYPERGLYCEMIACGM180mgdLLabel = uilabel(app.TimeinRangesTab);
            app.TIMEINHYPERGLYCEMIACGM180mgdLLabel.Text = 'TIME IN HYPERGLYCEMIA (CGM > 180 mg/dL):';
            app.TIMEINTARGETCGM70180mgdLLabel = uilabel(app.TimeinRangesTab);
            app.TIMEINTARGETCGM70180mgdLLabel.Text = 'TIME IN TARGET (CGM 70-180 mg/dL):';
            app.TIMEINLEVEL2HYPERGLYCEMIACGM250mgdLLabel = uilabel(app.TimeinRangesTab);
            app.TIMEINLEVEL2HYPERGLYCEMIACGM250mgdLLabel.Text = 'TIME IN LEVEL 2 HYPERGLYCEMIA (CGM > 250 mg/dL): ';
            app.TIMEINTIGHTTARGETCGM70140mgdLLabel = uilabel(app.TimeinRangesTab);
            app.TIMEINTIGHTTARGETCGM70140mgdLLabel.Text = 'TIME IN TIGHT TARGET (CGM 70-140 mg/dL): ';
            app.timehypoLabel = uilabel(app.TimeinRangesTab);
            app.timehypoLabel.Text = '<timehypo>';
            app.timehyperLabel = uilabel(app.TimeinRangesTab);
            app.timehyperLabel.Text = '<timehyper>';
            app.timetargetLabel = uilabel(app.TimeinRangesTab);
            app.timetargetLabel.Text = '<timetarget>';
            app.timelevel2hyperLabel = uilabel(app.TimeinRangesTab);
            app.timelevel2hyperLabel.Text = '<timelevel2hyper>';
            app.timetighttargetLabel = uilabel(app.TimeinRangesTab);
            app.timetighttargetLabel.Text = '<timetighttarget>';
            app.timelevel2hypoLabel = uilabel(app.TimeinRangesTab);
            app.timelevel2hypoLabel.Text = '<timelevel2hypo>';
            app.TIMEINLEVEL1HYPOGLYCEMIACGM5470mgdLLabel = uilabel(app.TimeinRangesTab);
            app.TIMEINLEVEL1HYPOGLYCEMIACGM5470mgdLLabel.Text = 'TIME IN LEVEL 1 HYPOGLYCEMIA (CGM 54-70 mg/dL): ';
            app.timelevel1hypoLabel = uilabel(app.TimeinRangesTab);
            app.timelevel1hypoLabel.Text = '<timelevel1hypo>';
            app.TIMEINLEVEL1HYPERGLYCEMIACGM180250mgdLLabel = uilabel(app.TimeinRangesTab);
            app.TIMEINLEVEL1HYPERGLYCEMIACGM180250mgdLLabel.Text = 'TIME IN LEVEL 1 HYPERGLYCEMIA (CGM 180-250 mg/dL): ';
            app.timelevel1hyperLabel = uilabel(app.TimeinRangesTab);
            app.timelevel1hyperLabel.Text = '<timelevel1hyper>';
            app.GlycemicTransformationTab = uitab(app.TabGroup);
            app.GlycemicTransformationTab.Title = 'Glycemic Transformation';
            app.GRADEHYPERGLYCEMIASCORELabel = uilabel(app.GlycemicTransformationTab);
            app.GRADEHYPERGLYCEMIASCORELabel.Text = 'GRADE HYPERGLYCEMIA SCORE:';
            app.GRADESCORELabel = uilabel(app.GlycemicTransformationTab);
            app.GRADESCORELabel.Text = 'GRADE SCORE:';
            app.GRADEEUGLYCEMIASCORELabel = uilabel(app.GlycemicTransformationTab);
            app.GRADEEUGLYCEMIASCORELabel.Text = 'GRADE EUGLYCEMIA SCORE:';
            app.GRADEHYPOGLYCEMIASCORELabel = uilabel(app.GlycemicTransformationTab);
            app.GRADEHYPOGLYCEMIASCORELabel.Text = 'GRADE HYPOGLYCEMIA SCORE:';
            app.INDEXGLYCEMICCONTROLLabel = uilabel(app.GlycemicTransformationTab);
            app.INDEXGLYCEMICCONTROLLabel.Text = 'INDEX GLYCEMIC CONTROL: ';
            app.HYPOINDEXLabel = uilabel(app.GlycemicTransformationTab);
            app.HYPOINDEXLabel.Text = 'HYPO INDEX:';
            app.gradeLabel = uilabel(app.GlycemicTransformationTab);
            app.gradeLabel.Text = '<grade>';
            app.gradeeuLabel = uilabel(app.GlycemicTransformationTab);
            app.gradeeuLabel.Text = '<gradeeu>';
            app.gradehypoLabel = uilabel(app.GlycemicTransformationTab);
            app.gradehypoLabel.Text = '<gradehypo>';
            app.igcLabel = uilabel(app.GlycemicTransformationTab);
            app.igcLabel.Text = '<igc>';
            app.hypoindexLabel = uilabel(app.GlycemicTransformationTab);
            app.hypoindexLabel.Text = '<hypoindex>';
            app.gradehyperLabel = uilabel(app.GlycemicTransformationTab);
            app.gradehyperLabel.Text = '<gradehyper>';
            app.HYPERINDEXLabel = uilabel(app.GlycemicTransformationTab);
            app.HYPERINDEXLabel.Text = 'HYPER INDEX:';
            app.hyperindexLabel = uilabel(app.GlycemicTransformationTab);
            app.hyperindexLabel.Text = '<hyperindex>';
            app.MRVALUELabel = uilabel(app.GlycemicTransformationTab);
            app.MRVALUELabel.Text = 'MR VALUE:';
            app.mrLabel = uilabel(app.GlycemicTransformationTab);
            app.mrLabel.Text = '<mr>';
            app.DataQualityTab = uitab(app.TabGroup);
            app.DataQualityTab.Title = 'Data Quality';
            app.MISSINGGLUCOSEVALUESPERCENTAGELabel = uilabel(app.DataQualityTab);
            app.MISSINGGLUCOSEVALUESPERCENTAGELabel.Text = 'MISSING GLUCOSE VALUES PERCENTAGE:';
            app.missingpercentageLabel = uilabel(app.DataQualityTab);
            app.missingpercentageLabel.Text = '<missingpercentage>';
            app.NUMBEROFDAYSLabel = uilabel(app.DataQualityTab);
            app.NUMBEROFDAYSLabel.Text = 'NUMBER OF DAYS:';
            app.numberDaysLabel = uilabel(app.DataQualityTab);
            app.numberDaysLabel.Text = '<numberDays>';
            app.EventsTab = uitab(app.TabGroup);
            app.EventsTab.Title = 'Events';
            app.HYPOGLYCEMICEVENTSPERWEEKLabel = uilabel(app.EventsTab);
            app.HYPOGLYCEMICEVENTSPERWEEKLabel.Text = 'HYPOGLYCEMIC EVENTS PER WEEK:';
            app.HYPOGLYCEMICEVENTSMEANDURATIONLabel = uilabel(app.EventsTab);
            app.HYPOGLYCEMICEVENTSMEANDURATIONLabel.Text = 'HYPOGLYCEMIC EVENTS MEAN DURATION:';
            app.hypodurationLabel = uilabel(app.EventsTab);
            app.hypodurationLabel.Text = '<hypoduration>';
            app.hypoperweekLabel = uilabel(app.EventsTab);
            app.hypoperweekLabel.Text = '<hypoperweek>';
            app.HYPERGLYCEMICEVENTSPERWEEKLabel_2 = uilabel(app.EventsTab);
            app.HYPERGLYCEMICEVENTSPERWEEKLabel_2.Text = 'HYPERGLYCEMIC EVENTS PER WEEK:';
            app.HYPERGLYCEMICEVENTSMEANDURATIONLabel_2 = uilabel(app.EventsTab);
            app.HYPERGLYCEMICEVENTSMEANDURATIONLabel_2.Text = 'HYPERGLYCEMIC EVENTS MEAN DURATION:';
            app.hyperdurationLabel = uilabel(app.EventsTab);
            app.hyperdurationLabel.Text = '<hyperduration>';
            app.hyperperweekLabel = uilabel(app.EventsTab);
            app.hyperperweekLabel.Text = '<hyperperweek>';
            app.EXTENDEDHYPOGLYCEMICEVENTSPERWEEKLabel = uilabel(app.EventsTab);
            app.EXTENDEDHYPOGLYCEMICEVENTSPERWEEKLabel.Text = 'EXTENDED HYPOGLYCEMIC EVENTS PER WEEK:';
            app.EXTENDEDHYPOGLYCEMICEVENTSMEANDURATIONLabel = uilabel(app.EventsTab);
            app.EXTENDEDHYPOGLYCEMICEVENTSMEANDURATIONLabel.Text = 'EXTENDED HYPOGLYCEMIC EVENTS MEAN DURATION:';
            app.extendedhypodurationLabel = uilabel(app.EventsTab);
            app.extendedhypodurationLabel.Text = '<extendedhypoduration>';
            app.extendedhypoperweekLabel = uilabel(app.EventsTab);
            app.extendedhypoperweekLabel.Text = '<extendedhypoperweek>';
            app.LEVEL1HYPERGLYCEMICEVENTSPERWEEKLabel = uilabel(app.EventsTab);
            app.LEVEL1HYPERGLYCEMICEVENTSPERWEEKLabel.Text = 'LEVEL 1 HYPERGLYCEMIC EVENTS PER WEEK:';
            app.LEVEL1HYPERGLYCEMICEVENTSMEANDURATIONLabel = uilabel(app.EventsTab);
            app.LEVEL1HYPERGLYCEMICEVENTSMEANDURATIONLabel.Text = 'LEVEL 1 HYPERGLYCEMIC EVENTS MEAN DURATION:';
            app.l1hyperdurationLabel = uilabel(app.EventsTab);
            app.l1hyperdurationLabel.Text = '<l1hyperduration>';
            app.l1hyperperweekLabel = uilabel(app.EventsTab);
            app.l1hyperperweekLabel.Text = '<l1hyperperweek>';
            app.LEVEL2HYPERGLYCEMICEVENTSPERWEEKLabel = uilabel(app.EventsTab);
            app.LEVEL2HYPERGLYCEMICEVENTSPERWEEKLabel.Text = 'LEVEL 2 HYPERGLYCEMIC EVENTS PER WEEK:';
            app.LEVEL2HYPERGLYCEMICEVENTSMEANDURATIONLabel = uilabel(app.EventsTab);
            app.LEVEL2HYPERGLYCEMICEVENTSMEANDURATIONLabel.Text = 'LEVEL 2 HYPERGLYCEMIC EVENTS MEAN DURATION:';
            app.l2hyperdurationLabel = uilabel(app.EventsTab);
            app.l2hyperdurationLabel.Text = '<l2hyperduration>';
            app.l2hyperperweekLabel = uilabel(app.EventsTab);
            app.l2hyperperweekLabel.Text = '<l2hyperperweek>';
            app.LEVEL1HYPOGLYCEMICEVENTSPERWEEKLabel = uilabel(app.EventsTab);
            app.LEVEL1HYPOGLYCEMICEVENTSPERWEEKLabel.Text = 'LEVEL 1 HYPOGLYCEMIC EVENTS PER WEEK:';
            app.LEVEL1HYPOGLYCEMICEVENTSMEANDURATIONLabel = uilabel(app.EventsTab);
            app.LEVEL1HYPOGLYCEMICEVENTSMEANDURATIONLabel.Text = 'LEVEL 1 HYPOGLYCEMIC EVENTS MEAN DURATION:';
            app.l1hypodurationLabel = uilabel(app.EventsTab);
            app.l1hypodurationLabel.Text = '<l1hypoduration>';
            app.l1hypoperweekLabel = uilabel(app.EventsTab);
            app.l1hypoperweekLabel.Text = '<l1hypoperweek>';
            app.LEVEL2HYPOGLYCEMICEVENTSPERWEEKLabel = uilabel(app.EventsTab);
            app.LEVEL2HYPOGLYCEMICEVENTSPERWEEKLabel.Text = 'LEVEL 2 HYPOGLYCEMIC EVENTS PER WEEK:';
            app.LEVEL2HYPOGLYCEMICEVENTSMEANDURATIONLabel = uilabel(app.EventsTab);
            app.LEVEL2HYPOGLYCEMICEVENTSMEANDURATIONLabel.Text = 'LEVEL 2 HYPOGLYCEMIC EVENTS MEAN DURATION:';
            app.l2hypodurationLabel = uilabel(app.EventsTab);
            app.l2hypodurationLabel.Text = '<l2hypoduration>';
            app.l2hypoperweekLabel = uilabel(app.EventsTab);
            app.l2hypoperweekLabel.Text = '<l2hypoperweek>';
            app.SaveAnalysisResultsButton = uibutton(app.AnalysisDataPanel, 'push');
            app.SaveAnalysisResultsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveAnalysisResultsButtonPushed, true);
            app.SaveAnalysisResultsButton.Text = 'Save Analysis Results';
            % Show the figure after all components are created
            app.AGATASingleProfileGUIUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = agataSingleProfile(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AGATASingleProfileGUIUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AGATASingleProfileGUIUIFigure)
        end
    end
end